classdef P2ROUD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        P2ROUD_UIFigure                 matlab.ui.Figure
        Hyperlink                       matlab.ui.control.Hyperlink
        MessagesTabGroup                matlab.ui.container.TabGroup
        MessagesTab                     matlab.ui.container.Tab
        StatusImage                     matlab.ui.control.Image
        StatusLabel                     matlab.ui.control.Label
        StatusBox                       matlab.ui.control.TextArea
        MessageBox                      matlab.ui.control.TextArea
        SettingsTab                     matlab.ui.container.Tab
        MessagesLabel                   matlab.ui.control.Label
        MessageLevelSpinnerLabel        matlab.ui.control.Label
        MessageLevelSpinner             matlab.ui.control.Spinner
        ColorsButtonGroup               matlab.ui.container.ButtonGroup
        BlackWhiteButton                matlab.ui.control.RadioButton
        ClassicButton                   matlab.ui.control.RadioButton
        ColorsLabel                     matlab.ui.control.Label
        AutoSoundsCheckBoxLabel         matlab.ui.control.Label
        AutoSoundsCheckBox              matlab.ui.control.CheckBox
        SoundsLabel                     matlab.ui.control.Label
        ReconstructionTabGroup          matlab.ui.container.TabGroup
        ImagesTab                       matlab.ui.container.Tab
        MaskButton                      matlab.ui.control.Button
        OpenButton                      matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        DilateButton                    matlab.ui.control.Button
        ErodeButton                     matlab.ui.control.Button
        ThresholdSlider                 matlab.ui.control.Slider
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        OrientationSpinner              matlab.ui.control.Spinner
        OrientationSpinnerLabel         matlab.ui.control.Label
        MovieImage                      matlab.ui.control.Image
        AutoIntensityCheckBoxLabel      matlab.ui.control.Label
        AutoIntensityCheckBox           matlab.ui.control.CheckBox
        MovieStopButton                 matlab.ui.control.Button
        MoviePlayButton                 matlab.ui.control.Button
        SetButton                       matlab.ui.control.Button
        WindowEditField                 matlab.ui.control.NumericEditField
        LevelEditField                  matlab.ui.control.NumericEditField
        WindowSlider                    matlab.ui.control.Slider
        LLabel                          matlab.ui.control.Label
        WLabel                          matlab.ui.control.Label
        LevelSlider                     matlab.ui.control.Slider
        Dim3Label                       matlab.ui.control.Label
        Dim4Label                       matlab.ui.control.Label
        Dim5Label                       matlab.ui.control.Label
        Dim6Label                       matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        Dim3EditField                   matlab.ui.control.NumericEditField
        Dim4EditField                   matlab.ui.control.NumericEditField
        Dim5EditField                   matlab.ui.control.NumericEditField
        Dim6EditField                   matlab.ui.control.NumericEditField
        Dim3Slider                      matlab.ui.control.Slider
        Dim4Slider                      matlab.ui.control.Slider
        Dim5Slider                      matlab.ui.control.Slider
        Dim6Slider                      matlab.ui.control.Slider
        RecoFig                         matlab.ui.control.UIAxes
        MIPTab                          matlab.ui.container.Tab
        MIPResizeButton                 matlab.ui.control.StateButton
        MIPxPlayButton                  matlab.ui.control.Button
        MIPyPlayButton                  matlab.ui.control.Button
        MIPOrientationSpinner           matlab.ui.control.Spinner
        MIPOrientationSpinnerLabel      matlab.ui.control.Label
        MIPXLabel                       matlab.ui.control.Label
        MIPYLabel                       matlab.ui.control.Label
        MIPSetButton                    matlab.ui.control.Button
        MIPWindowEditField              matlab.ui.control.NumericEditField
        MIPLevelEditField               matlab.ui.control.NumericEditField
        MIPWindowSlider                 matlab.ui.control.Slider
        MIPLLabel                       matlab.ui.control.Label
        MIPWLabel                       matlab.ui.control.Label
        MIPLevelSlider                  matlab.ui.control.Slider
        MIPAngleResetButton             matlab.ui.control.Button
        MIPRotateLabel                  matlab.ui.control.Label
        MIPxAngleLabel                  matlab.ui.control.Label
        MIPyAngleLabel                  matlab.ui.control.Label
        MIPxAngleSlider                 matlab.ui.control.Slider
        MIPyAngleSlider                 matlab.ui.control.Slider
        MIPFig                          matlab.ui.control.UIAxes
        PhaseTab                        matlab.ui.container.Tab
        PhaseMinLabel                   matlab.ui.control.Label
        PhaseMaxLabel                   matlab.ui.control.Label
        PhaseUnwrapButton               matlab.ui.control.Button
        PhaseOrientationSpinner         matlab.ui.control.Spinner
        PhaseOrientationSpinnerLabel    matlab.ui.control.Label
        PhaseMovieImage                 matlab.ui.control.Image
        PhaseDim6Label                  matlab.ui.control.Label
        PhaseDim5Label                  matlab.ui.control.Label
        PhaseDim4Label                  matlab.ui.control.Label
        PhaseDim3Label                  matlab.ui.control.Label
        PhaseDim6Slider                 matlab.ui.control.Slider
        PhaseDim6EditField              matlab.ui.control.NumericEditField
        PhaseDim4EditField              matlab.ui.control.NumericEditField
        PhaseDim5EditField              matlab.ui.control.NumericEditField
        PhaseDim3EditField              matlab.ui.control.NumericEditField
        PhaseDim4Slider                 matlab.ui.control.Slider
        PhaseDim5Slider                 matlab.ui.control.Slider
        PhaseDim3Slider                 matlab.ui.control.Slider
        PhaseMovieStopButton            matlab.ui.control.Button
        PhaseMovieStartButton           matlab.ui.control.Button
        PhaseXLabel                     matlab.ui.control.Label
        PhaseYLabel                     matlab.ui.control.Label
        PhaseRecoFig                    matlab.ui.control.UIAxes
        ScanParameterTabGroup           matlab.ui.container.TabGroup
        ScanParametersTab               matlab.ui.container.Tab
        VencViewField                   matlab.ui.control.EditField
        VencViewFieldLabel              matlab.ui.control.Label
        TimeDynViewField                matlab.ui.control.EditField
        TimeDynViewFieldLabel           matlab.ui.control.Label
        ScanTimeViewField               matlab.ui.control.EditField
        ScanTimeViewFieldLabel          matlab.ui.control.Label
        TrajectoryViewField             matlab.ui.control.EditField
        TrajectoryViewFieldLabel        matlab.ui.control.Label
        FAViewField                     matlab.ui.control.EditField
        FAViewFieldLabel                matlab.ui.control.Label
        MRDfileViewField                matlab.ui.control.EditField
        MRDfileViewFieldLabel           matlab.ui.control.Label
        NEViewField                     matlab.ui.control.NumericEditField
        NEViewFieldLabel                matlab.ui.control.Label
        NFAViewField                    matlab.ui.control.NumericEditField
        NFAViewFieldLabel               matlab.ui.control.Label
        NAViewField                     matlab.ui.control.NumericEditField
        NAViewFieldLabel                matlab.ui.control.Label
        TEViewField                     matlab.ui.control.NumericEditField
        TEViewFieldLabel                matlab.ui.control.Label
        TRViewField                     matlab.ui.control.NumericEditField
        TRViewFieldLabel                matlab.ui.control.Label
        FOVViewField1                   matlab.ui.control.NumericEditField
        FOVViewField1Label              matlab.ui.control.Label
        KMatrixViewField1               matlab.ui.control.NumericEditField
        KMatrixViewField1Label          matlab.ui.control.Label
        SequenceViewField               matlab.ui.control.EditField
        SequenceViewFieldLabel          matlab.ui.control.Label
        NRViewField                     matlab.ui.control.NumericEditField
        NRViewFieldLabel                matlab.ui.control.Label
        MZLabel                         matlab.ui.control.Label
        MYLabel                         matlab.ui.control.Label
        MXLabel                         matlab.ui.control.Label
        FOVViewField3                   matlab.ui.control.NumericEditField
        FOVViewField2                   matlab.ui.control.NumericEditField
        KMatrixViewField3               matlab.ui.control.NumericEditField
        KMatrixViewField2               matlab.ui.control.NumericEditField
        GeometryTab                     matlab.ui.container.Tab
        SlabOverlapEditField            matlab.ui.control.NumericEditField
        SlabOverlapEditFieldLabel       matlab.ui.control.Label
        SliceGapViewField               matlab.ui.control.NumericEditField
        SliceGapViewFieldLabel          matlab.ui.control.Label
        AngleZViewFieldLabel            matlab.ui.control.Label
        AngleZViewField                 matlab.ui.control.NumericEditField
        AngleYViewFieldLabel            matlab.ui.control.Label
        AngleYViewField                 matlab.ui.control.NumericEditField
        AngleXViewFieldLabel            matlab.ui.control.Label
        AngleXViewField                 matlab.ui.control.NumericEditField
        OffsetZViewFieldLabel           matlab.ui.control.Label
        OffsetZViewField                matlab.ui.control.NumericEditField
        OffsetYViewField                matlab.ui.control.NumericEditField
        OffsetYViewFieldLabel           matlab.ui.control.Label
        OffsetXViewField                matlab.ui.control.NumericEditField
        OffsetXViewFieldLabel           matlab.ui.control.Label
        SliceThicknessViewField         matlab.ui.control.NumericEditField
        SliceThicknessViewFieldLabel    matlab.ui.control.Label
        PhaseOrientationViewField       matlab.ui.control.NumericEditField
        PhaseOrientationViewFieldLabel  matlab.ui.control.Label
        AspectRatioViewField            matlab.ui.control.NumericEditField
        AspectRatioViewFieldLabel       matlab.ui.control.Label
        FieldOfViewViewField2           matlab.ui.control.NumericEditField
        FieldOfViewViewField2Label      matlab.ui.control.Label
        ReconstructionTab               matlab.ui.container.Tab
        BMDdeNoiseCheckBox              matlab.ui.control.CheckBox
        BMDdeNoiseLabel                 matlab.ui.control.Label
        SumTypeNeDropDown               matlab.ui.control.DropDown
        IncludeNeEditField              matlab.ui.control.EditField
        SumTypeNrDropDown               matlab.ui.control.DropDown
        IncludeNrEditField              matlab.ui.control.EditField
        SumRepetitionsCheckBox          matlab.ui.control.CheckBox
        SumRepetitionsLabel             matlab.ui.control.Label
        SumEchosLabel                   matlab.ui.control.Label
        SumEchosCheckBox                matlab.ui.control.CheckBox
        MultiEchoLabel                  matlab.ui.control.Label
        ZTEIterationsLabel              matlab.ui.control.Label
        ZTEIterationsEditField          matlab.ui.control.NumericEditField
        DeNoisingLabel                  matlab.ui.control.Label
        CompressedsensingLabel          matlab.ui.control.Label
        LLRxyzEditFieldLabel            matlab.ui.control.Label
        LLRxyzEditField                 matlab.ui.control.NumericEditField
        WVxyzEditFieldLabel             matlab.ui.control.Label
        WVxyzEditField                  matlab.ui.control.NumericEditField
        TVtimeEditFieldLabel            matlab.ui.control.Label
        TVtimeEditField                 matlab.ui.control.NumericEditField
        TVxyzEditFieldLabel             matlab.ui.control.Label
        TVxyzEditField                  matlab.ui.control.NumericEditField
        PCAdeNoiseWindowEditField       matlab.ui.control.NumericEditField
        PCAdeNoiseWindowLabel           matlab.ui.control.Label
        PCAdeNoiseCheckBox              matlab.ui.control.CheckBox
        PCAdeNoiseLabel                 matlab.ui.control.Label
        DeRingLabel                     matlab.ui.control.Label
        DeRingCheckBox                  matlab.ui.control.CheckBox
        RadialTab                       matlab.ui.container.Tab
        ZTEPanel                        matlab.ui.container.Panel
        ZTEDeadTimeCalibrationLabel     matlab.ui.control.Label
        ZTEDeadTimeCalibrationCheckBox  matlab.ui.control.CheckBox
        ZTEShiftLabel                   matlab.ui.control.Label
        ZTEShiftEditField               matlab.ui.control.NumericEditField
        ZTEDeadTimeLabel                matlab.ui.control.Label
        ZTEDeadTimeEditField            matlab.ui.control.NumericEditField
        ZTEFullEchoLabel                matlab.ui.control.Label
        ZTEFullEchoCheckBox             matlab.ui.control.CheckBox
        CorrectionsPanel                matlab.ui.container.Panel
        TukeyFilterCheckBoxLabel        matlab.ui.control.Label
        TukeyFilterCheckBox             matlab.ui.control.CheckBox
        AmplitudeCorrectionCheckBoxLabel  matlab.ui.control.Label
        AmplitudeCorrectionCheckBox     matlab.ui.control.CheckBox
        DensityCorrectCheckBoxLabel     matlab.ui.control.Label
        DensityCorrectCheckBox          matlab.ui.control.CheckBox
        CenterEchoCheckBoxLabel         matlab.ui.control.Label
        CenterEchoCheckBox              matlab.ui.control.CheckBox
        PhaseCorrectCheckBoxLabel       matlab.ui.control.Label
        PhaseCorrectCheckBox            matlab.ui.control.CheckBox
        GradientdelaysPanel             matlab.ui.container.Panel
        SyzDelayEditField               matlab.ui.control.NumericEditField
        SyzdelayEditFieldLabel          matlab.ui.control.Label
        SxzDelayEditField               matlab.ui.control.NumericEditField
        SxzdelayEditFieldLabel          matlab.ui.control.Label
        SxyDelayEditField               matlab.ui.control.NumericEditField
        SxydelayEditFieldLabel          matlab.ui.control.Label
        DataOffsetRadialEditField       matlab.ui.control.NumericEditField
        DataOffsetRadialEditFieldLabel  matlab.ui.control.Label
        GzDelayEditField                matlab.ui.control.NumericEditField
        GzdelayEditFieldLabel           matlab.ui.control.Label
        GyDelayEditField                matlab.ui.control.NumericEditField
        GydelayEditFieldLabel           matlab.ui.control.Label
        GxDelayEditField                matlab.ui.control.NumericEditField
        GxdelayEditFieldLabel           matlab.ui.control.Label
        AbortGradCalButton              matlab.ui.control.Button
        RingMethodCheckBoxLabel         matlab.ui.control.Label
        RingMethodCheckBox              matlab.ui.control.CheckBox
        GradDelayCalibrationCheckBoxLabel  matlab.ui.control.Label
        GradDelayCalibrationCheckBox    matlab.ui.control.CheckBox
        KspaceTabGroup                  matlab.ui.container.TabGroup
        KspaceTab                       matlab.ui.container.Tab
        CoilSpinner                     matlab.ui.control.Spinner
        CoilSpinnerLabel                matlab.ui.control.Label
        KFlipAngleEditField             matlab.ui.control.NumericEditField
        KFlipAngleEditFieldLabel        matlab.ui.control.Label
        KOrientationSpinner             matlab.ui.control.Spinner
        KOrientationSpinnerLabel        matlab.ui.control.Label
        KEchoEditField                  matlab.ui.control.NumericEditField
        KEchoEditFieldLabel             matlab.ui.control.Label
        KWindowEditField                matlab.ui.control.NumericEditField
        KLevelEditField                 matlab.ui.control.NumericEditField
        KWindowSlider                   matlab.ui.control.Slider
        KLLabel                         matlab.ui.control.Label
        KWLabel                         matlab.ui.control.Label
        KLevelSlider                    matlab.ui.control.Slider
        FastSlowSwitch                  matlab.ui.control.RockerSwitch
        KspaceMovieLabel                matlab.ui.control.Label
        KStopButton                     matlab.ui.control.Button
        KStartButton                    matlab.ui.control.Button
        FillingPercLabel                matlab.ui.control.Label
        FillingViewField                matlab.ui.control.NumericEditField
        KFlipAngleSlider                matlab.ui.control.Slider
        KDim4Label                      matlab.ui.control.Label
        KEchoSlider                     matlab.ui.control.Slider
        KDim3Label                      matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        senseMapButton                  matlab.ui.control.RadioButton
        fillingButton                   matlab.ui.control.RadioButton
        averagesButton                  matlab.ui.control.RadioButton
        kValuesButton                   matlab.ui.control.RadioButton
        KyLabel                         matlab.ui.control.Label
        KxLabel                         matlab.ui.control.Label
        KDim4EditField                  matlab.ui.control.NumericEditField
        KDim4Slider                     matlab.ui.control.Slider
        KDim3Slider                     matlab.ui.control.Slider
        KDim3EditField                  matlab.ui.control.NumericEditField
        KspaceFig                       matlab.ui.control.UIAxes
        CoilsTab                        matlab.ui.container.Tab
        KDim3EditFieldS                 matlab.ui.control.NumericEditField
        KDim3LabelS                     matlab.ui.control.Label
        SensitivityMapsPanel            matlab.ui.container.Panel
        KDim3SliderS                    matlab.ui.control.Slider
        Label5                          matlab.ui.control.Label
        Label1                          matlab.ui.control.Label
        Label8                          matlab.ui.control.Label
        Label7                          matlab.ui.control.Label
        Label6                          matlab.ui.control.Label
        Label4                          matlab.ui.control.Label
        Label3                          matlab.ui.control.Label
        Label2                          matlab.ui.control.Label
        SenseFig8                       matlab.ui.control.UIAxes
        SenseFig7                       matlab.ui.control.UIAxes
        SenseFig6                       matlab.ui.control.UIAxes
        SenseFig5                       matlab.ui.control.UIAxes
        SenseFig4                       matlab.ui.control.UIAxes
        SenseFig3                       matlab.ui.control.UIAxes
        SenseFig2                       matlab.ui.control.UIAxes
        SenseFig1                       matlab.ui.control.UIAxes
        CoilSelectionPanel              matlab.ui.container.Panel
        AutoSensitivityCheckBox         matlab.ui.control.CheckBox
        RelativeSensitivityLabel        matlab.ui.control.Label
        CoilLabel                       matlab.ui.control.Label
        S8EditField                     matlab.ui.control.NumericEditField
        S7EditField                     matlab.ui.control.NumericEditField
        S6EditField                     matlab.ui.control.NumericEditField
        S5EditField                     matlab.ui.control.NumericEditField
        S4EditField                     matlab.ui.control.NumericEditField
        S3EditField                     matlab.ui.control.NumericEditField
        S2EditField                     matlab.ui.control.NumericEditField
        S1EditField                     matlab.ui.control.NumericEditField
        C8CheckBox                      matlab.ui.control.CheckBox
        C7CheckBox                      matlab.ui.control.CheckBox
        C6CheckBox                      matlab.ui.control.CheckBox
        C5CheckBox                      matlab.ui.control.CheckBox
        C4CheckBox                      matlab.ui.control.CheckBox
        C3CheckBox                      matlab.ui.control.CheckBox
        C2CheckBox                      matlab.ui.control.CheckBox
        C1CheckBox                      matlab.ui.control.CheckBox
        DashboardTabGroup               matlab.ui.container.TabGroup
        DashboardTab                    matlab.ui.container.Tab
        ExitButton                      matlab.ui.control.Button
        ExportPanel                     matlab.ui.container.Panel
        Lamp6                           matlab.ui.control.Lamp
        Lamp5                           matlab.ui.control.Lamp
        Lamp3                           matlab.ui.control.Lamp
        GIFButton                       matlab.ui.control.Button
        MRDButton                       matlab.ui.control.Button
        ExportProgressGauge             matlab.ui.control.LinearGauge
        DCMNIFTIButton                  matlab.ui.control.Button
        ReconstructionParametersPanel   matlab.ui.container.Panel
        CSRecoLabel                     matlab.ui.control.Label
        CSRecoCheckBox                  matlab.ui.control.CheckBox
        AbortRecoButton                 matlab.ui.control.Button
        RecoProgressGauge               matlab.ui.control.LinearGauge
        Lamp2                           matlab.ui.control.Lamp
        RecoButton                      matlab.ui.control.Button
        ReconstructionDimensionsPanel   matlab.ui.container.Panel
        NREditFieldLabel                matlab.ui.control.Label
        ZEditFieldLabel                 matlab.ui.control.Label
        YEditFieldLabel                 matlab.ui.control.Label
        XEditFieldLabel                 matlab.ui.control.Label
        NREditField                     matlab.ui.control.NumericEditField
        ZEditField                      matlab.ui.control.NumericEditField
        YEditField                      matlab.ui.control.NumericEditField
        XEditField                      matlab.ui.control.NumericEditField
        Lamp1                           matlab.ui.control.Lamp
        LoadMRDButton                   matlab.ui.control.Button
        P2ROUDLabel                     matlab.ui.control.Label
        MRSImage                        matlab.ui.control.Image
        CreditsLabel                    matlab.ui.control.Label
        TulpImage                       matlab.ui.control.Image
        TitleLabel                      matlab.ui.control.Label
    end


    % ------------------------------------------------------
    %
    % P2ROUD
    % Preclinical PROspective Undersampling in multiple Dimensions
    % Matlab 2025a
    %
    % Gustav Strijkers
    % Amsterdam UMC
    % g.j.strijkers@amsterdamumc.nl
    % Aug 2026
    %
    %
    % Version 1.0
    % - initial commit
    %
    % Version 1.1
    % - add scaling for k-space visualization
    % - corrected orientation of odd-echo images in multi-gradient-echo
    %
    % Version 1.2
    % - bug fixes
    % - reconstruction of CINE images, for which NE-dimension is the CINE loop
    % - GIF export
    % - Image zoom
    % - NIFTI export for 2D multislice data
    % - Bug fix in DICOM export
    % - Bug fix in NIFTI export dimensions
    %
    % Version 1.3
    % - multi coil reconstruction panel
    % - bug fixes
    % - read total acquisition time from rpr file if available
    %
    % Version 1.4
    % - Low-rank in spatial domain reconstruction added (with BART)
    % - Improved Tukey filter centered around k-space maximum
    % - rtable.rtv is renamed to origrtable.rtv.orig to prevent resorting of kspace with sur export
    % - removed CINE flipping of odd images, for some reason not needed anymore
    % - Added reconstruction parameter export fil
    %
    % Version 3.0
    % - New proudData class introduced for easier app maintenance
    % - Various bug fixes
    % - MIP visualization
    %
    % Version 3.1
    % - Noise suppression algorithm
    %
    % Version 4.0
    % - Phase and 4D-flow reconstruction
    %
    % Version 4.1
    % - Bug fixes
    %
    % Version 4.2
    % - Some improvements to the MIP
    % - Bug fixes
    %
    % Version 4.3
    % - Bug fixes
    %
    % Version 5.0
    % - 2D radial reconstruction
    % - Image shifts for off-center acquistitions correctly incorporated
    % - Image orientation labels
    % - Mouse wheel scrolling for main image plot added
    % - Import of partially completed acquisition possible
    %
    % Version 5.2
    % - P2ROUD k-space trajectories, with pe1_order = 4 (exLUT)
    % - multi-echo, multi-experiments, multi-flip-angle, P2ROUD reconstruction
    % - Gibbs ringing suppression filter
    % - Improved reconstructions
    % - PCA denoising
    % - Segmented k-space filling
    %
    % Version 5.3
    % - Improvements to 3D image reconstruction (magnitude and phase images)
    % - Logaritmic k-space image for better visibility
    % - Fixed removal of navigator for data that has one
    % - EPI reconstruction improvements
    % - Tooltips updated 
    %
    % Version 5.4
    % - Coil sensitivity maps
    %
    % Version 5.5
    % - Rewritten the reconstruction methods
    % - Some bug fixes sensitivity maps
    %
    % Version 5.6
    % - Black/white & Classic color scheme
    % - Bug fix segmented k-space
    % - Improved Ecalib coil sensitivity estimations
    %
    % Version 5.7
    % - FLASH sequence version check for off-center corrections
    % - P2ROUD app version check
    % - missing rtable bug with MRD export fixed
    % - DICOM export improvements
    % - AI denoising
    % - App compatible with new external navigators
    % - Fixed problem with multi-slice acquisition and zigzag k-space filling
    %
    % Version 6.0
    % - ZTE reconstruction (regridding and CS)
    % - GPU support for BART
    % - MPRAGE import
    % - Working on new 3D LUT import for undersampled k-spaces
    %
    % Version 6.1
    % - Bug fixes in MRD export, Dicom export
    % - Check for some tags in dicom export
    % - NonReg LUT import
    %
    % Version 6.2
    % - Remove the nifti export button, nifti export is done automatically with dcm export
    % - Improved image orienation labels
    % - Various bug fixes
    % - PCA denoising bug fixed
    % - Summing echoes or repetitions
    %
    % Version 6.3
    % - Complete overhaul of app file structure


    % --------------------------------------------------------------
    %
    %                     PUBLIC PROPERTIES
    %
    % --------------------------------------------------------------

    properties (Access = public)

        % Proud class
        pd                          proudData       % Data and meta data

        % Version control
        appVersion = "6.3.1"        % App version
        bartVersion                 % Bart version
        GPUpresentFlag = false      % GPU present for Bart
        jsonPar                     % JSON parameters

        % Files and directories
        appDir                      % Application directory
        oldDir                      % Starting directory
        currentDir                  % Deployed directory
        mrdImportPath               % MRD import path
        mrdExportPath               % MRD export path
        dicomExportPath             % Dicom export path
        niftiExportPath             % NIFTI export path
        gifExportPath               % GIF export path
        tag                         % Filename tag
        rtableFile                  % rtable file name
        logFile                     % log file ID
        oldWarningState             % warning state MATLAB had when the app started
        busyFlag = false            % a step is running: status BUSY until it ends
        closeRequested = false      % the window was closed while a step was running
        closeTimer                  % timer that closes the app once that step has finished

        % Flags
        bartDetectedFlag            % Bart toolbox detected true or false
        mrdFlag                     % MRD directory checked true or false
        dicomFlag                   % Dicom directory checked true or false
        forceProudFlag              % Force P2ROUD trajectory input true / false
        validNIFTIdataFlag          % Data suitable for NIFTI export true or false
        validMovieFlag              % Data with multiple repetitions true or false
        newFigFlag                  % New figure flag false or true
        newPhaseFigFlag             % New phase figure flag false or true
        stopRecoFlag                % Stop the reco true or false
        stopMovieFlag               % Movie running (false) or stopping (true)
        stopPhaseMovieFlag          % Movie running (false) or stopping (true)
        stopKspaceMovieFlag         % K movie running (false) or stopping (true)
        stopMIPxMovieFlag           % MIP x movie running (false) or stopping (true)
        stopMIPyMovieFlag           % MIP y movie running (false) or stopping (true)
        stopGradCalFlag             % Stop the grdient calibration (false/true)
        scrollEnableFlag            % Mouse scroll wheel enable true/false
        blockMessagesFlag = false   % block messages in message window

        % Figure & plot elements
        imageOrient                 % Image orientation permutation matrix
        kSpaceOrientLabel           % Orientation labels in k-space figure
        imageOrientLabel            % Orientation labels in image figure
        tLabel                      % Image orienatation label top
        bLabel                      % Image orienatation label bottom
        lLabel                      % Image orienatation label left
        rLabel                      % Image orienatation label right
        cbar                        % Kspace averages and filling colorbar
        rect                        % Rectangle box
        green                       % Other shade of green
        brightgreen                 % Green
        red                         % Other shade of red
        brightred                   % Red
        blue                        % Blue
        yellow                      % Yellow
        orange                      % Orange
        guiBlack                    % app black
        guiGrey                     % app grey
        guiMiddleGrey               % app middle grey
        guiDarkGrey                 % app dark grey
        guiWhite                    % app white
        guiBlackBlue                % app black/blueish
        kmovieFig                   % Kmovie fig object
        kSpaceButtonStatus          % Status of the k-space panel buttons
        imageButtonStatus           % Status of the image panel buttons
        recoImage                   % Reconstructed image object
        phaseRecoImage              % Reconstructed phase image object
        blackImage                  % Black background image
        MIPcounter                  % MIP angle counter
        MIPstep = 5                 % MIP angle step size
        structElement               % Image morphological operation structuring element
        progressCounter             % Reconstruction progress counter
        totalCounter                % Total number of reconstruction steps

        % Sounds
        warningSound                % Warning sound
        errorSound                  % Error sound

    end


    % --------------------------------------------------------------
    %
    %                     PRIVATE FUNCTIONS
    %
    % --------------------------------------------------------------


    methods (Access = private)


        % --------------------------------------------------------------
        %
        %                     LIGHTS AND BUTTONS
        %
        % --------------------------------------------------------------

        % Set the lights
        function SetLightsFcn(app,code)

            % Purpose:
            %   Set the six progress lamps along the top of the app.
            %
            % How it works:
            %   One entry of code per lamp, in the order load, reconstruct, DICOM, unused,
            %   MRD, GIF. The string 'Null' leaves a lamp as it is, so a step can change its
            %   own lamp without disturbing the others.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %   code - 1x6 cell of colour names, or 'Null' to leave a lamp alone
            %
            % Output:
            %   none, the lamp colours change.

            if ~strcmp(code{1},'Null') app.Lamp1.Color = code{1}; end %#ok<*SEPEX>
            if ~strcmp(code{2},'Null') app.Lamp2.Color = code{2}; end
            if ~strcmp(code{3},'Null') app.Lamp3.Color = code{3}; end
            if ~strcmp(code{5},'Null') app.Lamp5.Color = code{5}; end
            if ~strcmp(code{6},'Null') app.Lamp6.Color = code{6}; end
            drawnow;

        end


        % Set the buttons
        function SetButtonsFcn(app,code)

            % Purpose:
            %   Enable or disable the six main action buttons.
            %
            % How it works:
            %   One entry of code per button, in the same order as the lamps. The string
            %   'Null' leaves a button as it is, so a step can change its own button without
            %   disturbing the others.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %   code - 1x7 cell of 'On', 'Off' or 'Null'
            %
            % Output:
            %   none, the button states change.

            if ~strcmp(code{1},'Null') app.LoadMRDButton.Enable = code{1}; end
            if ~strcmp(code{2},'Null') app.RecoButton.Enable = code{2}; end
            if ~strcmp(code{3},'Null') app.DCMNIFTIButton.Enable = code{3}; end
            if ~strcmp(code{5},'Null') app.MRDButton.Enable = code{5}; end
            if ~strcmp(code{6},'Null') app.GIFButton.Enable = code{6}; end
            if ~strcmp(code{7},'Null') app.ExitButton.Enable = code{7}; end
            drawnow;

        end


        % Set application colors
        function SetAppColorsFcn(app, guiColors)

            % Purpose:
            %   Apply a colour scheme to every component in the app.
            %
            % How it works:
            %   Unpacks the eight colours into named roles -- background, font, box, gauge,
            %   gauge background, figure, movie figure and grid -- and then assigns them
            %   component by component. The list is explicit rather than looped because the
            %   components do not all take the same colour property.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %   guiColors - 1x8 cell of RGB triplets, in the order the roles are
            %               unpacked at the top of the function
            %
            % Output:
            %   none, the app colours change.

            backgroundColor = guiColors{1};
            fontColor = guiColors{2};
            boxColor = guiColors{3};
            gaugeColor = guiColors{4};
            gaugeBackgroundColor = guiColors{5};
            figureColor = guiColors{6};
            movieFigureColor = guiColors{7};
            % gridColor = guiColors{8};

            % Main tabs
            app.P2ROUD_UIFigure.Color = backgroundColor;
            app.DashboardTab.BackgroundColor = backgroundColor;
            app.KspaceTab.BackgroundColor = backgroundColor;
            app.CoilsTab.BackgroundColor = backgroundColor;
            app.RadialTab.BackgroundColor = backgroundColor;
            app.ScanParametersTab.BackgroundColor = backgroundColor;
            app.GeometryTab.BackgroundColor = backgroundColor;
            app.ReconstructionTab.BackgroundColor = backgroundColor;
            app.ImagesTab.BackgroundColor = backgroundColor;
            app.MIPTab.BackgroundColor = backgroundColor;
            app.PhaseTab.BackgroundColor = backgroundColor;
            app.MessagesTab.BackgroundColor = backgroundColor;
            app.SettingsTab.BackgroundColor = backgroundColor;

            % Panels & button groups
            app.ReconstructionDimensionsPanel.BackgroundColor = backgroundColor;
            app.ReconstructionParametersPanel.BackgroundColor = backgroundColor;
            app.ExportPanel.BackgroundColor = backgroundColor;
            app.ButtonGroup.BackgroundColor = backgroundColor;
            app.CoilSelectionPanel.BackgroundColor = backgroundColor;
            app.SensitivityMapsPanel.BackgroundColor = backgroundColor;
            app.GradientdelaysPanel.BackgroundColor = backgroundColor;
            app.CorrectionsPanel.BackgroundColor = backgroundColor;
            app.ColorsButtonGroup.BackgroundColor = backgroundColor;
            app.MessageBox.FontColor = boxColor;
            app.MessageBox.BackgroundColor = figureColor;
            app.StatusBox.BackgroundColor = figureColor;
            app.ZTEPanel.BackgroundColor = backgroundColor;

            % Fonts
            app.XEditFieldLabel.FontColor = fontColor;
            app.YEditFieldLabel.FontColor = fontColor;
            app.ZEditFieldLabel.FontColor = fontColor;
            app.NREditFieldLabel.FontColor = fontColor;
            app.WVxyzEditFieldLabel.FontColor = fontColor;
            app.TVxyzEditFieldLabel.FontColor = fontColor;
            app.LLRxyzEditFieldLabel.FontColor = fontColor;
            app.TVtimeEditFieldLabel.FontColor = fontColor;
            app.CSRecoLabel.FontColor = fontColor;
            app.PCAdeNoiseLabel.FontColor = fontColor;
            app.PCAdeNoiseWindowLabel.FontColor = fontColor;
            app.BMDdeNoiseLabel.FontColor = fontColor;
            app.DeRingLabel.FontColor = fontColor;
            app.ZTEShiftLabel.FontColor = fontColor;
            app.ZTEDeadTimeLabel.FontColor = fontColor;
            app.ZTEDeadTimeCalibrationLabel.FontColor = fontColor;
            app.ZTEFullEchoLabel.FontColor = fontColor;
            app.ZTEIterationsLabel.FontColor = fontColor;
            app.SumEchosLabel.FontColor = fontColor;
            app.SumRepetitionsLabel.FontColor = fontColor;

            app.KspaceMovieLabel.FontColor = fontColor;
            app.KyLabel.FontColor = fontColor;
            app.KxLabel.FontColor = fontColor;
            app.KDim3Label.FontColor = fontColor;
            app.KDim3LabelS.FontColor = fontColor;
            app.KDim4Label.FontColor = fontColor;
            app.KFlipAngleEditFieldLabel.FontColor = fontColor;
            app.KEchoEditFieldLabel.FontColor = fontColor;
            app.KOrientationSpinnerLabel.FontColor = fontColor;
            app.FillingPercLabel.FontColor = fontColor;
            app.fillingButton.FontColor = fontColor;
            app.averagesButton.FontColor = fontColor;
            app.kValuesButton.FontColor = fontColor;
            app.senseMapButton.FontColor = fontColor;
            app.CoilSpinnerLabel.FontColor = fontColor;
            app.KLLabel.FontColor = fontColor;
            app.KWLabel.FontColor = fontColor;
            app.FastSlowSwitch.FontColor = fontColor;

            app.CoilLabel.FontColor = fontColor;
            app.RelativeSensitivityLabel.FontColor = fontColor;
            app.AutoSensitivityCheckBox.FontColor = fontColor;
            app.C1CheckBox.FontColor = fontColor;
            app.C2CheckBox.FontColor = fontColor;
            app.C3CheckBox.FontColor = fontColor;
            app.C4CheckBox.FontColor = fontColor;
            app.C5CheckBox.FontColor = fontColor;
            app.C6CheckBox.FontColor = fontColor;
            app.C7CheckBox.FontColor = fontColor;
            app.C8CheckBox.FontColor = fontColor;
            app.Label1.FontColor = fontColor;
            app.Label2.FontColor = fontColor;
            app.Label3.FontColor = fontColor;
            app.Label4.FontColor = fontColor;
            app.Label5.FontColor = fontColor;
            app.Label6.FontColor = fontColor;
            app.Label7.FontColor = fontColor;
            app.Label8.FontColor = fontColor;

            app.GradDelayCalibrationCheckBoxLabel.FontColor = fontColor;
            app.RingMethodCheckBoxLabel.FontColor = fontColor;
            app.GxdelayEditFieldLabel.FontColor = fontColor;
            app.GydelayEditFieldLabel.FontColor = fontColor;
            app.GzdelayEditFieldLabel.FontColor = fontColor;
            app.SxydelayEditFieldLabel.FontColor = fontColor;
            app.SxzdelayEditFieldLabel.FontColor = fontColor;
            app.SyzdelayEditFieldLabel.FontColor = fontColor;
            app.DataOffsetRadialEditFieldLabel.FontColor = fontColor;
            app.CenterEchoCheckBoxLabel.FontColor = fontColor;
            app.PhaseCorrectCheckBoxLabel.FontColor = fontColor;
            app.AmplitudeCorrectionCheckBoxLabel.FontColor = fontColor;
            app.DensityCorrectCheckBoxLabel.FontColor = fontColor;
            app.TukeyFilterCheckBoxLabel.FontColor = fontColor;

            app.TRViewFieldLabel.FontColor = fontColor;
            app.TEViewFieldLabel.FontColor = fontColor;
            app.NEViewFieldLabel.FontColor = fontColor;
            app.NAViewFieldLabel.FontColor = fontColor;
            app.NFAViewFieldLabel.FontColor = fontColor;
            app.NRViewFieldLabel.FontColor = fontColor;
            app.MRDfileViewFieldLabel.FontColor = fontColor;
            app.SequenceViewFieldLabel.FontColor = fontColor;
            app.ScanTimeViewFieldLabel.FontColor = fontColor;
            app.TimeDynViewFieldLabel.FontColor = fontColor;
            app.FAViewFieldLabel.FontColor = fontColor;
            app.TrajectoryViewFieldLabel.FontColor = fontColor;
            app.VencViewFieldLabel.FontColor = fontColor;
            app.MXLabel.FontColor = fontColor;
            app.MYLabel.FontColor = fontColor;
            app.MZLabel.FontColor = fontColor;
            app.KMatrixViewField1Label.FontColor = fontColor;
            app.FOVViewField1Label.FontColor = fontColor;
            app.MessageLevelSpinnerLabel.FontColor = fontColor;

            app.FieldOfViewViewField2Label.FontColor = fontColor;
            app.AspectRatioViewFieldLabel.FontColor = fontColor;
            app.PhaseOrientationViewFieldLabel.FontColor = fontColor;
            app.SliceThicknessViewFieldLabel.FontColor = fontColor;
            app.SliceGapViewFieldLabel.FontColor = fontColor;
            app.SlabOverlapEditFieldLabel.FontColor = fontColor;
            app.OffsetXViewFieldLabel.FontColor = fontColor;
            app.OffsetYViewFieldLabel.FontColor = fontColor;
            app.OffsetZViewFieldLabel.FontColor = fontColor;
            app.AngleXViewFieldLabel.FontColor = fontColor;
            app.AngleYViewFieldLabel.FontColor = fontColor;
            app.AngleZViewFieldLabel.FontColor = fontColor;

            app.XLabel.FontColor = fontColor;
            app.YLabel.FontColor = fontColor;
            app.ThresholdEditFieldLabel.FontColor = fontColor;
            app.AutoIntensityCheckBoxLabel.FontColor = fontColor;
            app.WLabel.FontColor = fontColor;
            app.LLabel.FontColor = fontColor;
            app.OrientationSpinnerLabel.FontColor = fontColor;
            app.Dim6Label.FontColor = fontColor;
            app.Dim5Label.FontColor = fontColor;
            app.Dim4Label.FontColor = fontColor;
            app.Dim3Label.FontColor = fontColor;

            app.MIPXLabel.FontColor = fontColor;
            app.MIPYLabel.FontColor = fontColor;
            app.MIPWLabel.FontColor = fontColor;
            app.MIPLLabel.FontColor = fontColor;
            app.MIPOrientationSpinnerLabel.FontColor = fontColor;
            app.MIPxAngleLabel.FontColor = fontColor;
            app.MIPyAngleLabel.FontColor = fontColor;
            app.MIPRotateLabel.FontColor = fontColor;

            app.PhaseXLabel.FontColor = fontColor;
            app.PhaseYLabel.FontColor = fontColor;
            app.PhaseOrientationSpinnerLabel.FontColor = fontColor;
            app.PhaseMinLabel.FontColor = fontColor;
            app.PhaseMaxLabel.FontColor = fontColor;
            app.PhaseDim6Label.FontColor = fontColor;
            app.PhaseDim5Label.FontColor = fontColor;
            app.PhaseDim4Label.FontColor = fontColor;
            app.PhaseDim3Label.FontColor = fontColor;

            app.ClassicButton.FontColor = fontColor;
            app.BlackWhiteButton.FontColor = fontColor;
            app.AutoSoundsCheckBoxLabel.FontColor = fontColor;

            % Gauges
            app.RecoProgressGauge.FontColor = gaugeColor;
            app.RecoProgressGauge.BackgroundColor = gaugeBackgroundColor;
            app.ExportProgressGauge.FontColor = gaugeColor;
            app.ExportProgressGauge.BackgroundColor = gaugeBackgroundColor;

            % Images
            app.KspaceFig.Color = movieFigureColor;
            app.KspaceFig.XColor = movieFigureColor;
            app.KspaceFig.YColor = movieFigureColor;

            app.RecoFig.Color = movieFigureColor;
            app.RecoFig.XColor = movieFigureColor;
            app.RecoFig.YColor = movieFigureColor;

            app.MIPFig.Color = movieFigureColor;
            app.MIPFig.XColor = movieFigureColor;
            app.MIPFig.YColor = movieFigureColor;

            app.PhaseRecoFig.Color = movieFigureColor;
            app.PhaseRecoFig.XColor = movieFigureColor;
            app.PhaseRecoFig.YColor = movieFigureColor;

            app.SenseFig1.Color = movieFigureColor;
            app.SenseFig1.XColor = movieFigureColor;
            app.SenseFig1.YColor = movieFigureColor;

            app.SenseFig2.Color = movieFigureColor;
            app.SenseFig2.XColor = movieFigureColor;
            app.SenseFig2.YColor = movieFigureColor;

            app.SenseFig3.Color = movieFigureColor;
            app.SenseFig3.XColor = movieFigureColor;
            app.SenseFig3.YColor = movieFigureColor;

            app.SenseFig4.Color = movieFigureColor;
            app.SenseFig4.XColor = movieFigureColor;
            app.SenseFig4.YColor = movieFigureColor;

            app.SenseFig5.Color = movieFigureColor;
            app.SenseFig5.XColor = movieFigureColor;
            app.SenseFig5.YColor = movieFigureColor;

            app.SenseFig6.Color = movieFigureColor;
            app.SenseFig6.XColor = movieFigureColor;
            app.SenseFig6.YColor = movieFigureColor;

            app.SenseFig7.Color = movieFigureColor;
            app.SenseFig7.XColor = movieFigureColor;
            app.SenseFig7.YColor = movieFigureColor;

            app.SenseFig8.Color = movieFigureColor;
            app.SenseFig8.XColor = movieFigureColor;
            app.SenseFig8.YColor = movieFigureColor;

        end % SetAppColorsFcn


        % Switch off all buttons
        function SwitchOffAllButtonsFcn(app)

            % Purpose:
            %   Disable all six main action buttons at once.
            %
            % How it works:
            %   Calls SetButtonsFcn with 'Off' for every button. Used at the start of a long
            %   operation so that nothing else can be started while it runs.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the app controls are updated.

            app.SetButtonsFcn({'Off','Off','Off','Off','Off','Off','Off'});

        end


        % Disable all labels
        function DisableLabelsFcn(app)

            % Purpose:
            %   Disable the k-space, image, coil and reconstruction controls together.
            %
            % How it works:
            %   Calls the four Disable*Fcn helpers in turn. Grouping them here means a long
            %   operation has one call to make rather than four.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the app controls are updated.

            app.DisableKbuttonsFcn;
            app.DisableImageButtonsFcn;
            app.DisableCoilButtonsFcn;
            app.DisableRecoButtonsFcn;

        end


        % Enable all labels
        function EnableLabelsFcn(app)

            % Purpose:
            %   Re-enable the k-space, image, coil and reconstruction controls together.
            %
            % How it works:
            %   The counterpart of DisableLabelsFcn, calling the four Enable*Fcn helpers.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the app controls are updated.

            app.EnableKbuttonsFcn;
            app.EnableImageButtonsFcn;
            app.EnableCoilButtonsFcn;
            app.EnableRecoButtonsFcn;

        end


        % Enable/disable reco parameters
        function SetRecoFieldsFcn(app)

            % Purpose:
            %   Show only the reconstruction fields that apply to the chosen method.
            %
            % How it works:
            %   The compressed sensing regularisation fields -- wavelet, total variation,
            %   locally low rank and total variation over time -- are only meaningful when
            %   the CS box is ticked, so they and their labels follow it.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the app controls are updated.

            if app.CSRecoCheckBox.Value == 1
                app.WVxyzEditField.Enable = 'on';
                app.TVxyzEditField.Enable = 'on';
                app.LLRxyzEditField.Enable = 'on';
                app.TVtimeEditField.Enable = 'on';
                app.WVxyzEditFieldLabel.Enable = 'on';
                app.TVxyzEditFieldLabel.Enable = 'on';
                app.LLRxyzEditFieldLabel.Enable = 'on';
                app.TVtimeEditFieldLabel.Enable = 'on';
            else
                app.WVxyzEditField.Enable = 'off';
                app.TVxyzEditField.Enable = 'off';
                app.LLRxyzEditField.Enable = 'off';
                app.TVtimeEditField.Enable = 'off';
                app.WVxyzEditFieldLabel.Enable = 'off';
                app.TVxyzEditFieldLabel.Enable = 'off';
                app.LLRxyzEditFieldLabel.Enable = 'off';
                app.TVtimeEditFieldLabel.Enable = 'off';
            end

            if app.bartDetectedFlag && app.CSRecoCheckBox.Value && contains(app.pd.dataType,["ZTE","2Dradial","3Dute"])
                app.ZTEIterationsLabel.Enable = 'on';
                app.ZTEIterationsEditField.Enable = 'on';
            end

            if ~app.bartDetectedFlag
                app.TVxyzEditField.Enable = 'off';
                app.LLRxyzEditField.Enable = 'off';
                app.TVxyzEditFieldLabel.Enable = 'off';
                app.LLRxyzEditFieldLabel.Enable = 'off';
                app.ZTEIterationsLabel.Enable = 'off';
                app.ZTEIterationsEditField.Enable = 'off';
            end

            app.EnableRadialButtonsFcn;

            drawnow;

        end


        % Disable reco buttons
        function DisableRecoButtonsFcn(app)

            % Purpose:
            %   Disable the reconstruction controls.
            %
            % How it works:
            %   Switches off the matrix size fields, the regularisation fields, the method
            %   check boxes and the post-processing options, so that none of them can be
            %   changed while a reconstruction is running.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the app controls are updated.

            app.XEditField.Enable = 'off';
            app.YEditField.Enable = 'off';
            app.ZEditField.Enable = 'off';
            app.NREditField.Enable = 'off';
            app.WVxyzEditField.Enable= 'off';
            app.TVxyzEditField.Enable = 'off';
            app.LLRxyzEditField.Enable = 'off';
            app.TVtimeEditField.Enable = 'off';
            app.CSRecoCheckBox.Enable = 'off';
            app.PCAdeNoiseCheckBox.Enable = 'off';
            app.BMDdeNoiseCheckBox.Enable = 'off';
            app.PCAdeNoiseWindowEditField.Enable = 'off';
            app.DeRingCheckBox.Enable = 'off';
            app.AbortRecoButton.Enable = 'off';

            app.XEditFieldLabel.Enable = 'off';
            app.YEditFieldLabel.Enable = 'off';
            app.ZEditFieldLabel.Enable = 'off';
            app.NREditFieldLabel.Enable = 'off';
            app.WVxyzEditFieldLabel.Enable = 'off';
            app.TVxyzEditFieldLabel.Enable = 'off';
            app.LLRxyzEditFieldLabel.Enable = 'off';
            app.TVtimeEditFieldLabel.Enable = 'off';
            app.CSRecoLabel.Enable = 'off';
            app.PCAdeNoiseLabel.Enable = 'off';
            app.PCAdeNoiseWindowLabel.Enable = 'off';
            app.BMDdeNoiseLabel.Enable = 'off';
            app.DeRingLabel.Enable = 'off';

            app.ZTEFullEchoLabel.Enable = 'off';
            app.ZTEFullEchoCheckBox.Enable = 'off';
            app.ZTEShiftLabel.Enable = 'off';
            app.ZTEShiftEditField.Enable = 'off';
            app.ZTEDeadTimeLabel.Enable = 'off';
            app.ZTEDeadTimeEditField.Enable = 'off';
            app.ZTEDeadTimeCalibrationCheckBox.Enable = 'off';
            app.ZTEDeadTimeCalibrationLabel.Enable = 'off';
            app.ZTEIterationsLabel.Enable = 'off';
            app.ZTEIterationsEditField.Enable = 'off';

            app.SumEchosCheckBox.Enable = 'off';
            app.SumEchosLabel.Enable = 'off';
            app.SumTypeNeDropDown.Enable = 'off';
            app.IncludeNeEditField.Enable = 'off';
            if app.NEViewField.Value == 1
                app.SumEchosCheckBox.Value = 0;
            end

            app.SumRepetitionsCheckBox.Enable = 'off';
            app.SumRepetitionsLabel.Enable = 'off';
            app.SumTypeNrDropDown.Enable = 'off';
            app.IncludeNrEditField.Enable = 'off';
            if app.NRViewField.Value == 1
                app.SumRepetitionsCheckBox.Value = 0;
            end

            app.DisableRadialButtonsFcn;

            drawnow;

        end


        % Enable reco buttons
        function EnableRecoButtonsFcn(app)

            % Purpose:
            %   Enable the reconstruction controls.
            %
            % How it works:
            %   The counterpart of DisableRecoButtonsFcn. Controls that only apply to some
            %   data types are left to EnableRadialButtonsFcn, which knows which ones those
            %   are.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the app controls are updated.

            app.XEditField.Enable = 'on';
            app.YEditField.Enable = 'on';
            app.ZEditField.Enable = 'on';
            app.NREditField.Enable = 'on';
            app.WVxyzEditField.Enable= 'on';
            app.TVxyzEditField.Enable = 'on';
            app.LLRxyzEditField.Enable = 'on';
            app.TVtimeEditField.Enable = 'on';
            app.CSRecoCheckBox.Enable = 'on';
            app.PCAdeNoiseCheckBox.Enable = 'on';
            app.BMDdeNoiseCheckBox.Enable = 'on';
            app.PCAdeNoiseWindowEditField.Enable = 'on';
            app.DeRingCheckBox.Enable = 'on';
            app.AbortRecoButton.Enable = 'off';

            app.XEditFieldLabel.Enable = 'on';
            app.YEditFieldLabel.Enable = 'on';
            app.ZEditFieldLabel.Enable = 'on';
            app.NREditFieldLabel.Enable = 'on';
            app.WVxyzEditFieldLabel.Enable = 'on';
            app.TVxyzEditFieldLabel.Enable = 'on';
            app.LLRxyzEditFieldLabel.Enable = 'on';
            app.TVtimeEditFieldLabel.Enable = 'on';
            app.CSRecoLabel.Enable = 'on';
            app.PCAdeNoiseLabel.Enable = 'on';
            app.PCAdeNoiseWindowLabel.Enable = 'on';
            app.BMDdeNoiseLabel.Enable = 'on';
            app.DeRingLabel.Enable = 'on';

            if app.pd.dataType == "ZTE"
                app.ZTEFullEchoLabel.Enable = 'on';
                app.ZTEFullEchoCheckBox.Enable = 'on';
                app.ZTEShiftLabel.Enable = 'on';
                app.ZTEShiftEditField.Enable = 'on';
                app.ZTEDeadTimeLabel.Enable = 'on';
                app.ZTEDeadTimeEditField.Enable = 'on';
                app.ZTEDeadTimeCalibrationCheckBox.Enable = 'on';
                app.ZTEDeadTimeCalibrationLabel.Enable = 'on';
            else
                app.ZTEFullEchoLabel.Enable = 'off';
                app.ZTEFullEchoCheckBox.Enable = 'off';
                app.ZTEShiftLabel.Enable = 'off';
                app.ZTEShiftEditField.Enable = 'off';
                app.ZTEDeadTimeLabel.Enable = 'off';
                app.ZTEDeadTimeEditField.Enable = 'off';
                app.ZTEDeadTimeCalibrationCheckBox.Enable = 'off';
                app.ZTEDeadTimeCalibrationLabel.Enable = 'off';
            end

            if contains(app.pd.dataType,["ZTE","2Dradial","3Dute"])
                app.ZTEIterationsLabel.Enable = 'off';
                app.ZTEIterationsEditField.Enable = 'off';
                if app.bartDetectedFlag && app.CSRecoCheckBox.Value
                    app.ZTEIterationsLabel.Enable = 'on';
                    app.ZTEIterationsEditField.Enable = 'on';
                end
            else
                app.ZTEIterationsLabel.Enable = 'off';
                app.ZTEIterationsEditField.Enable = 'off';
            end

            if app.NEViewField.Value > 1
                app.SumEchosCheckBox.Enable = 'on';
                app.SumEchosLabel.Enable = 'on';
                app.IncludeNeEditField.Enable = 'on';
                app.SumTypeNeDropDown.Enable = 'on';
            end

            if app.NRViewField.Value > 1
                app.SumRepetitionsCheckBox.Enable = 'on';
                app.SumRepetitionsLabel.Enable = 'on';
                app.IncludeNrEditField.Enable = 'on';
                app.SumTypeNrDropDown.Enable = 'on';
            end

            app.SetRecoFieldsFcn;
            drawnow;

        end


        % Disable k-buttons
        function DisableKbuttonsFcn(app)

            % Purpose:
            %   Disable the k-space viewer controls, remembering their state.
            %
            % How it works:
            %   Saves the current Enable state of every k-space control into
            %   app.kSpaceButtonStatus before switching them off, so that
            %   EnableKbuttonsFcn can put back exactly what was there rather than switching
            %   everything on.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the app controls are updated.

            app.kSpaceButtonStatus = get(app.kSpaceButtonHandles, 'Enable');

            app.KDim3EditField.Enable = 'off';
            app.KDim3EditFieldS.Enable = 'off';
            app.KDim3Slider.Enable = 'off';
            app.KDim3SliderS.Enable = 'off';
            app.KDim4EditField.Enable = 'off';
            app.KDim4Slider.Enable = 'off';
            app.KFlipAngleEditField.Enable = 'off';
            app.KFlipAngleSlider.Enable = 'off';
            app.KEchoEditField.Enable = 'off';
            app.KEchoSlider.Enable = 'off';
            app.KOrientationSpinner.Enable = 'off';
            app.fillingButton.Enable = 'off';
            app.averagesButton.Enable = 'off';
            app.kValuesButton.Enable = 'off';
            app.senseMapButton.Enable = 'off';
            app.CoilSpinner.Enable = 'off';
            app.KspaceMovieLabel.Enable = 'off';
            app.KStartButton.Enable = 'off';
            app.KStopButton.Enable = 'off';
            app.FastSlowSwitch.Enable = 'off';
            app.KLevelEditField.Enable = 'off';
            app.KWindowEditField.Enable = 'off';
            app.KLevelSlider.Enable = 'off';
            app.KWindowSlider.Enable = 'off';
            app.CoilSpinnerLabel.Enable = 'off';
            app.FillingViewField.Enable = 'off';
            app.FillingPercLabel.Enable = 'off';
            app.KOrientationSpinnerLabel.Enable = 'off';
            app.KEchoEditFieldLabel.Enable = 'off';
            app.KFlipAngleEditFieldLabel.Enable = 'off';
            app.KDim4Label.Enable = 'off';
            app.KDim3Label.Enable = 'off';
            app.KDim3LabelS.Enable = 'off';
            app.KLLabel.Enable = 'off';
            app.KWLabel.Enable = 'off';
            app.KxLabel.Enable = 'off';
            app.KyLabel.Enable = 'off';

            drawnow;

        end


        % Enable k-buttons
        function EnableKbuttonsFcn(app)

            % Purpose:
            %   Restore the k-space viewer controls to the state they were in.
            %
            % How it works:
            %   Writes app.kSpaceButtonStatus back onto the handles in one call. This is why
            %   the disable step saves the state: controls that were off because they do not
            %   apply to the data must stay off.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the app controls are updated.

            % Restore the Enable states the matching Disable saved
            set(app.kSpaceButtonHandles, {'Enable'}, app.kSpaceButtonStatus);

            drawnow;

        end % EnableKbuttonsFcn


        % Disable image buttons
        function DisableImageButtonsFcn(app)

            % Purpose:
            %   Disable the image viewer controls, remembering their state.
            %
            % How it works:
            %   Saves the current Enable state of every image control into
            %   app.imageButtonStatus before switching them off, so that
            %   EnableImageButtonsFcn can restore exactly what was there.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the app controls are updated.

            app.imageButtonStatus = get(app.imageButtonHandles, 'Enable');

            app.MaskButton.Enable = 'off';
            app.ThresholdSlider.Enable = 'off';
            app.ThresholdEditField.Enable = 'off';
            app.ErodeButton.Enable = 'off';
            app.DilateButton.Enable = 'off';
            app.CloseButton.Enable = 'off';
            app.OpenButton.Enable = 'off';
            app.AutoIntensityCheckBox.Enable = 'off';
            app.SetButton.Enable = 'off';
            app.WindowSlider.Enable = 'off';
            app.LevelSlider.Enable = 'off';
            app.OrientationSpinner.Enable = 'off';
            app.Dim6Slider.Enable = 'off';
            app.Dim5Slider.Enable = 'off';
            app.Dim4Slider.Enable = 'off';
            app.Dim3Slider.Enable = 'off';
            app.Dim6EditField.Enable = 'off';
            app.Dim5EditField.Enable = 'off';
            app.Dim4EditField.Enable = 'off';
            app.Dim3EditField.Enable = 'off';
            app.XLabel.Enable = 'off';
            app.YLabel.Enable = 'off';
            app.MoviePlayButton.Enable = 'off';
            app.MovieStopButton.Enable = 'off';
            app.ThresholdEditFieldLabel.Enable = 'off';
            app.WindowEditField.Enable = 'off';
            app.LevelEditField.Enable = 'off';
            app.OrientationSpinnerLabel.Enable = 'off';
            app.Dim6Label.Enable = 'off';
            app.Dim5Label.Enable = 'off';
            app.Dim4Label.Enable = 'off';
            app.Dim3Label.Enable = 'off';
            app.AutoIntensityCheckBoxLabel.Enable = 'off';
            app.LLabel.Enable = 'off';
            app.WLabel.Enable = 'off';

            app.MIPXLabel.Enable = 'off';
            app.MIPYLabel.Enable = 'off';
            app.MIPWindowEditField.Enable = 'off';
            app.MIPLevelEditField.Enable = 'off';
            app.MIPSetButton.Enable = 'off';
            app.MIPWindowSlider.Enable = 'off';
            app.MIPLevelSlider.Enable = 'off';
            app.MIPWLabel.Enable = 'off';
            app.MIPLLabel.Enable = 'off';
            app.MIPOrientationSpinner.Enable = 'off';
            app.MIPOrientationSpinnerLabel.Enable = 'off';
            app.MIPResizeButton.Enable = 'off';
            app.MIPAngleResetButton.Enable = 'off';
            app.MIPxAngleSlider.Enable = 'off';
            app.MIPyAngleSlider.Enable = 'off';
            app.MIPxAngleLabel.Enable = 'off';
            app.MIPyAngleLabel.Enable = 'off';
            app.MIPxPlayButton.Enable = 'off';
            app.MIPyPlayButton.Enable = 'off';
            app.MIPRotateLabel.Enable = 'off';

            app.PhaseYLabel.Enable = 'off';
            app.PhaseXLabel.Enable = 'off';
            app.PhaseUnwrapButton.Enable = 'off';
            app.PhaseOrientationSpinner.Enable = 'off';
            app.PhaseCorrectCheckBoxLabel.Enable = 'off';
            app.PhaseMinLabel.Enable = 'off';
            app.PhaseMaxLabel.Enable = 'off';
            app.PhaseMovieStartButton.Enable = 'off';
            app.PhaseMovieStopButton.Enable = 'off';
            app.PhaseDim3Label.Enable = 'off';
            app.PhaseDim3EditField.Enable = 'off';
            app.PhaseDim3Slider.Enable = 'off';
            app.PhaseDim4Label.Enable = 'off';
            app.PhaseDim4EditField.Enable = 'off';
            app.PhaseDim4Slider.Enable = 'off';
            app.PhaseDim5Label.Enable = 'off';
            app.PhaseDim5EditField.Enable = 'off';
            app.PhaseDim5Slider.Enable = 'off';
            app.PhaseDim6Label.Enable = 'off';
            app.PhaseDim6EditField.Enable = 'off';
            app.PhaseDim6Slider.Enable = 'off';

            app.PhaseOrientationSpinnerLabel.Enable = 'off';

            drawnow;

        end


        % Enable image buttons
        function EnableImageButtonsFcn(app)

            % Purpose:
            %   Restore the image viewer controls to the state they were in.
            %
            % How it works:
            %   Writes app.imageButtonStatus back onto the handles in one call, for the same
            %   reason as EnableKbuttonsFcn.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the app controls are updated.

            % Restore the Enable states the matching Disable saved
            set(app.imageButtonHandles, {'Enable'}, app.imageButtonStatus);

            drawnow;

        end % EnableImageButtonsFcn


        % Reset coil buttons
        function ResetCoilButtonsFcn(app)

            % Purpose:
            %   Set the coil check boxes up for a newly loaded data set.
            %
            % How it works:
            %   Enables and ticks one check box per coil the data actually has, and disables
            %   the rest. Coil 1 is always on, because a reconstruction needs at least one.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the app controls are updated.

            app.C1CheckBox.Value = 1;
            app.C1CheckBox.Enable = 'on';
            app.Label1.Enable = 'on';
            if app.pd.nrCoils > 1 app.C2CheckBox.Enable = 'on'; app.C2CheckBox.Value = 1; app.Label2.Enable = 'on'; else app.C2CheckBox.Enable = 'off'; app.C2CheckBox.Value = 0; app.Label2.Enable = 'off'; end
            if app.pd.nrCoils > 2 app.C3CheckBox.Enable = 'on'; app.C3CheckBox.Value = 1; app.Label3.Enable = 'on'; else app.C3CheckBox.Enable = 'off'; app.C3CheckBox.Value = 0; app.Label3.Enable = 'off'; end
            if app.pd.nrCoils > 3 app.C4CheckBox.Enable = 'on'; app.C4CheckBox.Value = 1; app.Label4.Enable = 'on'; else app.C4CheckBox.Enable = 'off'; app.C4CheckBox.Value = 0; app.Label4.Enable = 'off'; end
            if app.pd.nrCoils > 4 app.C5CheckBox.Enable = 'on'; app.C5CheckBox.Value = 1; app.Label5.Enable = 'on'; else app.C5CheckBox.Enable = 'off'; app.C5CheckBox.Value = 0; app.Label5.Enable = 'off'; end
            if app.pd.nrCoils > 5 app.C6CheckBox.Enable = 'on'; app.C6CheckBox.Value = 1; app.Label6.Enable = 'on'; else app.C6CheckBox.Enable = 'off'; app.C6CheckBox.Value = 0; app.Label6.Enable = 'off'; end
            if app.pd.nrCoils > 6 app.C7CheckBox.Enable = 'on'; app.C7CheckBox.Value = 1; app.Label7.Enable = 'on'; else app.C7CheckBox.Enable = 'off'; app.C7CheckBox.Value = 0; app.Label7.Enable = 'off'; end
            if app.pd.nrCoils > 7 app.C8CheckBox.Enable = 'on'; app.C8CheckBox.Value = 1; app.Label8.Enable = 'on'; else app.C8CheckBox.Enable = 'off'; app.C8CheckBox.Value = 0; app.Label8.Enable = 'off'; end

            app.S1EditField.Value = 1;
            app.S2EditField.Value = 1;
            app.S3EditField.Value = 1;
            app.S4EditField.Value = 1;
            app.S5EditField.Value = 1;
            app.S6EditField.Value = 1;
            app.S7EditField.Value = 1;
            app.S8EditField.Value = 1;

            app.pd.coilSensitivities = [app.S1EditField.Value app.S2EditField.Value app.S3EditField.Value app.S4EditField.Value ...
                app.S5EditField.Value app.S6EditField.Value app.S7EditField.Value app.S8EditField.Value];

            app.pd.coilActiveFlag = [app.C1CheckBox.Value app.C2CheckBox.Value app.C3CheckBox.Value app.C4CheckBox.Value ...
                app.C5CheckBox.Value app.C6CheckBox.Value app.C7CheckBox.Value app.C8CheckBox.Value];

            app.S1EditField.Enable = 'on';
            if app.pd.nrCoils > 1 app.S2EditField.Enable = 'on'; else app.S2EditField.Enable = 'off'; end
            if app.pd.nrCoils > 2 app.S3EditField.Enable = 'on'; else app.S3EditField.Enable = 'off'; end
            if app.pd.nrCoils > 3 app.S4EditField.Enable = 'on'; else app.S4EditField.Enable = 'off'; end
            if app.pd.nrCoils > 4 app.S5EditField.Enable = 'on'; else app.S5EditField.Enable = 'off'; end
            if app.pd.nrCoils > 5 app.S6EditField.Enable = 'on'; else app.S6EditField.Enable = 'off'; end
            if app.pd.nrCoils > 6 app.S7EditField.Enable = 'on'; else app.S7EditField.Enable = 'off'; end
            if app.pd.nrCoils > 7 app.S8EditField.Enable = 'on'; else app.S8EditField.Enable = 'off'; end

            app.AutoSensitivityCheckBox.Value = 0;
            app.AutoSensitivityCheckBox.Enable = 'on';

            drawnow

        end


        % Enable coil buttons
        function EnableCoilButtonsFcn(app)

            % Purpose:
            %   Enable the coil controls that the loaded data has coils for.
            %
            % How it works:
            %   Enables a check box and label per acquired coil and disables the others,
            %   leaving the ticks alone so that the user's choice of coils survives. The
            %   sensitivity fields follow the automatic sensitivity box.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the app controls are updated.

            app.C1CheckBox.Enable = 'on';
            app.Label1.Enable = 'on';
            if app.pd.nrCoils > 1 app.C2CheckBox.Enable = 'on'; app.Label2.Enable = 'on'; else app.C2CheckBox.Enable = 'off'; app.Label2.Enable = 'off'; end
            if app.pd.nrCoils > 2 app.C3CheckBox.Enable = 'on'; app.Label3.Enable = 'on'; else app.C3CheckBox.Enable = 'off'; app.Label3.Enable = 'off'; end
            if app.pd.nrCoils > 3 app.C4CheckBox.Enable = 'on'; app.Label4.Enable = 'on'; else app.C4CheckBox.Enable = 'off'; app.Label4.Enable = 'off'; end
            if app.pd.nrCoils > 4 app.C5CheckBox.Enable = 'on'; app.Label5.Enable = 'on'; else app.C5CheckBox.Enable = 'off'; app.Label5.Enable = 'off'; end
            if app.pd.nrCoils > 5 app.C6CheckBox.Enable = 'on'; app.Label6.Enable = 'on'; else app.C6CheckBox.Enable = 'off'; app.Label6.Enable = 'off'; end
            if app.pd.nrCoils > 6 app.C7CheckBox.Enable = 'on'; app.Label7.Enable = 'on'; else app.C7CheckBox.Enable = 'off'; app.Label7.Enable = 'off'; end
            if app.pd.nrCoils > 7 app.C8CheckBox.Enable = 'on'; app.Label8.Enable = 'on'; else app.C8CheckBox.Enable = 'off'; app.Label8.Enable = 'off'; end

            app.CoilLabel.Enable = 'on';
            app.RelativeSensitivityLabel.Enable = 'on';
            app.AutoSensitivityCheckBox.Enable = 'on';
            if app.AutoSensitivityCheckBox.Value == 0

                app.S1EditField.Enable = 'on';
                if app.pd.nrCoils > 1 app.S2EditField.Enable = 'on'; else app.S2EditField.Enable = 'off'; end
                if app.pd.nrCoils > 2 app.S3EditField.Enable = 'on'; else app.S3EditField.Enable = 'off'; end
                if app.pd.nrCoils > 3 app.S4EditField.Enable = 'on'; else app.S4EditField.Enable = 'off'; end
                if app.pd.nrCoils > 4 app.S5EditField.Enable = 'on'; else app.S5EditField.Enable = 'off'; end
                if app.pd.nrCoils > 5 app.S6EditField.Enable = 'on'; else app.S6EditField.Enable = 'off'; end
                if app.pd.nrCoils > 6 app.S7EditField.Enable = 'on'; else app.S7EditField.Enable = 'off'; end
                if app.pd.nrCoils > 7 app.S8EditField.Enable = 'on'; else app.S8EditField.Enable = 'off'; end

            else

                app.S1EditField.Enable = 'off';
                app.S2EditField.Enable = 'off';
                app.S3EditField.Enable = 'off';
                app.S4EditField.Enable = 'off';
                app.S5EditField.Enable = 'off';
                app.S6EditField.Enable = 'off';
                app.S7EditField.Enable = 'off';
                app.S8EditField.Enable = 'off';

            end

            drawnow

        end


        % Disable coil buttons
        function DisableCoilButtonsFcn(app)

            % Purpose:
            %   Disable all the coil controls.
            %
            % How it works:
            %   Switches off every coil check box, sensitivity field and label, so that the
            %   coil selection cannot change while a reconstruction is running.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the app controls are updated.

            app.CoilLabel.Enable = 'off';
            app.RelativeSensitivityLabel.Enable = 'off';
            app.AutoSensitivityCheckBox.Enable = 'off';

            app.C1CheckBox.Enable = 'off';
            app.C2CheckBox.Enable = 'off';
            app.C3CheckBox.Enable = 'off';
            app.C4CheckBox.Enable = 'off';
            app.C5CheckBox.Enable = 'off';
            app.C6CheckBox.Enable = 'off';
            app.C7CheckBox.Enable = 'off';
            app.C8CheckBox.Enable = 'off';

            app.S1EditField.Enable = 'off';
            app.S2EditField.Enable = 'off';
            app.S3EditField.Enable = 'off';
            app.S4EditField.Enable = 'off';
            app.S5EditField.Enable = 'off';
            app.S6EditField.Enable = 'off';
            app.S7EditField.Enable = 'off';
            app.S8EditField.Enable = 'off';

            app.Label1.Enable = 'off';
            app.Label2.Enable = 'off';
            app.Label3.Enable = 'off';
            app.Label4.Enable = 'off';
            app.Label5.Enable = 'off';
            app.Label6.Enable = 'off';
            app.Label7.Enable = 'off';
            app.Label8.Enable = 'off';

            drawnow

        end


        % Copy the results of loading, sorting and k-space filling into the widgets
        function MirrorDataOutputsFcn(app)

            % Purpose:
            %   Copy the sorting results from the data object back onto the app.
            %
            % How it works:
            %   The class reports what it actually did through its *Out properties, which
            %   can differ from what was asked for. Copying them into the fields keeps the
            %   app showing the sizes the data really has rather than the ones requested.
            %   Every method that sets any of these properties sets all of them, so the
            %   group is consistent whichever step ran.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the app controls are updated.

            app.XEditField.Value = app.pd.dimXOut;
            app.NREditField.Value = app.pd.nrDynamicsOut;
            app.SlabOverlapEditField.Value = app.pd.slabOverlapOut;
            app.KMatrixViewField1.Value = app.pd.kMatrixXOut;
            app.KMatrixViewField2.Value = app.pd.kMatrixYOut;
            app.KMatrixViewField3.Value = app.pd.kMatrixZOut;
            app.YEditField.Value = app.pd.dimYOut;
            app.ZEditField.Value = app.pd.dimZOut;
            app.FillingViewField.Value = app.pd.fillingPercentageOut;

        end % MirrorDataOutputsFcn


        % Set the CS regularisation starting values for one data type
        function ApplyCsDefaultsFcn(app, key)

            % Purpose:
            %   Load the compressed sensing defaults for one data type into the app.
            %
            % How it works:
            %   Starts from proud.defaultCsSettings, then overlays the user's settings file
            %   field by field where the key exists and the value is numeric. A missing or
            %   malformed entry falls back to the shipped default rather than failing, so an
            %   old settings file still works after a new key is added.
            %
            %   llrxyzGpu is applied instead of llrxyz when a GPU is present, and
            %   zteIterations only for the data types that carry it.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %   key - data type name, the field to read from the csDefaults block: 'ute3D',
            %         'cartesian3D', 'cartesian2D', 'radial2D' or 'zte'
            %
            % Output:
            %   none, the regularisation fields are set.

            fallback = proud.defaultCsSettings();
            fallback = fallback.(key);
            cs = fallback;

            if isprop(app,'jsonPar') && ~isempty(app.jsonPar) && ...
                    isfield(app.jsonPar,'csDefaults') && isfield(app.jsonPar.csDefaults, key)
                user = app.jsonPar.csDefaults.(key);
                f = fieldnames(fallback);
                for k = 1:numel(f)
                    if isfield(user, f{k}) && isnumeric(user.(f{k})) && ...
                            isscalar(user.(f{k})) && isfinite(user.(f{k}))
                        cs.(f{k}) = double(user.(f{k}));
                    end
                end
            end

            app.WVxyzEditField.Value  = cs.wvxyz;
            app.TVxyzEditField.Value  = cs.tvxyz;
            app.TVtimeEditField.Value = cs.tvtime;

            if app.GPUpresentFlag
                app.LLRxyzEditField.Value = cs.llrxyzGpu;
            else
                app.LLRxyzEditField.Value = cs.llrxyz;
            end

            if isfield(cs,'zteIterations')
                app.ZTEIterationsEditField.Value = cs.zteIterations;
            end

        end % ApplyCsDefaultsFcn


        % Handles of the image tab controls
        function h = imageButtonHandles(app)

            % Purpose:
            %   List the image viewer controls whose state is saved and restored.
            %
            % How it works:
            %   One explicit array of handles, used by DisableImageButtonsFcn and
            %   EnableImageButtonsFcn so that both work on exactly the same set.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   h - array of component handles.

            h = [ ...
                app.MaskButton, app.ThresholdSlider, app.ThresholdEditField, ...
                app.ErodeButton, app.DilateButton, app.CloseButton, ...
                app.OpenButton, app.AutoIntensityCheckBox, app.SetButton, ...
                app.WindowSlider, app.LevelSlider, app.OrientationSpinner, ...
                app.Dim6Slider, app.Dim5Slider, app.Dim4Slider, ...
                app.Dim3Slider, app.Dim6EditField, app.Dim5EditField, ...
                app.Dim4EditField, app.Dim3EditField, app.XLabel, ...
                app.YLabel, app.MoviePlayButton, app.MovieStopButton, ...
                app.ThresholdEditFieldLabel, app.WindowEditField, app.LevelEditField, ...
                app.OrientationSpinnerLabel, app.Dim6Label, app.Dim5Label, ...
                app.Dim4Label, app.Dim3Label, app.AutoIntensityCheckBoxLabel, ...
                app.LLabel, app.WLabel, app.MIPXLabel, ...
                app.MIPYLabel, app.MIPWindowEditField, app.MIPLevelEditField, ...
                app.MIPSetButton, app.MIPWindowSlider, app.MIPLevelSlider, ...
                app.MIPWLabel, app.MIPLLabel, app.MIPOrientationSpinner, ...
                app.MIPOrientationSpinnerLabel, app.MIPResizeButton, app.MIPAngleResetButton, ...
                app.MIPxAngleSlider, app.MIPyAngleSlider, app.MIPxAngleLabel, ...
                app.MIPyAngleLabel, app.MIPxPlayButton, app.MIPyPlayButton, ...
                app.MIPRotateLabel, app.PhaseYLabel, app.PhaseXLabel, ...
                app.PhaseUnwrapButton, app.PhaseOrientationSpinner, app.PhaseCorrectCheckBoxLabel, ...
                app.PhaseMinLabel, app.PhaseMaxLabel, app.PhaseMovieStartButton, ...
                app.PhaseMovieStopButton, app.PhaseDim3Label, app.PhaseDim3EditField, ...
                app.PhaseDim3Slider, app.PhaseDim4Label, app.PhaseDim4EditField, ...
                app.PhaseDim4Slider, app.PhaseDim5Label, app.PhaseDim5EditField, ...
                app.PhaseDim5Slider, app.PhaseDim6Label, app.PhaseDim6EditField, ...
                app.PhaseDim6Slider, app.PhaseOrientationSpinnerLabel];

        end % imageButtonHandles


        % Handles of the k-space tab controls
        function h = kSpaceButtonHandles(app)

            % Purpose:
            %   List the k-space viewer controls whose state is saved and restored.
            %
            % How it works:
            %   One explicit array of handles, used by DisableKbuttonsFcn and
            %   EnableKbuttonsFcn so that both work on exactly the same set.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   h - array of component handles.

            h = [ ...
                app.KDim3EditField, app.KDim3Slider, app.KDim4EditField, ...
                app.KDim4Slider, app.KFlipAngleEditField, app.KFlipAngleSlider, ...
                app.KEchoEditField, app.KEchoSlider, app.KOrientationSpinner, ...
                app.fillingButton, app.averagesButton, app.kValuesButton, ...
                app.senseMapButton, app.CoilSpinner, app.KspaceMovieLabel, ...
                app.KStartButton, app.KStopButton, app.FastSlowSwitch, ...
                app.KLevelEditField, app.KWindowEditField, app.KLevelSlider, ...
                app.KWindowSlider, app.CoilSpinnerLabel, app.FillingViewField, ...
                app.FillingPercLabel, app.KOrientationSpinnerLabel, app.KEchoEditFieldLabel, ...
                app.KFlipAngleEditFieldLabel, app.KDim4Label, app.KDim3Label, ...
                app.KLLabel, app.KWLabel, app.KxLabel, ...
                app.KyLabel, app.KDim3EditFieldS, app.KDim3SliderS, ...
                app.KDim3LabelS];

        end % kSpaceButtonHandles


        % Copy the results of a reconstruction into the widgets
        function MirrorRecoOutputsFcn(app)

            % Purpose:
            %   Copy the reconstruction results from the data object back onto the app.
            %
            % How it works:
            %   The same idea as MirrorDataOutputsFcn, for the values a reconstruction
            %   settles rather than a sort: matrix sizes, data offset, gradient delays, slab
            %   overlap, ZTE dead time and the eight coil sensitivities. A calibrated value
            %   therefore appears in the field the user can edit.
            %
            %   The calibration checkbox is cleared afterwards: a calibration runs once, and
            %   what it found is then in the delay fields for the user to keep or change.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the app controls are updated.

            app.XEditField.Value = app.pd.dimXOut;
            app.YEditField.Value = app.pd.dimYOut;
            app.ZEditField.Value = app.pd.dimZOut;
            app.NREditField.Value = app.pd.nrDynamicsOut;
            app.DataOffsetRadialEditField.Value = app.pd.dataOffsetOut;
            app.SlabOverlapEditField.Value = app.pd.slabOverlapOut;
            app.ZTEDeadTimeEditField.Value = app.pd.deadTimeZteOut;

            % Automatic coil sensitivity estimation writes into the object, show it
            app.S1EditField.Value = app.pd.coilSensitivities(1);
            app.S2EditField.Value = app.pd.coilSensitivities(2);
            app.S3EditField.Value = app.pd.coilSensitivities(3);
            app.S4EditField.Value = app.pd.coilSensitivities(4);
            app.S5EditField.Value = app.pd.coilSensitivities(5);
            app.S6EditField.Value = app.pd.coilSensitivities(6);
            app.S7EditField.Value = app.pd.coilSensitivities(7);
            app.S8EditField.Value = app.pd.coilSensitivities(8);

            app.GxDelayEditField.Value  = app.pd.delaysOut(1);
            app.GyDelayEditField.Value  = app.pd.delaysOut(2);
            app.GzDelayEditField.Value  = app.pd.delaysOut(3);
            app.SxyDelayEditField.Value = app.pd.delaysOut(4);
            app.SxzDelayEditField.Value = app.pd.delaysOut(5);
            app.SyzDelayEditField.Value = app.pd.delaysOut(6);

            % A calibration runs once, after which the user works with what it found
            app.GradDelayCalibrationCheckBox.Value = 0;

        end % MirrorRecoOutputsFcn


        % Enable or disable the radial, UTE and ZTE controls for the loaded data type
        function EnableRadialButtonsFcn(app)

            % Purpose:
            %   Enable the trajectory controls that apply to the loaded data type.
            %
            % How it works:
            %   Dispatches on pd.dataType. Gradient delays exist per axis, but which of them
            %   mean anything differs: 2D radial has no z delay, 3D UTE and ZTE have all
            %   three, and Cartesian data has none. ZTE additionally has its dead time,
            %   shift and full-echo controls. Each branch enables what applies and disables
            %   the rest, so a control that is on is one that will be used. Options that
            %   need Bart stay disabled when Bart is not installed.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the app controls are updated.

            switch app.pd.dataType

                case "2Dradial"

                    app.GxDelayEditField.Enable = 'on';
                    app.GxdelayEditFieldLabel.Enable = 'on';
                    app.GyDelayEditField.Enable = 'on';
                    app.GydelayEditFieldLabel.Enable = 'on';
                    app.GzDelayEditField.Enable = 'off';
                    app.GzdelayEditFieldLabel.Enable = 'off';
                    app.SxyDelayEditField.Enable = 'on';
                    app.SxydelayEditFieldLabel.Enable = 'on';
                    app.SxzDelayEditField.Enable = 'off';
                    app.SxzdelayEditFieldLabel.Enable = 'off';
                    app.SyzDelayEditField.Enable = 'off';
                    app.SyzdelayEditFieldLabel.Enable = 'off';

                    if app.bartDetectedFlag

                        if app.CSRecoCheckBox.Value
                            app.GradDelayCalibrationCheckBox.Enable = 'on';
                            app.GradDelayCalibrationCheckBoxLabel.Enable = 'on';
                            app.RingMethodCheckBox.Enable = 'on';
                            app.RingMethodCheckBoxLabel.Enable = 'on';
                            app.AbortGradCalButton.Enable = 'on';
                        else
                            app.GradDelayCalibrationCheckBox.Enable = 'on';
                            app.GradDelayCalibrationCheckBoxLabel.Enable = 'on';
                            app.RingMethodCheckBox.Enable = 'on';
                            app.RingMethodCheckBoxLabel.Enable = 'on';
                            app.AbortGradCalButton.Enable = 'on';
                        end

                        app.CenterEchoCheckBox.Enable = 'on';
                        app.CenterEchoCheckBoxLabel.Enable = 'on';
                        app.PhaseCorrectCheckBox.Enable = 'on';
                        app.PhaseCorrectCheckBoxLabel.Enable = 'on';
                        app.AmplitudeCorrectionCheckBox.Enable = 'on';
                        app.AmplitudeCorrectionCheckBoxLabel.Enable = 'on';
                        app.DensityCorrectCheckBox.Enable = 'on';
                        app.DensityCorrectCheckBoxLabel.Enable = 'on';
                        app.TukeyFilterCheckBox.Enable = 'on';
                        app.TukeyFilterCheckBoxLabel.Enable = 'on';
                        app.DataOffsetRadialEditField.Enable = 'off';
                        app.DataOffsetRadialEditFieldLabel.Enable = 'on';

                    else

                        app.GradDelayCalibrationCheckBox.Enable = 'on';
                        app.GradDelayCalibrationCheckBoxLabel.Enable = 'on';
                        app.GradDelayCalibrationCheckBox.Value = 0;
                        app.RingMethodCheckBox.Enable = 'off';
                        app.RingMethodCheckBoxLabel.Enable = 'off';
                        app.RingMethodCheckBox.Value = 0;
                        app.AbortGradCalButton.Enable = 'on';
                        app.CenterEchoCheckBox.Enable = 'on';
                        app.CenterEchoCheckBoxLabel.Enable = 'on';
                        app.PhaseCorrectCheckBox.Enable = 'on';
                        app.PhaseCorrectCheckBoxLabel.Enable = 'on';
                        app.AmplitudeCorrectionCheckBox.Enable = 'on';
                        app.AmplitudeCorrectionCheckBoxLabel.Enable = 'on';
                        app.DensityCorrectCheckBox.Enable = 'off';
                        app.DensityCorrectCheckBoxLabel.Enable = 'off';
                        app.DensityCorrectCheckBox.Value = 0;
                        app.TukeyFilterCheckBox.Enable = 'on';
                        app.TukeyFilterCheckBoxLabel.Enable = 'on';
                        app.DataOffsetRadialEditField.Enable = 'off';
                        app.DataOffsetRadialEditFieldLabel.Enable = 'off';

                    end

                case "3Dute"

                    app.GxDelayEditField.Enable = 'on';
                    app.GxdelayEditFieldLabel.Enable = 'on';
                    app.GyDelayEditField.Enable = 'on';
                    app.GydelayEditFieldLabel.Enable = 'on';
                    app.GzDelayEditField.Enable = 'on';
                    app.GzdelayEditFieldLabel.Enable = 'on';
                    app.SxyDelayEditField.Enable = 'on';
                    app.SxydelayEditFieldLabel.Enable = 'on';
                    app.SxzDelayEditField.Enable = 'on';
                    app.SxzdelayEditFieldLabel.Enable = 'on';
                    app.SyzDelayEditField.Enable = 'on';
                    app.SyzdelayEditFieldLabel.Enable = 'on';

                    if app.bartDetectedFlag

                        if app.CSRecoCheckBox.Value
                            app.GradDelayCalibrationCheckBox.Enable = 'on';
                            app.GradDelayCalibrationCheckBoxLabel.Enable = 'on';
                            app.RingMethodCheckBox.Enable = 'off';
                            app.RingMethodCheckBoxLabel.Enable = 'off';
                            app.AbortGradCalButton.Enable = 'on';
                        else
                            app.GradDelayCalibrationCheckBox.Enable = 'on';
                            app.GradDelayCalibrationCheckBoxLabel.Enable = 'on';
                            app.RingMethodCheckBox.Enable = 'off';
                            app.RingMethodCheckBoxLabel.Enable = 'off';
                            app.AbortGradCalButton.Enable = 'on';
                        end

                        app.CenterEchoCheckBox.Enable = 'on';
                        app.CenterEchoCheckBoxLabel.Enable = 'on';
                        app.PhaseCorrectCheckBox.Enable = 'on';
                        app.PhaseCorrectCheckBoxLabel.Enable = 'on';
                        app.AmplitudeCorrectionCheckBox.Enable = 'on';
                        app.AmplitudeCorrectionCheckBoxLabel.Enable = 'on';
                        app.DensityCorrectCheckBox.Enable = 'on';
                        app.DensityCorrectCheckBoxLabel.Enable = 'on';
                        app.TukeyFilterCheckBox.Enable = 'on';
                        app.TukeyFilterCheckBoxLabel.Enable = 'on';
                        app.DataOffsetRadialEditField.Enable = 'on';
                        app.DataOffsetRadialEditFieldLabel.Enable = 'on';

                    else

                        app.GradDelayCalibrationCheckBox.Enable = 'on';
                        app.GradDelayCalibrationCheckBoxLabel.Enable = 'on';
                        app.GradDelayCalibrationCheckBox.Value = 0;
                        app.RingMethodCheckBox.Enable = 'off';
                        app.RingMethodCheckBoxLabel.Enable = 'off';
                        app.RingMethodCheckBox.Value = 0;
                        app.AbortGradCalButton.Enable = 'on';
                        app.CenterEchoCheckBox.Enable = 'on';
                        app.CenterEchoCheckBoxLabel.Enable = 'on';
                        app.PhaseCorrectCheckBox.Enable = 'on';
                        app.PhaseCorrectCheckBoxLabel.Enable = 'on';
                        app.AmplitudeCorrectionCheckBox.Enable = 'on';
                        app.AmplitudeCorrectionCheckBoxLabel.Enable = 'on';
                        app.DensityCorrectCheckBox.Enable = 'off';
                        app.DensityCorrectCheckBoxLabel.Enable = 'off';
                        app.DensityCorrectCheckBox.Value = 0;
                        app.TukeyFilterCheckBox.Enable = 'on';
                        app.TukeyFilterCheckBoxLabel.Enable = 'on';
                        app.DataOffsetRadialEditField.Enable = 'on';
                        app.DataOffsetRadialEditFieldLabel.Enable = 'on';

                    end

                case "ZTE"

                    app.GxDelayEditField.Enable = 'on';
                    app.GxdelayEditFieldLabel.Enable = 'on';
                    app.GyDelayEditField.Enable = 'on';
                    app.GydelayEditFieldLabel.Enable = 'on';
                    app.GzDelayEditField.Enable = 'on';
                    app.GzdelayEditFieldLabel.Enable = 'on';
                    app.SxyDelayEditField.Enable = 'on';
                    app.SxydelayEditFieldLabel.Enable = 'on';
                    app.SxzDelayEditField.Enable = 'on';
                    app.SxzdelayEditFieldLabel.Enable = 'on';
                    app.SyzDelayEditField.Enable = 'on';
                    app.SyzdelayEditFieldLabel.Enable = 'on';
                    app.GradDelayCalibrationCheckBox.Enable = 'off';
                    app.GradDelayCalibrationCheckBoxLabel.Enable = 'off';
                    app.GradDelayCalibrationCheckBox.Value = 0;
                    app.RingMethodCheckBox.Enable = 'off';
                    app.RingMethodCheckBoxLabel.Enable = 'off';
                    app.RingMethodCheckBox.Value = 0;
                    app.AbortGradCalButton.Enable = 'off';
                    app.CenterEchoCheckBox.Enable = 'off';
                    app.CenterEchoCheckBoxLabel.Enable = 'off';
                    app.PhaseCorrectCheckBox.Enable = 'off';
                    app.PhaseCorrectCheckBoxLabel.Enable = 'off';
                    app.AmplitudeCorrectionCheckBox.Enable = 'off';
                    app.AmplitudeCorrectionCheckBoxLabel.Enable = 'off';
                    app.DensityCorrectCheckBox.Enable = 'off';
                    app.DensityCorrectCheckBoxLabel.Enable = 'off';
                    app.TukeyFilterCheckBox.Enable = 'off';
                    app.TukeyFilterCheckBoxLabel.Enable = 'off';
                    app.DataOffsetRadialEditField.Enable = 'off';
                    app.DataOffsetRadialEditFieldLabel.Enable = 'off';

                otherwise

                    app.GxDelayEditField.Enable = 'off';
                    app.GxdelayEditFieldLabel.Enable = 'off';
                    app.GyDelayEditField.Enable = 'off';
                    app.GydelayEditFieldLabel.Enable = 'off';
                    app.GzDelayEditField.Enable = 'off';
                    app.GzdelayEditFieldLabel.Enable = 'off';
                    app.SxyDelayEditField.Enable = 'off';
                    app.SxydelayEditFieldLabel.Enable = 'off';
                    app.SxzDelayEditField.Enable = 'off';
                    app.SxzdelayEditFieldLabel.Enable = 'off';
                    app.SyzDelayEditField.Enable = 'off';
                    app.SyzdelayEditFieldLabel.Enable = 'off';
                    app.GradDelayCalibrationCheckBox.Enable = 'off';
                    app.GradDelayCalibrationCheckBoxLabel.Enable = 'off';
                    app.RingMethodCheckBox.Enable = 'off';
                    app.RingMethodCheckBoxLabel.Enable = 'off';
                    app.AbortGradCalButton.Enable = 'off';
                    app.CenterEchoCheckBox.Enable = 'off';
                    app.CenterEchoCheckBoxLabel.Enable = 'off';
                    app.PhaseCorrectCheckBox.Enable = 'off';
                    app.PhaseCorrectCheckBoxLabel.Enable = 'off';
                    app.AmplitudeCorrectionCheckBox.Enable = 'off';
                    app.AmplitudeCorrectionCheckBoxLabel.Enable = 'off';
                    app.DensityCorrectCheckBox.Enable = 'off';
                    app.DensityCorrectCheckBoxLabel.Enable = 'off';
                    app.TukeyFilterCheckBox.Enable = 'off';
                    app.TukeyFilterCheckBoxLabel.Enable = 'off';
                    app.DataOffsetRadialEditField.Enable = 'off';
                    app.DataOffsetRadialEditFieldLabel.Enable = 'off';

            end

            drawnow;

        end

        % Disable radial buttons
        function DisableRadialButtonsFcn(app)

            % Purpose:
            %   Disable all the trajectory and gradient delay controls.
            %
            % How it works:
            %   Switches off the six delay fields, their labels and the ZTE controls, for
            %   data types that have no trajectory to correct.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the app controls are updated.

            app.GxDelayEditField.Enable = 'off';
            app.GxdelayEditFieldLabel.Enable = 'off';
            app.GyDelayEditField.Enable = 'off';
            app.GydelayEditFieldLabel.Enable = 'off';
            app.GzDelayEditField.Enable = 'off';
            app.GzdelayEditFieldLabel.Enable = 'off';
                    app.SxyDelayEditField.Enable = 'off';
                    app.SxydelayEditFieldLabel.Enable = 'off';
                    app.SxzDelayEditField.Enable = 'off';
                    app.SxzdelayEditFieldLabel.Enable = 'off';
                    app.SyzDelayEditField.Enable = 'off';
                    app.SyzdelayEditFieldLabel.Enable = 'off';
            app.GradDelayCalibrationCheckBox.Enable = 'off';
            app.GradDelayCalibrationCheckBoxLabel.Enable = 'off';
            app.RingMethodCheckBox.Enable = 'off';
            app.RingMethodCheckBoxLabel.Enable = 'off';
            app.AbortGradCalButton.Enable = 'off';
            if contains(app.pd.dataType,["2Dradial","3Dute"])
                app.AbortGradCalButton.Enable = 'on';
            end
            app.CenterEchoCheckBox.Enable = 'off';
            app.CenterEchoCheckBoxLabel.Enable = 'off';
            app.PhaseCorrectCheckBox.Enable = 'off';
            app.PhaseCorrectCheckBoxLabel.Enable = 'off';
            app.AmplitudeCorrectionCheckBox.Enable = 'off';
            app.AmplitudeCorrectionCheckBoxLabel.Enable = 'off';
            app.DensityCorrectCheckBox.Enable = 'off';
            app.DensityCorrectCheckBoxLabel.Enable = 'off';
            app.TukeyFilterCheckBox.Enable = 'off';
            app.TukeyFilterCheckBoxLabel.Enable = 'off';
            app.DataOffsetRadialEditField.Enable = 'off';
            app.DataOffsetRadialEditFieldLabel.Enable = 'off';

            drawnow;

        end


        % Set images, figure properties, etc.
        function SetAppLayoutFcn(app)

            % Purpose:
            %   Load the images, icons and fixed layout the app starts with.
            %
            % How it works:
            %   Assigns the logo images, the button icons and the movie icons, reads the
            %   black placeholder used by the empty figures, and puts the tabs and panels
            %   into their starting positions. Called once at startup.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the app appearance is set.

            % Images
            app.TulpImage.ImageSource = 'AmsterdamUMC.png';
            app.MRSImage.ImageSource = 'MRSolutionsLogo.gif';
            app.MovieImage.ImageSource = 'Movie.gif';
            app.PhaseMovieImage.ImageSource = 'Movie.gif';
            app.blackImage = imread('black.png');

            % Buttons
            app.LoadMRDButton.Icon = 'disk.png';
            app.RecoButton.Icon = 'reconstruct.gif';
            app.DCMNIFTIButton.Icon = 'disk.png';
            app.MRDButton.Icon = 'disk.png';
            app.GIFButton.Icon = 'disk.png';
            app.ExitButton.Icon = 'exit.png';
            app.MoviePlayButton.Icon = 'play.gif';
            app.MovieStopButton.Icon = 'stop.gif';
            app.PhaseMovieStartButton.Icon = 'play.gif';
            app.PhaseMovieStopButton.Icon = 'stop.gif';
            app.KStartButton.Icon = 'play.gif';
            app.KStopButton.Icon = 'stop.gif';
            app.MIPxPlayButton.Icon = 'play.gif';
            app.MIPyPlayButton.Icon = 'play.gif';
            app.MaskButton.Icon = 'segmentation.png';
            app.PhaseUnwrapButton.Icon = 'unwrap.png';

            % Title
            app.TitleLabel.Text = ['P' char(178) 'ROUD'];

            % Define app colors
            app.brightred = 'Red';
            app.red = '#D95319';
            app.brightgreen = 'Green';
            app.green = '#6CB557';
            app.yellow = 'Yellow';
            app.orange = '#FDA400';
            app.blue = '#0073BE';

            app.guiBlack = [0.15,0.15,0.15];
            app.guiGrey = [0.94,0.94,0.94];
            app.guiMiddleGrey = [0.9,0.9,0.9];
            app.guiDarkGrey = [0.5,0.5,0.5];
            app.guiWhite = [1.0,1.0,1.0];
            app.guiBlackBlue = [0.02,0.13,0.3];

            app.MovieImage.BackgroundColor = app.guiGrey;
            app.PhaseMovieImage.BackgroundColor = app.guiGrey;

            % Load sounds
            [app.warningSound,~] = audioread('Glass.aiff');
            [app.errorSound,~] = audioread('Ping.aiff');

            % Block figure interactivity
            app.KspaceFig.Toolbar.Visible = 'off';
            app.KspaceFig.Interactions = [];
            app.MIPFig.Toolbar.Visible = 'off';
            app.MIPFig.Interactions = [];
            app.PhaseRecoFig.Toolbar.Visible = 'off';
            app.PhaseRecoFig.Interactions = [];
            app.PhaseRecoFig.Interactions = [zoomInteraction regionZoomInteraction];
            app.SenseFig1.Toolbar.Visible = 'off';
            app.SenseFig1.Interactions = [];
            app.SenseFig2.Toolbar.Visible = 'off';
            app.SenseFig2.Interactions = [];
            app.SenseFig3.Toolbar.Visible = 'off';
            app.SenseFig3.Interactions = [];
            app.SenseFig4.Toolbar.Visible = 'off';
            app.SenseFig4.Interactions = [];
            app.SenseFig5.Toolbar.Visible = 'off';
            app.SenseFig5.Interactions = [];
            app.SenseFig6.Toolbar.Visible = 'off';
            app.SenseFig6.Interactions = [];
            app.SenseFig7.Toolbar.Visible = 'off';
            app.SenseFig7.Interactions = [];
            app.SenseFig8.Toolbar.Visible = 'off';
            app.SenseFig8.Interactions = [];

            % Set k-space level and window limits
            app.KLevelEditField.Limits = [0 app.pd.MAX_KSPACE+1];
            app.KLevelSlider.Limits = [0 app.pd.MAX_KSPACE+1];
            app.KWindowEditField.Limits = [0 app.pd.MAX_KSPACE+1];
            app.KWindowSlider.Limits = [0 app.pd.MAX_KSPACE+1];

            % Set image level and window limits
            app.LevelEditField.Limits = [0 app.pd.MAX_IMAGE+1];
            app.LevelSlider.Limits = [0 app.pd.MAX_IMAGE+1];
            app.WindowEditField.Limits = [0 app.pd.MAX_IMAGE+1];
            app.WindowSlider.Limits = [0 app.pd.MAX_IMAGE+1];
            app.MIPLevelEditField.Limits = [0 app.pd.MAX_IMAGE+1];
            app.MIPLevelSlider.Limits = [0 app.pd.MAX_IMAGE+1];
            app.MIPWindowEditField.Limits = [0 app.pd.MAX_IMAGE+1];
            app.MIPWindowSlider.Limits = [0 app.pd.MAX_IMAGE+1];
            app.ThresholdEditField.Limits = [0 app.pd.MAX_IMAGE+1];
            app.ThresholdSlider.Limits = [0 app.pd.MAX_IMAGE+1];

            drawnow;

        end


        % App version check
        function CheckVersionFnc(app)

            % Purpose:
            %   Tell the user when a newer release of the app is available.
            %
            % How it works:
            %   Reads the version list from the address in the settings file with a short
            %   timeout, compares it against app.appVersion through isVersionNewer, and
            %   reports only when the published version is genuinely newer. The whole check
            %   is guarded: no network, a slow server or a malformed reply leaves the app
            %   running normally, because this is a convenience and not a requirement.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, a message is reported when an update exists.

            try
                currentVersion = string(app.appVersion);
                newestVersions = app.jsonPar.newestVersions;

                webTxt = "";
                ver = 0;

                opts = weboptions( ...
                    'Timeout', 5, ...
                    'ContentType', 'text', ...
                    'CharacterEncoding', 'UTF-8');

                while webTxt == "" && ver < numel(newestVersions)
                    ver = ver + 1;

                    newVersionFile = string(app.jsonPar.web1) + ...
                        string(newestVersions{ver}) + ...
                        string(app.jsonPar.web2);

                    app.TextMessage("Checking: " + newVersionFile, 2);
                    drawnow;

                    try
                        tStart = tic;
                        webTxt = strtrim(string(webread(char(newVersionFile), opts)));
                        app.TextMessage("Response received in " + string(round(toc(tStart),2)) + " s", 2);
                    catch ME1
                        app.TextMessage("Failed: " + ME1.message, 2);
                        webTxt = "";
                    end
                end

                if webTxt == ""
                    ME = MException('P2ROUD:error','Could not retrieve version file ...');
                    throw(ME);
                end

                tok = regexp(webTxt, 'p2roud_version\s*=\s*([0-9]+(?:\.[0-9]+){1,3})', 'tokens', 'once');
                if isempty(tok)
                    ME = MException('P2ROUD:error','Could not parse version string from file ...');
                    throw(ME);
                end

                newVersion = string(tok{1});

                app.TextMessage("Current version = " + currentVersion, 1);
                app.TextMessage("Newest version = " + newVersion, 1);

                if isVersionNewer(newVersion, currentVersion)
                    app.TextMessage("A new version of P2ROUD is available ...", 1);
                    app.TextMessage("Visit https://github.com/Moby1971/ to update ...", 1);
                else
                    app.TextMessage("P2ROUD is up to date ...", 1);
                end

            catch ME
                app.TextMessage(ME.message, 2);
                app.TextMessage("Could not check P2ROUD version ...", 1);
            end

            function tf = isVersionNewer(newVer, curVer)

            % Purpose:
            %   Compare two version strings of the form major.minor.patch.
            %
            % How it works:
            %   Reads up to three numbers from each string and pads the missing ones with
            %   zero, so that '6.3' and '6.3.0' compare equal. The parts are compared in
            %   order and the first difference decides, which is what makes 6.10 newer than
            %   6.9 where a plain string comparison would not.
            %
            % Inputs:
            %   newVer - the published version string
            %   curVer - the running version string
            %
            % Output:
            %   tf - true when newVer is strictly newer than curVer.

                a = sscanf(char(newVer), '%d.%d.%d').';
                b = sscanf(char(curVer), '%d.%d.%d').';
                a(end+1:3) = 0;
                b(end+1:3) = 0;
                tf = false;
                for k = 1:3
                    if a(k) > b(k)
                        tf = true;
                        return
                    elseif a(k) < b(k)
                        return
                    end
                end
            end

        end % CheckVersionFnc


        % --------------------------------------------------------------
        %
        %                           PARAMETERS
        %
        % --------------------------------------------------------------

        % Trajectory types:
        %
        %                   app.pd.trajType
        %                   ----------------
        % pe1_order = 1:    standard
        %                   rtable                  (rtable.rtv) fast-spin-echo
        % pe1_order = 2:    zigzag
        % pe1_order = 3:    mul_kspace              (table in MRD file)
        % pe1_order = 4:    P2ROUD                  (txt file), number of entries = ky x kz
        % pe1_order = 5:    NRLUT                   (txt file), Non Regular LUT, k-space undersampling
        %
        %
        % Data types:
        %
        %                   app.pd.dataType
        %                   ---------------
        %                   2D
        %                   2Dradial
        %                   2Depi
        %                   3D
        %                   3Dute
        %                   ZTE
        %
        %
        %

        % Set data parameters
        function SetParametersFcn(app)

            % Purpose:
            %   Read the scan parameters and set every dependent control in the app.
            %
            % How it works:
            %   Hands the parameters to the class first, then mirrors what came back. The
            %   trajectory type is worked out from the sequence and the look-up table, and
            %   that decides the rest: which matrix size fields apply, which delay controls
            %   are meaningful, which reconstruction methods are offered, and what the
            %   k-space axes are labelled. The compressed sensing defaults for the data type
            %   are applied here as well. This is the single place where a newly loaded
            %   scan turns into a configured app.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, pd and the app controls are updated.

            app.TextMessage('Setting k-space parameters ...',1);
            app.TrajectoryViewField.Value = '-';

            % Determine the type of multi-dimensional dataset
            app.pd = app.pd.setDataParameters(gatherRecoParams(app));
            MirrorDataOutputsFcn(app);

            % Read the SQL properties
            if app.pd.sqlFlag
                app.pd = app.pd.sqlParameters(gatherRecoParams(app));
            end

            % Standard k-space filling
            app.pd.trajType = "standard";

            % Scanner k-space table (RARE sequence)
            app.rtableFile = '';
            if (app.pd.proudRecoScanFlag == false) && (app.pd.dataType ~= "2Dradial")
                tableFilename{1} = strcat(app.mrdImportPath,filesep,'rtable.rtv');
                tableFilename{2} = strcat(app.mrdImportPath,filesep,'origrtable.rtv.orig');
                tableFilename{3} = strcat(app.mrdImportPath,filesep,'Recon_001',filesep,'rtable.rtv');
                tableFilename{4} = strcat(app.mrdImportPath,filesep,'Recon_001',filesep,'origrtable.rtv.orig');
                for cnt = 1:length(tableFilename)
                    if isfile(tableFilename{cnt})
                        app.TextMessage('Scanner k-space table detected ...',1);
                        app.pd.trajType = "rtable";
                        app.TrajectoryViewField.Value = 'rtable.rtv';
                        app.rtableFile = tableFilename{cnt};
                        break;
                    end
                end
            end

            % User k-space trajectory & P2ROUD implementation
            if app.pd.proudRecoScanFlag == false

                % pe1_order == 2 : k-space zig-zag
                if (app.pd.pe1Order == 2) && (app.pd.dataType ~= "2Dradial")
                    app.pd.trajType = "zigzag";
                    app.TrajectoryViewField.Value = 'zigzag';
                end

                % pe1_order == 3 : k-space trajectory list in preclinical
                if (app.pd.pe1Order == 3) && (app.pd.dataType ~= "2Dradial")
                    app.pd.trajType = "mul_kspace";
                    app.TrajectoryViewField.Value = 'mul_kspace_traj';
                end

                % pe1_order == 4 : 3D k-space trajectory from txt file
                if ((app.pd.pe1Order == 4) && (app.pd.dataType ~= "2Dradial")) || app.forceProudFlag

                    app.TextMessage('3D LUT ...',1);

                    % Read k-space trajectory file
                    flist = dir(fullfile(app.mrdImportPath,'*.txt'));
                    if ~isempty(flist)
                        for cnt = length(flist):-1:1
                            if contains(flist(cnt).name,app.pd.IGNORE_FILES)
                                flist(cnt) = [];
                            end
                        end
                    end

                    if ~isempty(flist)
                        app.pd.gpVarProud = load([flist(1).folder,filesep,flist(1).name]);
                        app.TrajectoryViewField.Value = flist(1).name;
                        app.pd.trajType = "P2ROUD";
                        app.TextMessage(strcat("3D LUT trajectory file = ",flist(1).name," ..."),1);
                    else
                        app.pd.trajType = "";
                        app.TextMessage('ERROR: Trajectory file not found ...',1);
                        app.SetStatus(2);
                    end

                end

                % pe1_order == 5 : Unrestricted 3D LUT mode
                if ((app.pd.pe1Order == 5) && (app.pd.dataType ~= "2Dradial"))

                    app.TextMessage("3D Non Regular LUT ...",1);

                    % Read k-space trajectory file
                    flist = dir(fullfile(app.mrdImportPath,'*.txt'));
                    if ~isempty(flist)
                        for cnt = length(flist):-1:1
                            if contains(flist(cnt).name,app.pd.IGNORE_FILES)
                                flist(cnt) = [];
                            end
                        end
                    end

                    if ~isempty(flist)
                        app.pd.gpVarLut = load([flist(1).folder,filesep,flist(1).name]);
                        app.TrajectoryViewField.Value = flist(1).name;
                        app.pd.trajType = "NRLUT";
                        app.TextMessage(strcat("Non Regular LUT trajectory file = ",flist(1).name," ..."),1);
                    else
                        app.pd.trajType = "";
                        app.TextMessage('ERROR: Trajectory file not found ...',1);
                        app.SetStatus(2);
                    end

                end

                % ZTE trajectory
                if app.pd.dataType == "ZTE"
                    app.TextMessage('ZTE k-space trajectory ...',1);

                    % Read k-space trajectory file
                    flist = dir(fullfile(app.mrdImportPath,'*.txt'));
                    if ~isempty(flist)
                        for cnt = length(flist):-1:1
                            if contains(flist(cnt).name,app.pd.IGNORE_FILES)
                                flist(cnt) = [];
                            end
                        end
                    end

                    if ~isempty(flist)
                        app.pd.gpVarProud = load([flist(1).folder,filesep,flist(1).name]);
                        app.TrajectoryViewField.Value = flist(1).name;
                        app.pd.trajType = "ZTE";
                        app.TextMessage(strcat("ZTE trajectory file = ",flist(1).name," ..."),1);
                    else
                        app.pd.trajType = "";
                        app.TextMessage('ERROR: Trajectory file not found ...',1);
                        app.SetStatus(2);
                    end

                end

            end

            % Show some parameters in app
            app.SequenceViewField.Value = app.pd.PPL;
            app.TRViewField.Value = app.pd.TR;
            app.TEViewField.Value = app.pd.TE;
            app.NAViewField.Value = app.pd.noAverages;
            app.NRViewField.Value = app.pd.experimentArray;
            app.NEViewField.Value = app.pd.noEchoes;
            app.FAViewField.Value = num2str(app.pd.flipAngleArray);
            app.NFAViewField.Value = length(app.pd.flipAngleArray);
            app.TextMessage(strcat("Phase orientation = ",num2str(app.pd.phaseOrientation)," ..."),2);

            % Geometry parameters
            app.FieldOfViewViewField2.Value = app.pd.FOV;
            app.AspectRatioViewField.Value = app.pd.aspectratio;
            app.SliceThicknessViewField.Value = app.pd.sliceThickness;
            app.SliceGapViewField.Value = app.pd.SQLsliceGap;
            app.AspectRatioViewField.Value = app.pd.aspectratio;
            app.PhaseOrientationViewField.Value = app.pd.phaseOrientation;
            app.OffsetXViewField.Value = app.pd.SQLoffsetX;
            app.OffsetYViewField.Value = app.pd.SQLoffsetY;
            app.OffsetZViewField.Value = app.pd.SQLoffsetZ;
            app.AngleXViewField.Value = app.pd.SQLangleX;
            app.AngleYViewField.Value = app.pd.SQLangleY;
            app.AngleZViewField.Value = app.pd.SQLangleZ;

            % Matrix sizes and parameters
            switch app.pd.dataType

                case "3Dute"

                    % 3D data
                    app.MZLabel.Text = 'Kz';
                    app.ZEditFieldLabel.Text = 'Z';

                    % Permute k-space to X(readout), Y(spokes)
                    app.pd = app.pd.permute3Dkspace;
                    app.pd.multiRepetitionsFlag = false;

                    % New matrix dimensions
                    app.KMatrixViewField1.Value = app.pd.noSamples;
                    app.KMatrixViewField2.Value = app.pd.noViews;
                    app.KMatrixViewField3.Value = app.pd.noViews2;
                    app.NRViewField.Value = app.pd.nrRepetitions;

                    % Field of view
                    app.FOVViewField1.Value = app.pd.FOV;
                    app.FOVViewField2.Value = app.pd.FOV*app.pd.aspectratio;
                    app.FOVViewField3.Value = app.pd.sliceThickness;

                    % Reco size
                    app.XEditField.Value = app.KMatrixViewField1.Value;
                    app.YEditField.Value = app.KMatrixViewField1.Value;
                    app.ZEditField.Value = app.KMatrixViewField3.Value;
                    app.NREditField.Value = app.NRViewField.Value;

                    % Gradient optimization settings
                    app.GradDelayCalibrationCheckBox.Value = 0;
                    app.RingMethodCheckBox.Value = 0;
                    app.CenterEchoCheckBox.Value = 1;
                    app.PhaseCorrectCheckBox.Value = 1;
                    app.AmplitudeCorrectionCheckBox.Value = 0;
                    app.DensityCorrectCheckBox.Value = 1;
                    app.TukeyFilterCheckBox.Value = 1;
                    app.EnableRadialButtonsFcn;

                    % CS regularisation starting values for this data type
                    app.ApplyCsDefaultsFcn("ute3D");

                    % Morphological operation structuring element
                    [xx,yy,zz] = ndgrid(-1:1);
                    app.structElement = sqrt(xx.^2 + yy.^2 + zz.^2) <= 1.0;

                case "3D"

                    % 3D data
                    app.MZLabel.Text = 'Kz';
                    app.ZEditFieldLabel.Text = 'Z';

                    % Matrix dimensions
                    app.KMatrixViewField1.Value = app.pd.noSamples;
                    app.KMatrixViewField2.Value = app.pd.noViews;
                    app.KMatrixViewField3.Value = app.pd.noViews2;

                    % Permute k-space to (X, Y, Z, NR, NFA, NE)
                    app.pd = app.pd.permute3Dkspace;

                    % Field of view
                    app.FOVViewField1.Value = app.pd.FOV;
                    app.FOVViewField2.Value = app.pd.FOV*app.pd.aspectratio;
                    app.FOVViewField3.Value = app.pd.sliceThickness*app.pd.noSlices + app.pd.SQLsliceGap*(app.pd.noSlices - 1);

                    % Reco size
                    app.XEditField.Value = app.KMatrixViewField1.Value;
                    app.YEditField.Value = app.KMatrixViewField2.Value;
                    app.ZEditField.Value = app.KMatrixViewField3.Value;
                    app.NREditField.Value = app.NRViewField.Value;

                    % Morphological operation structuring element
                    [xx,yy,zz] = ndgrid(-1:1);
                    app.structElement = sqrt(xx.^2 + yy.^2 + zz.^2) <= 1.0;

                    % CS regularisation starting values for this data type
                    app.ApplyCsDefaultsFcn("cartesian3D");

                case {"2D","2Depi"}

                    % 2D data or radial data
                    app.MZLabel.Text = 'Slices';
                    app.ZEditFieldLabel.Text = 'Slices';

                    % Permute k-space to (X, Y, Z, NR, NFA, NE)
                    app.pd = app.pd.permute2Dkspace;

                     % Matrix dimensions
                    app.KMatrixViewField1.Value = app.pd.noSamples;
                    app.KMatrixViewField2.Value = app.pd.noViews;
                    app.KMatrixViewField3.Value = app.pd.noSlices;

                    % Field of view
                    app.FOVViewField1.Value = app.pd.FOV;
                    app.FOVViewField2.Value = app.pd.FOV*app.pd.aspectratio;
                    app.FOVViewField3.Value = app.pd.sliceThickness;

                    % Reco size
                    app.XEditField.Value = app.KMatrixViewField1.Value;
                    app.YEditField.Value = app.KMatrixViewField2.Value;
                    app.ZEditField.Value = app.KMatrixViewField3.Value;
                    app.NREditField.Value = app.NRViewField.Value;

                    % Morphological operation structuring element
                    [xx,yy] = ndgrid(-1:1);
                    app.structElement = sqrt(xx.^2 + yy.^2) <= 1.0;

                    % CS regularisation starting values for this data type
                    app.ApplyCsDefaultsFcn("cartesian2D");

                case "2Dradial"

                    % 2D data or radial data
                    app.MZLabel.Text = 'Slices';
                    app.ZEditFieldLabel.Text = 'Slices';

                    % Matrix dimensions
                    app.KMatrixViewField1.Value = app.pd.noSamples;
                    app.KMatrixViewField2.Value = app.pd.noViews;
                    app.KMatrixViewField3.Value = app.pd.noSlices;

                    % Permute k-space to (X, Y, Z, NR, NFA, NE)
                    app.pd = app.pd.permute2Dkspace;

                    % Field of view
                    app.FOVViewField1.Value = app.pd.FOV;
                    app.FOVViewField2.Value = app.pd.FOV*app.pd.aspectratio;
                    app.FOVViewField3.Value = app.pd.sliceThickness;

                    % Reco size
                    app.XEditField.Value = app.KMatrixViewField1.Value;
                    app.YEditField.Value = app.KMatrixViewField2.Value;
                    app.ZEditField.Value = app.KMatrixViewField3.Value;
                    app.NREditField.Value = app.NRViewField.Value;

                    % Gradient optimization settings
                    app.GradDelayCalibrationCheckBox.Value = 1;
                    app.RingMethodCheckBox.Value = 1;
                    app.CenterEchoCheckBox.Value = 1;
                    app.PhaseCorrectCheckBox.Value = 1;
                    app.AmplitudeCorrectionCheckBox.Value = 0;
                    app.DensityCorrectCheckBox.Value = 1;
                    app.TukeyFilterCheckBox.Value = 1;
                    app.EnableRadialButtonsFcn;

                    % CS regularisation starting values for this data type
                    app.ApplyCsDefaultsFcn("radial2D");

                    % Morphological operation structuring element
                    [xx,yy] = ndgrid(-1:1);
                    app.structElement = sqrt(xx.^2 + yy.^2) <= 1.0;

                case "ZTE"

                    % 3D data
                    app.MZLabel.Text = 'Kz';
                    app.ZEditFieldLabel.Text = 'Z';

                    % Construct the k-space for ZTE
                    app.pd = app.pd.makeZTEkSpace(gatherRecoParams(app));

                    % Matrix dimensions
                    app.KMatrixViewField1.Value = app.pd.noSamples;
                    app.KMatrixViewField2.Value = app.pd.noViews;
                    app.KMatrixViewField3.Value = app.pd.noViews2;

                    % Permute k-space to (X, Y, Z, NR)
                    app.pd = app.pd.permute3Dkspace;

                    % Field of view
                    app.FOVViewField1.Value = app.pd.FOV;
                    app.FOVViewField2.Value = app.pd.FOV*app.pd.aspectratio;
                    app.FOVViewField3.Value = app.pd.sliceThickness*app.pd.noSlices;

                    % Reco size
                    app.XEditField.Value = app.KMatrixViewField1.Value;
                    app.YEditField.Value = app.KMatrixViewField1.Value;
                    app.ZEditField.Value = app.KMatrixViewField1.Value;
                    app.NREditField.Value = app.NRViewField.Value;

                    % CS regularisation starting values for this data type
                    app.ApplyCsDefaultsFcn("zte");

                    % Morphological operation structuring element
                    [xx,yy,zz] = ndgrid(-1:1);
                    app.structElement = sqrt(xx.^2 + yy.^2 + zz.^2) <= 1.0;

            end

            % Image orientations
            switch app.pd.dataType

                case {"3D","3Dute","ZTE"}

                    app.imageOrient = ...
                        [1 2 3 4 5 6 7; 2 3 1 4 5 6 7; 3 1 2 4 5 6 7; ...
                        4 1 2 3 5 6 7; 4 2 3 1 5 6 7; 4 3 1 2 5 6 7; ...
                        1 2 4 3 5 6 7; 2 3 4 1 5 6 7; 3 1 4 2 5 6 7; ...
                        1 2 3 6 4 5 7; 1 3 2 6 4 5 7; 2 3 1 6 4 5 7];

                    app.kSpaceOrientLabel = ["Kx","Ky","Kz","NR","FA","echo" ; "Ky","Kz","Kx","NR","FA","echo" ; "Kz","Kx","Ky","NR","FA","echo" ; ...
                        "NR","Kx","Ky","Kz","FA","echo" ; "NR","Ky","Kz","Kx","FA","echo" ; "NR","Kz","Kx","Ky","FA","echo" ; ...
                        "Kx","Ky","NR","Kz","FA","echo" ; "Ky","Kz","NR","Kx","FA","echo" ; "Kz","Kx","NR","Ky","FA","echo" ; ...
                        "Kx","Ky","Kz","echo","NR","FA" ; "Kx","Kz","Ky","echo","NR","FA" ; "Ky","Kz","Kx","echo","NR","FA" ];

                    app.imageOrientLabel = ["X","Y","Z","NR","FA","echo" ; "Y","Z","X","NR","FA","echo" ; "Z","X","Y","NR","FA","echo" ; ...
                        "NR","X","Y","Z","FA","echo" ; "NR","Y","Z","X","FA","echo" ; "NR","Z","X","Y","FA","echo" ; ...
                        "X","Y","NR","Z","FA","echo" ; "Y","Z","NR","X","FA","echo" ; "Z","X","NR","Y","FA","echo" ; ...
                        "X","Y","Z","echo","NR","FA" ; "X","Z","Y","echo","NR","FA" ; "Y","Z","X","echo","NR","FA" ];

                case {"2D","2Dradial","2Depi"}

                    app.imageOrient = ...
                        [1 2 3 4 5 6 ; 2 3 1 4 5 6 ; 3 1 2 4 5 6 ; ...
                        4 1 2 3 5 6 ; 4 2 3 1 5 6 ; 4 3 1 2 5 6 ; ...
                        1 2 4 3 5 6 ; 2 3 4 1 5 6 ; 3 1 4 2 5 6 ; ...
                        1 2 3 6 4 5 ; 1 3 2 6 4 5 ; 2 3 1 6 4 5 ];

                    app.kSpaceOrientLabel = ["Kx","Ky","slice","NR","FA","echo" ; "Ky","slice","Kx","NR","FA","echo" ; "slice","Kx","Ky","NR","FA","echo" ; ...
                        "NR","Kx","Ky","slice","FA","echo" ; "NR","Ky","slice","Kx","FA","echo" ; "NR","slice","Kx","Ky","FA","echo" ; ...
                        "Kx","Ky","NR","slice","FA","echo" ; "Ky","slice","NR","Kx","FA","echo" ; "slice","Kx","NR","Ky","FA","echo" ; ...
                        "Kx","Ky","slice","echo","NR","FA" ; "Kx","slice","Ky","echo","NR","FA" ; "Ky","slice","Kx","echo","NR","FA" ];

                    app.imageOrientLabel = ["X","Y","slice","NR","FA","echo" ; "Y","slice","X","NR","FA","echo" ; "slice","X","Y","NR","FA","echo" ; ...
                        "NR","X","Y","slice","FA","echo" ; "NR","Y","slice","X","FA","echo" ; "NR","slice","X","Y","FA","echo" ; ...
                        "X","Y","NR","slice","FA","echo" ; "Y","slice","NR","X","FA","echo" ; "slice","X","NR","Y","FA","echo" ; ...
                        "X","Y","slice","echo","NR","FA" ; "X","slice","Y","echo","NR","FA" ; "Y","slice","X","echo","NR","FA" ];

            end

            % Reset orientation spinners
            app.OrientationSpinner.Value = 1;
            app.MIPOrientationSpinner.Value = 1;
            app.KOrientationSpinner.Value = 1;
            app.PhaseOrientationSpinner.Value = 1;

            % Total scan time and scan time / dynamic
            app.CalcTotalAcquisitionTimeFcn;

        end % SetParametersFcn


        % Calculate the total acquisition time
        function CalcTotalAcquisitionTimeFcn(app)

            % Purpose:
            %   Work out the total acquisition time and the time per dynamic.
            %
            % How it works:
            %   Prefers the duration the scanner recorded in the RPR file, parsed out of the
            %   IM_TEXT_DCM_AcquisitionDuration entry, because that is what the scan really
            %   took. Without an RPR file the time is computed from the repetition time and
            %   the number of acquisitions instead.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, pd.totalAcqTime and the displayed times are set.

            try

                if app.pd.rprFileFlag

                    pos = strfind(app.pd.rprFile,":IM_TEXT_DCM_AcquisitionDuration");
                    txt = app.pd.rprFile(pos:pos+40);
                    pos2 = strfind(txt,"""");
                    tt = extractBetween(txt,pos2(1)+1,pos2(2)-1);
                    app.pd.totalAcqTime = str2num(tt{1}); %#ok<*ST2NM>

                else

                    ME = MException('P2ROUD:acqnotfound','Acquisition duration not found in rpr file ...');
                    throw(ME);

                end

            catch ME

                app.TextMessage(ME.message,2);

                app.pd.totalAcqTime = app.pd.noViews*app.pd.noSlices*app.NEViewField.Value*app.NAViewField.Value*app.NFAViewField.Value*app.NRViewField.Value*app.pd.tr/1000;

                % FSE type sequences
                if contains(app.pd.PPL,'fse')
                    if app.pd.viewsPerSegment > 1
                        app.pd.totalAcqTime = app.pd.totalAcqTime/app.pd.viewsPerSegment;
                    else
                        app.pd.totalAcqTime = app.pd.totalAcqTime/app.NEViewField.Value;
                    end
                    app.pd.totalAcqTime = app.pd.totalAcqTime/app.pd.noSlices;
                end

                % FLASH type sequences
                if contains(app.pd.PPL,'flash')
                    app.pd.totalAcqTime = app.pd.totalAcqTime/app.NEViewField.Value;
                    if app.pd.sliceInterleave == 2
                        app.pd.totalAcqTime = app.pd.totalAcqTime/app.pd.noSlices;
                    end
                end

                app.pd.totalAcqTime = app.pd.totalAcqTime*app.pd.noViews2;

            end

            app.TimeDynViewField.Value = datestr(seconds(app.pd.totalAcqTime/app.NREditField.Value),'HH:MM:SS.FFF'); %#ok<*DATST>
            app.ScanTimeViewField.Value = datestr(seconds(app.pd.totalAcqTime),'HH:MM:SS.FFF');
            drawnow;

        end % CalcTotalAcquisitionTimeFcn


        % Set the k-space imaging limits
        function SetKspaceLimitsFcn(app)

            % Purpose:
            %   Set the k-space viewer sliders and labels to match the loaded data.
            %
            % How it works:
            %   Enables the k-space controls, then gives every slider and edit field the
            %   range of the dimension it drives, so that no control can be moved outside
            %   the data. The axis labels follow the chosen orientation, and dimensions of
            %   length one are disabled rather than left at a range of a single value.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the k-space controls are set.

            % General buttons
            app.KxLabel.Enable = 'on';
            app.KyLabel.Enable = 'on';
            app.KspaceMovieLabel.Enable = 'on';
            app.KStartButton.Enable = 'on';
            app.FastSlowSwitch.Enable = 'on';
            app.KLevelEditField.Enable = 'on';
            app.KLLabel.Enable = 'on';
            app.KWindowEditField.Enable = 'on';
            app.KWLabel.Enable = 'on';
            app.KLevelSlider.Enable = 'on';
            app.KWindowSlider.Enable = 'on';
            app.fillingButton.Enable = 'on';
            app.averagesButton.Enable = 'on';
            app.kValuesButton.Enable = 'on';
            app.senseMapButton.Enable = 'on';
            app.FillingViewField.Enable = 'on';
            app.FillingPercLabel.Enable = 'on';
            app.KOrientationSpinner.Enable = 'on';
            app.KOrientationSpinnerLabel.Enable = 'on';
            app.CSRecoLabel.Enable = 'on';
            app.CSRecoCheckBox.Enable = 'on';
            app.PCAdeNoiseLabel.Enable = 'on';
            app.PCAdeNoiseWindowLabel.Enable = 'on';
            app.BMDdeNoiseLabel.Enable = 'on';
            app.PCAdeNoiseCheckBox.Enable = 'on';
            app.BMDdeNoiseCheckBox.Enable = 'on';
            app.PCAdeNoiseWindowEditField.Enable = 'on';
            app.DeRingLabel.Enable = 'on';
            app.KStopButton.Enable = 'off';
            app.DeRingCheckBox.Enable = 'on';
            app.XEditField.Enable = 'on';
            app.YEditField.Enable = 'on';
            app.ZEditField.Enable = 'on';
            app.NREditField.Enable = 'on';

            % Initial settings
            app.stopMovieFlag = true;
            app.stopKspaceMovieFlag = true;
            app.newFigFlag = true;
            app.newPhaseFigFlag = true;
            app.MIPcounter = 0;

            % Coils
            if app.pd.nrCoils>1
                app.CoilSpinner.Value = 1;
                app.CoilSpinner.Limits = [1 app.pd.nrCoils];
                app.CoilSpinner.Enable = 'on';
                app.CoilSpinnerLabel.Enable = 'on';
                app.AutoSensitivityCheckBox.Value = 1;
            else
                app.CoilSpinner.Value = 1;
                app.CoilSpinner.Limits = [1 2];
                app.CoilSpinner.Enable = 'off';
                app.CoilSpinnerLabel.Enable = 'off';
                app.pd.coilActiveFlag = [1 0 0 0 0 0 0 0];
                app.AutoSensitivityCheckBox.Value = 0;
            end

            % Dimension 1 selection
            im = permute(abs(app.pd.rawKspace{app.CoilSpinner.Value}),app.imageOrient(app.KOrientationSpinner.Value,:));
            dim1size = size(im,3);
            app.KDim3EditField.Value = 1;
            app.KDim3EditFieldS.Value = 1;
            app.KDim3Slider.Value = 1;
            app.KDim3SliderS.Value = 1;
            if dim1size > 1
                app.KDim3EditField.Limits = [1,dim1size];
                app.KDim3EditFieldS.Limits = [1,dim1size];
                app.KDim3Slider.Limits = [1,dim1size];
                app.KDim3SliderS.Limits = [1,dim1size];
                app.KDim3EditField.Value = round(dim1size/2);
                app.KDim3EditFieldS.Value = round(dim1size/2);
                app.KDim3Slider.Value = round(dim1size/2);
                app.KDim3SliderS.Value = round(dim1size/2);
                app.KDim3EditField.Enable = 'on';
                app.KDim3EditFieldS.Enable = 'on';
                app.KDim3Slider.Enable = 'on';
                app.KDim3SliderS.Enable = 'on';
                app.KDim3Label.Enable = 'on';
                app.KDim3LabelS.Enable = 'on';
            else
                app.KDim3EditField.Limits = [1,2];
                app.KDim3EditFieldS.Limits = [1,2];
                app.KDim3Slider.Limits = [1,2];
                app.KDim3SliderS.Limits = [1,2];
                app.KDim3EditField.Enable = 'off';
                app.KDim3EditFieldS.Enable = 'off';
                app.KDim3Slider.Enable = 'off';
                app.KDim3SliderS.Enable = 'off';
                app.KDim3Label.Enable = 'off';
                app.KDim3LabelS.Enable = 'off';
            end

            % Dimension 2 selection
            dim2size = size(im,4);
            app.KDim4EditField.Value = 1;
            app.KDim4Slider.Value = 1;
            if dim2size > 1
                app.KDim4EditField.Limits = [1,dim2size];
                app.KDim4Slider.Limits = [1,dim2size];
                app.KDim4EditField.Value = round(dim2size/2);
                app.KDim4Slider.Value = round(dim2size/2);
                app.KDim4EditField.Enable = 'on';
                app.KDim4Slider.Enable = 'on';
                app.KDim4Label.Enable = 'on';
            else
                app.KDim4EditField.Limits = [1,2];
                app.KDim4Slider.Limits = [1,2];
                app.KDim4EditField.Enable = 'off';
                app.KDim4Slider.Enable = 'off';
                app.KDim4Label.Enable = 'off';
            end

            % Echo selection
            app.KEchoEditField.Value = 1;
            app.KEchoSlider.Value = 1;
            if app.pd.multiEchoesFlag
                app.KEchoEditField.Limits = [1,app.NEViewField.Value];
                app.KEchoSlider.Limits = [1,app.NEViewField.Value];
                app.KEchoEditField.Enable = 'on';
                app.KEchoSlider.Enable = 'on';
                app.KEchoEditFieldLabel.Enable = 'on';
            else
                app.KEchoEditField.Limits = [1,2];
                app.KEchoSlider.Limits = [1,2];
                app.KEchoEditField.Enable = 'off';
                app.KEchoSlider.Enable = 'off';
                app.KEchoEditFieldLabel.Enable = 'off';
            end

            % Flip angle selection
            app.KFlipAngleEditField.Value = 1;
            app.KFlipAngleSlider.Value = 1;
            if app.pd.multiFlipAnglesFlag
                app.KFlipAngleEditField.Limits = [1,app.NFAViewField.Value];
                app.KFlipAngleSlider.Limits = [1,app.NFAViewField.Value];
                app.KFlipAngleEditField.Enable = 'on';
                app.KFlipAngleSlider.Enable = 'on';
                app.KFlipAngleEditFieldLabel.Enable = 'on';
            else
                app.KFlipAngleEditField.Limits = [1,2];
                app.KFlipAngleSlider.Limits = [1,2];
                app.KFlipAngleEditField.Enable = 'off';
                app.KFlipAngleSlider.Enable = 'off';
                app.KFlipAngleEditFieldLabel.Enable = 'off';
            end

            % Label the orientations
            if app.senseMapButton.Value == 1
                app.KxLabel.Text = app.imageOrientLabel(app.KOrientationSpinner.Value,1);
                app.KyLabel.Text = app.imageOrientLabel(app.KOrientationSpinner.Value,2);
                app.KDim3Label.Text = app.imageOrientLabel(app.KOrientationSpinner.Value,3);
                app.KDim3LabelS.Text = app.imageOrientLabel(app.KOrientationSpinner.Value,3);
                app.KDim4Label.Text = app.imageOrientLabel(app.KOrientationSpinner.Value,4);
            else
                app.KxLabel.Text = app.kSpaceOrientLabel(app.KOrientationSpinner.Value,1);
                app.KyLabel.Text = app.kSpaceOrientLabel(app.KOrientationSpinner.Value,2);
                app.KDim3Label.Text = app.kSpaceOrientLabel(app.KOrientationSpinner.Value,3);
                app.KDim3LabelS.Text = app.kSpaceOrientLabel(app.KOrientationSpinner.Value,3);
                app.KDim4Label.Text = app.kSpaceOrientLabel(app.KOrientationSpinner.Value,4);
            end

            % Button status memory
            app.kSpaceButtonStatus = get(app.kSpaceButtonHandles, 'Enable');

        end % SetKspaceLimitsFcn


        % Set the image limits
        function SetImageLimitsFcn(app)

            % Purpose:
            %   Set the image, MIP and phase viewer controls to match the reconstruction.
            %
            % How it works:
            %   Permutes the images into the chosen orientation first, because that decides
            %   which array dimension each control drives, then gives every slider and edit
            %   field the matching range. Scrolling is enabled only when there is more than
            %   one slice. The three viewers are kept consistent so that switching tabs does
            %   not move the display.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the image controls are set.

            % Dimension 3 selection
            im = permute(abs(app.pd.images),app.imageOrient(app.OrientationSpinner.Value,:));
            dim3size = size(im,3);
            app.Dim3EditField.Value = 1;
            app.Dim3Slider.Value = 1;
            app.PhaseDim3EditField.Value = 1;
            app.PhaseDim3Slider.Value = 1;
            if dim3size > 1
                app.scrollEnableFlag = true;
                app.Dim3EditField.Limits = [1,dim3size];
                app.Dim3Slider.Limits = [1,dim3size];
                app.Dim3EditField.Value = round(dim3size/2);
                app.Dim3Slider.Value = round(dim3size/2);
                app.Dim3EditField.Enable = 'on';
                app.Dim3Slider.Enable = 'on';
                app.Dim3Label.Enable = 'on';
                app.PhaseDim3EditField.Limits = [1,dim3size];
                app.PhaseDim3Slider.Limits = [1,dim3size];
                app.PhaseDim3EditField.Value = round(dim3size/2);
                app.PhaseDim3Slider.Value = round(dim3size/2);
                app.PhaseDim3EditField.Enable = 'on';
                app.PhaseDim3Slider.Enable = 'on';
                app.PhaseDim3Label.Enable = 'on';
            else
                app.scrollEnableFlag = false;
                app.Dim3EditField.Limits = [1,2];
                app.Dim3Slider.Limits = [1,2];
                app.Dim3EditField.Enable = 'off';
                app.Dim3Slider.Enable = 'off';
                app.Dim3Label.Enable = 'off';
                app.PhaseDim3EditField.Limits = [1,2];
                app.PhaseDim3Slider.Limits = [1,2];
                app.PhaseDim3EditField.Enable = 'off';
                app.PhaseDim3Slider.Enable = 'off';
                app.PhaseDim3Label.Enable = 'off';
            end

            % Dimension 4 selection
            dim4size = size(im,4);
            app.Dim4EditField.Value = 1;
            app.Dim4Slider.Value = 1;
            app.PhaseDim4EditField.Value = 1;
            app.PhaseDim4Slider.Value = 1;
            if dim4size > 1
                app.Dim4EditField.Limits = [1,dim4size];
                app.Dim4Slider.Limits = [1,dim4size];
                app.Dim4EditField.Value = round(dim4size/2);
                app.Dim4Slider.Value = round(dim4size/2);
                app.Dim4EditField.Enable = 'on';
                app.Dim4Slider.Enable = 'on';
                app.Dim4Label.Enable = 'on';
                app.PhaseDim4EditField.Limits = [1,dim4size];
                app.PhaseDim4Slider.Limits = [1,dim4size];
                app.PhaseDim4EditField.Value = round(dim4size/2);
                app.PhaseDim4Slider.Value = round(dim4size/2);
                app.PhaseDim4EditField.Enable = 'on';
                app.PhaseDim4Slider.Enable = 'on';
                app.PhaseDim4Label.Enable = 'on';
            else
                app.Dim4EditField.Limits = [1,2];
                app.Dim4Slider.Limits = [1,2];
                app.Dim4EditField.Enable = 'off';
                app.Dim4Slider.Enable = 'off';
                app.Dim4Label.Enable = 'off';
                app.PhaseDim4EditField.Limits = [1,2];
                app.PhaseDim4Slider.Limits = [1,2];
                app.PhaseDim4EditField.Enable = 'off';
                app.PhaseDim4Slider.Enable = 'off';
                app.PhaseDim4Label.Enable = 'off';
            end

            % Dimension 5 selection
            dim5size = size(im,5);
            app.Dim5EditField.Value = 1;
            app.Dim5Slider.Value = 1;
            app.PhaseDim5EditField.Value = 1;
            app.PhaseDim5Slider.Value = 1;
            if dim5size > 1
                app.Dim5EditField.Limits = [1,dim5size];
                app.Dim5Slider.Limits = [1,dim5size];
                app.Dim5EditField.Enable = 'on';
                app.Dim5Slider.Enable = 'on';
                app.Dim5Label.Enable = 'on';
                app.PhaseDim5EditField.Limits = [1,dim5size];
                app.PhaseDim5Slider.Limits = [1,dim5size];
                app.PhaseDim5EditField.Enable = 'on';
                app.PhaseDim5Slider.Enable = 'on';
                app.PhaseDim5Label.Enable = 'on';
            else
                app.Dim5EditField.Limits = [1,2];
                app.Dim5Slider.Limits = [1,2];
                app.Dim5EditField.Enable = 'off';
                app.Dim5Slider.Enable = 'off';
                app.Dim5Label.Enable = 'off';
                app.PhaseDim5EditField.Limits = [1,2];
                app.PhaseDim5Slider.Limits = [1,2];
                app.PhaseDim5EditField.Enable = 'off';
                app.PhaseDim5Slider.Enable = 'off';
                app.PhaseDim5Label.Enable = 'off';
            end

            % Flip angle selection
            dim6size = size(im,6);
            app.Dim6EditField.Value = 1;
            app.Dim6Slider.Value = 1;
            app.PhaseDim6EditField.Value = 1;
            app.PhaseDim6Slider.Value = 1;
            if dim6size > 1
                app.Dim6EditField.Limits = [1,dim6size];
                app.Dim6Slider.Limits = [1,dim6size];
                app.Dim6EditField.Enable = 'on';
                app.Dim6Slider.Enable = 'on';
                app.Dim6Label.Enable = 'on';
                app.PhaseDim6EditField.Limits = [1,dim6size];
                app.PhaseDim6Slider.Limits = [1,dim6size];
                app.PhaseDim6EditField.Enable = 'on';
                app.PhaseDim6Slider.Enable = 'on';
                app.PhaseDim6Label.Enable = 'on';
            else
                app.Dim6EditField.Limits = [1,2];
                app.Dim6Slider.Limits = [1,2];
                app.Dim6EditField.Enable = 'off';
                app.Dim6Slider.Enable = 'off';
                app.Dim6Label.Enable = 'off';
                app.PhaseDim6EditField.Limits = [1,2];
                app.PhaseDim6Slider.Limits = [1,2];
                app.PhaseDim6EditField.Enable = 'off';
                app.PhaseDim6Slider.Enable = 'off';
                app.PhaseDim6Label.Enable = 'off';
            end

            % Label the orientations
            app.XLabel.Text = app.imageOrientLabel(app.OrientationSpinner.Value,1);
            app.YLabel.Text = app.imageOrientLabel(app.OrientationSpinner.Value,2);
            app.PhaseXLabel.Text = app.imageOrientLabel(app.PhaseOrientationSpinner.Value,1);
            app.PhaseYLabel.Text = app.imageOrientLabel(app.PhaseOrientationSpinner.Value,2);
            app.MIPXLabel.Text = app.XLabel.Text;
            app.MIPYLabel.Text = app.YLabel.Text;
            app.MIPxAngleLabel.Text = app.XLabel.Text;
            app.MIPyAngleLabel.Text = app.YLabel.Text;
            app.Dim3Label.Text = app.imageOrientLabel(app.OrientationSpinner.Value,3);
            app.Dim4Label.Text = app.imageOrientLabel(app.OrientationSpinner.Value,4);
            app.Dim5Label.Text = app.imageOrientLabel(app.OrientationSpinner.Value,5);
            app.Dim6Label.Text = app.imageOrientLabel(app.OrientationSpinner.Value,6);
            app.PhaseDim3Label.Text = app.imageOrientLabel(app.PhaseOrientationSpinner.Value,3);
            app.PhaseDim4Label.Text = app.imageOrientLabel(app.PhaseOrientationSpinner.Value,4);
            app.PhaseDim5Label.Text = app.imageOrientLabel(app.PhaseOrientationSpinner.Value,5);
            app.PhaseDim6Label.Text = app.imageOrientLabel(app.PhaseOrientationSpinner.Value,6);

            % Movie button
            if app.stopMovieFlag
                app.MoviePlayButton.Enable = 'on';
                app.MovieStopButton.Enable = 'off';
            else
                app.MoviePlayButton.Enable = 'off';
                app.MovieStopButton.Enable = 'on';
            end

            % Phase movie button
            if app.stopPhaseMovieFlag
                app.PhaseMovieStartButton.Enable = 'on';
                app.PhaseMovieStopButton.Enable = 'off';
            else
                app.PhaseMovieStartButton.Enable = 'off';
                app.PhaseMovieStopButton.Enable = 'on';
            end

            % Other buttons
            app.XLabel.Enable = 'on';
            app.YLabel.Enable = 'on';
            app.MaskButton.Enable = 'on';
            app.ThresholdEditFieldLabel.Enable = 'on';
            app.ThresholdEditField.Enable = 'on';
            app.ThresholdSlider.Enable = 'on';
            app.ErodeButton.Enable = 'on';
            app.DilateButton.Enable = 'on';
            app.OpenButton.Enable = 'on';
            app.CloseButton.Enable = 'on';
            app.AutoIntensityCheckBox.Enable = 'on';
            app.AutoIntensityCheckBoxLabel.Enable = 'on';
            app.SetButton.Enable = 'on';
            app.WindowEditField.Enable = 'on';
            app.WindowSlider.Enable = 'on';
            app.WLabel.Enable = 'on';
            app.LevelEditField.Enable = 'on';
            app.LevelSlider.Enable = 'on';
            app.LLabel.Enable = 'on';
            app.OrientationSpinner.Enable = 'on';
            app.OrientationSpinnerLabel.Enable = 'on';

            app.MIPXLabel.Enable = 'on';
            app.MIPYLabel.Enable = 'on';
            app.MIPSetButton.Enable = 'on';
            app.MIPWindowEditField.Enable = 'on';
            app.MIPWindowSlider.Enable = 'on';
            app.MIPWLabel.Enable = 'on';
            app.MIPLevelEditField.Enable = 'on';
            app.MIPLevelSlider.Enable = 'on';
            app.MIPLLabel.Enable = 'on';
            app.MIPOrientationSpinner.Enable = 'on';
            app.MIPOrientationSpinnerLabel.Enable = 'on';
            app.MIPAngleResetButton.Enable = 'on';
            app.MIPResizeButton.Enable = 'on';
            app.MIPxAngleSlider.Enable = 'on';
            app.MIPyAngleSlider.Enable = 'on';
            app.MIPxAngleLabel.Enable = 'on';
            app.MIPyAngleLabel.Enable = 'on';
            app.MIPxPlayButton.Enable = 'on';
            app.MIPyPlayButton.Enable = 'on';
            app.MIPRotateLabel.Enable = 'on';

            app.PhaseXLabel.Enable = 'on';
            app.PhaseYLabel.Enable = 'on';
            app.PhaseUnwrapButton.Enable = 'on';
            app.PhaseOrientationSpinner.Enable = 'on';
            app.PhaseOrientationSpinnerLabel.Enable = 'on';
            app.PhaseMinLabel.Enable = 'on';
            app.PhaseMaxLabel.Enable = 'on';

            % Button status memory
            app.imageButtonStatus = get(app.imageButtonHandles, 'Enable');

        end % SetImageLimitsFcn


        % Sort and fill k-space
        function FillKspaceFcn(app)

            % Purpose:
            %   Sort the raw data into k-space and show the result.
            %
            % How it works:
            %   Hands the work to pd.fillKspace, mirrors the sizes that came back, then
            %   takes a fresh window and level and replots through the two window and level
            %   callbacks.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, pd holds the filled k-space and the view is updated.

            % Sort the raw data onto the reconstruction grid, then show it
            %
            % The sorting itself is app.pd.fillKspace, which picks the path from the
            % trajectory and data type, handles segmented acquisitions and the
            % navigator, and applies the Tukey filter and k-space scaling.

            app.pd = app.pd.fillKspace(gatherRecoParams(app));

            MirrorDataOutputsFcn(app);

            % Window and level, plot
            app.SetKspaceWLFcn;
            app.KWindowEditFieldValueChanged;
            app.KLevelEditFieldValueChanged;

        end % FillKspaceFcn


        % --------------------------------------------------------------
        %
        %                           FIGURES
        %
        % --------------------------------------------------------------


        % Calculate the k-space image aspect ratio
        function result = CalcKAspectRatioFcn(app,dim1Label,dim2Label,sizeX,sizeY)

            % Purpose:
            %   Work out the aspect ratio for the k-space display.
            %
            % How it works:
            %   Radial and ZTE k-space is isotropic by construction, so the ratio is simply
            %   the pixel counts. For Cartesian data the field of view differs per axis, so
            %   the ratio combines the field of view of the two axes on screen with their
            %   sizes, which is what keeps a rectangular field of view from being drawn
            %   square.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %   dim1Label - axis label of the first displayed dimension
            %   dim2Label - axis label of the second displayed dimension
            %   sizeX - number of points along the first dimension
            %   sizeY - number of points along the second dimension
            %
            % Output:
            %   result - the aspect ratio to give daspect.

            switch app.pd.dataType

                case {"3Dute","ZTE"}

                    result = sizeY/sizeX;

                otherwise

                    L1 = 1;
                    L2 = 1;

                    if strcmp(dim1Label,'Kx') L1 = app.FOVViewField1.Value; end
                    if strcmp(dim1Label,'Ky') L1 = app.FOVViewField2.Value; end
                    if strcmp(dim1Label,'Kz') L1 = app.FOVViewField3.Value; end
                    if strcmp(dim1Label,'NR') L1 = min([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
                    if strcmp(dim1Label,'slice') L1 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end

                    if strcmp(dim2Label,'Kx') L2 = app.FOVViewField1.Value; end
                    if strcmp(dim2Label,'Ky') L2 = app.FOVViewField2.Value; end
                    if strcmp(dim2Label,'Kz') L2 = app.FOVViewField3.Value; end
                    if strcmp(dim2Label,'NR') L2 = min([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
                    if strcmp(dim2Label,'slice') L2 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end

                    result = (L2/L1);

            end

        end % CalcKAspectRatioFcn


        % Calculate the image aspect ratio
        function result = CalcImageAspectRatioFcn(app, dim1Label, dim2Label, sizeX, sizeY)

            % Purpose:
            %   Work out the aspect ratio for the image display.
            %
            % How it works:
            %   Maps each axis label to the field of view of that axis and combines it with
            %   the matrix size, so that anisotropic voxels are drawn with the right shape.
            %   A dynamics axis has no field of view of its own and takes the mean of the
            %   two in-plane values.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %   dim1Label - axis label of the first displayed dimension
            %   dim2Label - axis label of the second displayed dimension
            %   sizeX - number of points along the first dimension
            %   sizeY - number of points along the second dimension
            %
            % Output:
            %   result - the aspect ratio to give daspect.

            L1 = 1;
            L2 = 1;

            if (strcmp(dim1Label,'X') || strcmp(dim1Label,'Kx')) L1 = app.FOVViewField1.Value; end
            if (strcmp(dim1Label,'Y') || strcmp(dim1Label,'Ky')) L1 = app.FOVViewField2.Value; end
            if (strcmp(dim1Label,'Z') || strcmp(dim1Label,'Kz')) L1 = app.FOVViewField3.Value; end
            if strcmp(dim1Label,'NR') L1 = mean([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
            if strcmp(dim1Label,'slice') L1 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end

            if (strcmp(dim2Label,'X') || strcmp(dim2Label,'Kx')) L2 = app.FOVViewField1.Value; end
            if (strcmp(dim2Label,'Y') || strcmp(dim2Label,'Ky')) L2 = app.FOVViewField2.Value; end
            if (strcmp(dim2Label,'Z') || strcmp(dim2Label,'Kz')) L2 = app.FOVViewField3.Value; end
            if strcmp(dim2Label,'NR') L2 = mean([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
            if strcmp(dim2Label,'slice') L2 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end

            result = (L1/L2)*(sizeY/sizeX);

        end % CalcImageAspectRatioFcn


        % Calculate the MIP image aspect ratio
        function result = CalcMIPAspectRatioFcn(app,dim1Label,dim2Label,sizeX,sizeY,alphaX,alphaY)

            % Purpose:
            %   Work out the aspect ratio for the rotated maximum intensity projection.
            %
            % How it works:
            %   As CalcImageAspectRatioFcn, with the two rotation angles taken into account:
            %   a rotated projection is a combination of two axes, so the effective extent
            %   along each screen direction changes with the angle.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %   dim1Label - axis label of the first displayed dimension
            %   dim2Label - axis label of the second displayed dimension
            %   sizeX - number of points along the first dimension
            %   sizeY - number of points along the second dimension
            %   alphaX - rotation about the x axis, degrees
            %   alphaY - rotation about the y axis, degrees
            %
            % Output:
            %   result - the aspect ratio to give daspect.

            if strcmp(dim1Label,'X') L1 = app.FOVViewField1.Value; end
            if strcmp(dim1Label,'Y') L1 = app.FOVViewField2.Value; end
            if strcmp(dim1Label,'Z') L1 = app.FOVViewField3.Value; end
            if strcmp(dim1Label,'NR') L1 = mean([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
            if strcmp(dim1Label,'slice') L1 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end

            if strcmp(dim2Label,'X') L2 = app.FOVViewField1.Value; end
            if strcmp(dim2Label,'Y') L2 = app.FOVViewField2.Value; end
            if strcmp(dim2Label,'Z') L2 = app.FOVViewField3.Value; end
            if strcmp(dim2Label,'NR') L2 = mean([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
            if strcmp(dim2Label,'slice') L2 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end

            if strcmp(app.Dim3Label.Text,'X') L3 = app.FOVViewField1.Value; end
            if strcmp(app.Dim3Label.Text,'Y') L3 = app.FOVViewField2.Value; end
            if strcmp(app.Dim3Label.Text,'Z') L3 = app.FOVViewField3.Value; end
            if strcmp(app.Dim3Label.Text,'NR') L3 = mean([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
            if strcmp(app.Dim3Label.Text,'slice') L3 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end

            fovx = sqrt((L1 * cos(pi*alphaY/180))^2 + (L3 * sin(pi*alphaY/180))^2);
            fovy = sqrt((L2 * cos(pi*alphaX/180))^2 + (L3 * sin(pi*alphaX/180))^2);

            result = (fovy/fovx)*(sizeY/sizeX);

        end % CalcMIPAspectRatioFcn


        % Set the image window and level
        function SetImageWLFcn(app)

            % Purpose:
            %   Take a window and level for the image display from the data.
            %
            % How it works:
            %   Takes the slice that is on screen and asks proud.util.imageWindowLevel for
            %   the scaling, then writes it to both the sliders and the edit fields so the
            %   pair agrees. Does nothing without a valid reconstruction.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the window and level controls are set.

            if app.pd.validRecoFlag

                % Select the data
                im = app.pd.images;

                im = permute(im,app.imageOrient(app.OrientationSpinner.Value,:));
                im = squeeze(im(:,:,app.Dim3EditField.Value,app.Dim4EditField.Value,app.Dim5EditField.Value,app.Dim6EditField.Value));

                [wScale, scale] = proud.util.imageWindowLevel(im);

                app.LevelSlider.Value = double(scale);
                app.LevelEditField.Value = double(round(scale));
                app.WindowSlider.Value = double(wScale);
                app.WindowEditField.Value = double(round(wScale));

            end

        end % SetImageWLFcn


        % Set the MIP window and level
        function SetMIPWLFcn(app)

            % Purpose:
            %   Take a window and level for the MIP display from the data.
            %
            % How it works:
            %   Projects the volume the same way the display does and scales from the
            %   maximum of that projection, at a third for the level and two thirds for the
            %   window. A projection is brighter than a slice, which is why it does not use
            %   the image window and level.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the MIP window and level controls are set.

            if app.pd.validRecoFlag

                % Select the data
                im = app.pd.images;
                im = permute(im,app.imageOrient(app.OrientationSpinner.Value,:));
                im = squeeze(im(:,:,:,app.Dim4EditField.Value,app.Dim5EditField.Value,app.Dim6EditField.Value));
                im = max(im,[],3);

                scale = double(max(im(:)));

                app.MIPLevelSlider.Value = scale/3;
                app.MIPLevelEditField.Value = round(scale/3);
                app.MIPWindowSlider.Value = scale/1.5;
                app.MIPWindowEditField.Value = round(scale/1.5);

            end

        end % SetMIPWLFcn


        % Set the k-space image window and level
        function SetKspaceWLFcn(app)

            % Purpose:
            %   Take a window and level for the k-space display from the data.
            %
            % How it works:
            %   Uses the sensitivity map or the raw k-space, whichever is on show, and asks
            %   proud.util.kspaceWindowLevel for the scaling. The two need different
            %   treatment: sensitivities are relative values around one, k-space is
            %   absolute and has a very bright centre.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the k-space window and level controls are set.

            if app.pd.validFileFlag

                % Select the data
                if app.senseMapButton.Value == 1
                    im = abs(app.pd.sensitivityMaps{app.CoilSpinner.Value});
                else
                    im = abs(app.pd.rawKspace{app.CoilSpinner.Value});
                end

                % Select which slice to show
                im = permute(im,app.imageOrient(app.KOrientationSpinner.Value,:));
                im = squeeze(im(:,:,app.KDim3EditField.Value,app.KDim4EditField.Value,app.KFlipAngleEditField.Value,app.KEchoEditField.Value));

                [window, level] = proud.util.kspaceWindowLevel(im, app.senseMapButton.Value == 1);

                app.KWindowSlider.Value = window;
                app.KWindowEditField.Value = window;
                app.KLevelSlider.Value = level;
                app.KLevelEditField.Value = level;

            end

        end % SetKspaceWLFcn


        % Plot k-space
        function PlotKspaceFigFcn(app)

            % Purpose:
            %   Draw the k-space view.
            %
            % How it works:
            %   Draws one of four things according to the selected button: the filling
            %   pattern, the averages, the k-space values themselves, or the sensitivity
            %   maps, each with its own colour map and scaling. The slice on show comes from
            %   the viewer controls and the orientation spinner. The whole body is guarded,
            %   because a plot failing should not stop a reconstruction.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the k-space figure is redrawn.

            try

                if app.pd.validFileFlag

                    cla(app.KspaceFig);
                    delete(app.cbar);

                    % Select the data
                    if app.fillingButton.Value == 1

                        im = app.pd.fillingSpace;
                        cmap = [0 0 0 ; summer(1)];
                        minScale = 0;
                        maxScale = max(im(:));

                    end

                    if app.averagesButton.Value == 1

                        im = app.pd.nsaSpace;
                        cmap = [0 0 0 ; summer(max(im(:)))];
                        minScale = 0;
                        maxScale = max(im(:));
                        app.cbar = colorbar(app.KspaceFig,'location','eastoutside','TickLabelsMode','auto','FontSize',12);

                    end

                    if app.kValuesButton.Value == 1

                        % Plot the k-space
                        im = abs(app.pd.rawKspace{app.CoilSpinner.Value});
                        cmap = gray;

                        % Window and Level
                        lvl = app.KLevelSlider.Value;
                        wdw = app.KWindowSlider.Value;
                        im = (1/lvl)*(im - lvl + wdw/2);
                        im(im<0) = 0;
                        im(im>1) = 1;
                        minScale = 0;
                        maxScale = 1;

                    end

                    if app.senseMapButton.Value == 1

                        % Plot the sense map
                        im = app.pd.MAX_KSPACE*abs(app.pd.sensitivityMaps{app.CoilSpinner.Value});
                        cmap = gray;

                        % Window and Level
                        lvl = app.KLevelSlider.Value;
                        wdw = app.KWindowSlider.Value;
                        im = (1/lvl)*(im - lvl + wdw/2);
                        im(im<0) = 0;
                        im(im>1) = 1;
                        minScale = 0;
                        maxScale = 1;

                    end

                    % Plot the image in the image panel
                    app.KspaceFig.Position = [34,87,325,325];

                    % Select which slice to show
                    im = permute(im,app.imageOrient(app.KOrientationSpinner.Value,:));
                    im = squeeze(im(:,:,app.KDim3EditField.Value,app.KDim4EditField.Value,app.KFlipAngleEditField.Value,app.KEchoEditField.Value));

                    if app.senseMapButton.Value == 1
                        % Rotate the image if phase-orientation equals 1
                        recoAspectRatio = app.CalcImageAspectRatioFcn(app.KxLabel.Text,app.KyLabel.Text,size(im,1),size(im,2));
                        if strcmp(app.KxLabel.Text,'X') && strcmp(app.KyLabel.Text,'Y') && app.pd.phaseOrientation
                            im = rot90(im,-1);
                            recoAspectRatio = app.CalcImageAspectRatioFcn(app.KyLabel.Text,app.KxLabel.Text,size(im,1),size(im,2));
                        end
                    else
                        recoAspectRatio = app.CalcKAspectRatioFcn(app.KxLabel.Text,app.KyLabel.Text,size(im,1),size(im,2));
                    end

                    % Plot the image in the k-space images panel
                    imshow(rot90(im),[minScale maxScale],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.KspaceFig);

                    % Image size
                    daspect(app.KspaceFig,[1 recoAspectRatio 1]);
                    xlim(app.KspaceFig,[0, size(im,1) + 1]);
                    ylim(app.KspaceFig,[0, size(im,2) + 1]);

                    % Plot the 8 small sensitivity map figs
                    app.PlotSenseMapsFcn;

                    drawnow;

                end

            catch ME

                app.TextMessage(ME.message,2);
                app.TextMessage("Something has gone wrong during plotting in k-space figure panel ...",1);

            end

        end % PlotKspaceFigFcn


        % Plot the 8 small sense maps in the coils panel
        function PlotSenseMapsFcn(app)

            % Purpose:
            %   Draw the coil sensitivity maps, one panel per coil.
            %
            % How it works:
            %   Stacks the maps of all coils into one array so that they share a scaling and
            %   can be compared by eye, then draws the selected slice of each into its own
            %   axes.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the sensitivity figure is redrawn.

            try

                % Image colormap
                cmap = gray;

                % Make coil images
                im = zeros([app.pd.MAX_COILS,size(app.pd.sensitivityMaps{1})]);
                for coil = 1:app.pd.MAX_COILS
                    im(coil,:,:,:,:,:,:) = abs(app.pd.sensitivityMaps{coil});
                end

                im = permute(im,[1 1+app.imageOrient(app.KOrientationSpinner.Value,:)]);
                im = im(:,:,:,app.KDim3EditField.Value,app.KDim4EditField.Value,app.KFlipAngleEditField.Value,app.KEchoEditField.Value);

                % Rotate for phase_orientation equals 1
                for coil = 1:app.pd.MAX_COILS
                    if ((strcmp(app.KxLabel.Text,'X') && strcmp(app.KyLabel.Text,'Y')) || ...
                            (strcmp(app.KxLabel.Text,'Kx') && strcmp(app.KyLabel.Text,'Ky'))) ...
                            && app.pd.phaseOrientation
                        im0 = permute(im,[2,3,1]);
                        ims{coil} = rot90(im0(:,:,coil),-1); %#ok<AGROW>
                    else
                        im0 = permute(im,[2,3,1]);
                        ims{coil} = im0(:,:,coil); %#ok<AGROW>
                    end
                end

                % AspectRatio
                recoAspectRatio = app.CalcImageAspectRatioFcn(app.KxLabel.Text,app.KyLabel.Text,size(ims{1},1),size(ims{1},2));
                if strcmp(app.KxLabel.Text,'X') && strcmp(app.KyLabel.Text,'Y') && app.pd.phaseOrientation
                    recoAspectRatio = app.CalcImageAspectRatioFcn(app.KyLabel.Text,app.KxLabel.Text,size(ims{1},1),size(ims{1},2));
                end

                for coil = 1:app.pd.nrCoils
                    mx(coil) = max(ims{coil}(:)); %#ok<AGROW>
                end
                for coil = 1:app.pd.nrCoils
                    ims{coil} = ims{coil}/max(mx(:));
                end

                % X and Y sizes
                sizeXY = [0 size(ims{1},1)+1 0 size(ims{1},2)+1];

                % Put them in the figures
                cla(app.SenseFig1);
                imshow(rot90(ims{1}),[0 1],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.SenseFig1);
                daspect(app.SenseFig1,[1 recoAspectRatio 1]);
                axis(app.SenseFig1,sizeXY);

                cla(app.SenseFig2);
                imshow(rot90(ims{2}),[0 1],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.SenseFig2);
                daspect(app.SenseFig2,[1 recoAspectRatio 1]);
                axis(app.SenseFig2,sizeXY);

                cla(app.SenseFig3);
                imshow(rot90(ims{3}),[0 1],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.SenseFig3);
                daspect(app.SenseFig3,[1 recoAspectRatio 1]);
                axis(app.SenseFig3,sizeXY);

                cla(app.SenseFig4);
                imshow(rot90(ims{4}),[0 1],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.SenseFig4);
                daspect(app.SenseFig4,[1 recoAspectRatio 1]);
                axis(app.SenseFig4,sizeXY);

                cla(app.SenseFig5);
                imshow(rot90(ims{5}),[0 1],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.SenseFig5);
                daspect(app.SenseFig5,[1 recoAspectRatio 1]);
                axis(app.SenseFig5,sizeXY);

                cla(app.SenseFig6);
                imshow(rot90(ims{6}),[0 1],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.SenseFig6);
                daspect(app.SenseFig6,[1 recoAspectRatio 1]);
                axis(app.SenseFig6,sizeXY);

                cla(app.SenseFig7);
                imshow(rot90(ims{7}),[0 1],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.SenseFig7);
                daspect(app.SenseFig7,[1 recoAspectRatio 1]);
                axis(app.SenseFig7,sizeXY);

                cla(app.SenseFig8);
                imshow(rot90(ims{8}),[0 1],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.SenseFig8);
                daspect(app.SenseFig8,[1 recoAspectRatio 1]);
                axis(app.SenseFig8,sizeXY);

                drawnow;

            catch ME

                app.TextMessage(ME.message,2);
                app.TextMessage("Something has gone wrong during plotting of sensitivity maps ...",1);

            end

        end % PlotSenseMapsFcn


        % Plot the reconstructed image
        function PlotImageFigFcn(app)

            % Purpose:
            %   Draw the image view.
            %
            % How it works:
            %   Applies the mask, permutes into the chosen orientation and takes the slice
            %   the viewer controls select. The colour map is grey with the lowest entry set
            %   to red when a mask is active, so masked-out voxels are visible as excluded
            %   rather than merely dark. Window and level come from the controls, or are
            %   computed here when the automatic box is ticked.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the image figure is redrawn.

            try

                if app.pd.validRecoFlag

                    % Red color map
                    cmap = [linspace(0,1,256)', linspace(0,1,256)', linspace(0,1,256)'];
                    if app.pd.validMaskFlag
                        cmap(1,:) = [0.7 0 0 ]; % reddish
                    end

                    % Select the data
                    try
                        im = app.pd.images.*app.pd.mask;
                    catch
                        im = app.pd.images;
                    end

                    % Select which slice to show
                    im = permute(im,app.imageOrient(app.OrientationSpinner.Value,:));
                    im = squeeze(im(:,:,app.Dim3EditField.Value,app.Dim4EditField.Value,app.Dim5EditField.Value,app.Dim6EditField.Value));

                    % Image scale
                    if app.AutoIntensityCheckBox.Value == 1
                        maxScale = double(round(max(im(:))));
                        threshold = double(graythresh(mat2gray(im)))*maxScale;
                        imt = im;
                        imt(imt < threshold) = 0;
                        scale = mean(nonzeros(imt(:)));
                        scale(scale > maxScale) = maxScale;
                        scale(isnan(scale)) = maxScale;
                        wScale = 2*scale;
                        wScale(wScale > maxScale) = maxScale;
                        app.LevelSlider.Value = double(scale);
                        app.LevelEditField.Value = double(round(scale));
                        app.WindowSlider.Value = double(wScale);
                        app.WindowEditField.Value = double(round(wScale));
                    end
                    minScale = app.LevelEditField.Value - app.WindowEditField.Value/2;
                    maxScale = app.LevelEditField.Value + app.WindowEditField.Value/2;
                    if minScale >= maxScale
                        maxScale = minScale + 1;
                    end

                    % Image aspect ratio & dimensions
                    recoAspectRatio = app.CalcImageAspectRatioFcn(app.XLabel.Text,app.YLabel.Text,size(im,1),size(im,2));

                    % If phase orientation = 1 rotate image (for viewing purposes only)
                    if strcmp(app.XLabel.Text,'X') && strcmp(app.YLabel.Text,'Y') && app.pd.phaseOrientation
                        im = rot90(im,-1);
                        recoAspectRatio = app.CalcImageAspectRatioFcn(app.YLabel.Text,app.XLabel.Text,size(im,1),size(im,2));
                    end

                    % Plot the image in the images panel
                    if app.newFigFlag

                        % Plot the image in the image panel
                        cla(app.RecoFig);
                        app.RecoFig.Position = [43,87,325,325];

                        % New fig with changed properties
                        app.recoImage = imshow(rot90(im),[minScale maxScale],'Colormap',cmap,'Interpolation','bilinear','InitialMagnification','fit','Parent',app.RecoFig);

                        % Aspect ratio and dimensions
                        daspect(app.RecoFig,[1 recoAspectRatio 1]);
                        xlim(app.RecoFig,[0, size(im,1) + 1]);
                        ylim(app.RecoFig,[0, size(im,2) + 1]);

                        % Calculate the left, right, top, bottom, image orienation labels
                        app.pd = app.pd.imageOrientLabels(gatherRecoParams(app));

                        % Plot the labels
                        app.rLabel = text(app.RecoFig,0.960*size(im,1),0.520*size(im,2),app.pd.rightLabel,'Color',app.green,'FontSize',14);
                        app.lLabel = text(app.RecoFig,0.015*size(im,1),0.520*size(im,2),app.pd.leftLabel,'Color',app.green,'FontSize',14);
                        app.bLabel = text(app.RecoFig,0.490*size(im,1),0.970*size(im,2),app.pd.bottomLabel,'Color',app.green,'FontSize',14);
                        app.tLabel = text(app.RecoFig,0.490*size(im,1),0.030*size(im,2),app.pd.topLabel,'Color',app.green,'FontSize',14);

                        % Set the new figure flag to false
                        app.newFigFlag = false;

                    else

                        % Only new data in the figure = fast
                        set(app.recoImage, 'CData', rot90(im));
                        set(app.recoImage, 'CDataMapping', 'scaled');

                        % Calculate the left, right, top, bottom, image orienation labels
                        app.pd = app.pd.imageOrientLabels(gatherRecoParams(app));

                        % Plot the labels
                        delete(app.rLabel);
                        delete(app.lLabel);
                        delete(app.bLabel);
                        delete(app.tLabel);
                        app.rLabel = text(app.RecoFig,0.960*size(im,1),0.520*size(im,2),app.pd.rightLabel,'Color',app.green,'FontSize',14);
                        app.lLabel = text(app.RecoFig,0.015*size(im,1),0.520*size(im,2),app.pd.leftLabel,'Color',app.green,'FontSize',14);
                        app.bLabel = text(app.RecoFig,0.490*size(im,1),0.970*size(im,2),app.pd.bottomLabel,'Color',app.green,'FontSize',14);
                        app.tLabel = text(app.RecoFig,0.490*size(im,1),0.030*size(im,2),app.pd.topLabel,'Color',app.green,'FontSize',14);

                        app.RecoFig.CLim = [minScale maxScale];
                        pause(0.002);

                    end

                    drawnow;

                end

            catch ME

                app.TextMessage(ME.message,2);
                app.TextMessage("Something has gone wrong during plotting of reconstructed images ...",1);

            end

        end % PlotImageFigFcn


        % Plot the MIP
        function PlotMipFigFcn(app)

            % Purpose:
            %   Draw the maximum intensity projection.
            %
            % How it works:
            %   Masks and permutes as the image view does, then projects along the third
            %   dimension after rotating by the two angles. The volume is halved in size
            %   first when the resize button is set, which keeps the rotation animation
            %   smooth on a large volume.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the MIP figure is redrawn.

             if app.pd.validRecoFlag

                % Clear the current figure
                cla(app.MIPFig);

                % Select the data
                im = app.pd.images.*app.pd.mask;

                % Select which volume to show in MIP window
                im = permute(im,app.imageOrient(app.OrientationSpinner.Value,:));
                im = squeeze(im(:,:,:,app.Dim4EditField.Value,app.Dim5EditField.Value,app.Dim6EditField.Value));

                if ndims(im) == 3

                    % Downsample if selected
                    if app.MIPResizeButton.Value
                        im = imresize3(im,size(im)/2);
                    end

                    % Resize image for correct image dimensions
                    maxDim = 1.2*max(size(im));
                    imDim = app.pd.pixelSize(app.imageOrient(app.OrientationSpinner.Value,:));
                    imDim = imDim/min(imDim(1:3));
                    im = imresize3(im,[size(im,1)*imDim(1) size(im,2)*imDim(2) size(im,3)*imDim(3)]);

                    % Image aspect ratio & dimensions
                    recoAspectRatio = app.CalcMIPAspectRatioFcn(app.XLabel.Text,app.YLabel.Text,size(im,2),size(im,1),0,0);

                    % If phase orientation = 1 rotate image (for viewing purposes only)
                    if strcmp(app.XLabel.Text,'X') && strcmp(app.YLabel.Text,'Y') && app.pd.phaseOrientation
                        recoAspectRatio = app.CalcMIPAspectRatioFcn(app.YLabel.Text,app.XLabel.Text,size(im,1),size(im,2),0,0);
                    end

                    % Phase orientation
                    if strcmp(app.XLabel.Text,'X') && strcmp(app.YLabel.Text,'Y') && app.pd.phaseOrientation
                        im = rot90(im,-1);
                    end

                    % Rotate
                    im = imrotate3(im,app.MIPxAngleSlider.Value,[0 1 0]);
                    im = imrotate3(im,app.MIPyAngleSlider.Value,[1 0 0]);

                    % MIP of 3rd dimension
                    im = max(im,[],3);

                    % Image scale
                    minScale = app.MIPLevelEditField.Value - app.MIPWindowEditField.Value/2;
                    maxScale = app.MIPLevelEditField.Value + app.MIPWindowEditField.Value/2;
                    if minScale >= maxScale
                        maxScale = minScale + 1;
                    end

                    % Plot the image in the image panel
                    app.MIPFig.Position = [43,87,325,325];

                    % New fig with changed properties
                    imshow(app.blackImage,'XData',[-2*maxDim 2*maxDim],'YData',[-2*maxDim 2*maxDim],'Parent',app.MIPFig);
                    hold(app.MIPFig,'on');
                    imshow(rot90(im),[minScale maxScale],'Parent',app.MIPFig);
                    daspect(app.MIPFig,[1 recoAspectRatio 1]);
                    xlim(app.MIPFig,[(size(im,1) - maxDim)/2, (maxDim + size(im,1))/2]);
                    ylim(app.MIPFig,[(size(im,2) - maxDim)/2, (maxDim + size(im,2))/2]);
                    hold(app.MIPFig,'off');

                else

                    % No image
                    emptyImage = ones(10,10);
                    imshow(emptyImage,[0 1.2],'Parent',app.MIPFig);
                    xlim(app.MIPFig,[0, size(emptyImage,1) + 1]);
                    ylim(app.MIPFig,[0, size(emptyImage,2) + 1]);

                end

            end

        end % PlotMipFigFcn


        % Plot the reconstructed phase image
        function PlotPhaseImageFigFcn(app)

            % Purpose:
            %   Draw the phase image view.
            %
            % How it works:
            %   Masks, permutes and slices as the image view does, but scales in multiples
            %   of pi so that the colour bar reads in radians, and widens the range after
            %   unwrapping where the values no longer fold into a single turn.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the phase figure is redrawn.

            if app.pd.validRecoFlag

                % Select the data
                im = app.pd.phaseImages.*app.pd.mask;

                % Select which slice to show
                im = permute(im,app.imageOrient(app.PhaseOrientationSpinner.Value,:));
                im = squeeze(im(:,:,app.PhaseDim3EditField.Value,app.PhaseDim4EditField.Value,app.PhaseDim5EditField.Value,app.PhaseDim6EditField.Value));

                % Image aspect ratio & dimensions
                recoAspectRatio = app.CalcImageAspectRatioFcn(app.PhaseXLabel.Text,app.PhaseYLabel.Text,size(im,1),size(im,2));

                % Scale
                scale = floor(max(abs(im(:)))/pi)+1;

                % If phase orientation = 1 rotate image (for viewing purposes only)
                if strcmp(app.PhaseXLabel.Text,'X') && strcmp(app.PhaseYLabel.Text,'Y') && app.pd.phaseOrientation
                    im = rot90(im,-1);
                    recoAspectRatio = app.CalcImageAspectRatioFcn(app.PhaseYLabel.Text,app.PhaseXLabel.Text,size(im,1),size(im,2));
                end

                % Plot the image in the images panel
                if app.newPhaseFigFlag

                    % Plot the image in the image panel
                    cla(app.PhaseRecoFig);
                    app.PhaseRecoFig.Position = [43,87,325,325];

                    % New fig with changed properties
                    app.phaseRecoImage = imshow(rot90(im),[-pi*scale-0.001 pi*scale],'Interpolation','bilinear','InitialMagnification','fit','Parent',app.PhaseRecoFig);

                    % Aspect ratio and dimensions
                    daspect(app.PhaseRecoFig,[1 recoAspectRatio 1]);
                    xlim(app.PhaseRecoFig,[0, size(im,1) + 1]);
                    ylim(app.PhaseRecoFig,[0, size(im,2) + 1]);
                    cbr = colorbar(app.PhaseRecoFig);
                    cbr.Position = [0.7 0.5 0.02 0.2];
                    cbr.TickDirection = 'out';
                    if scale == 1
                        app.PhaseMaxLabel.Text = ' π';
                        app.PhaseMinLabel.Text = '-π';
                    else
                        app.PhaseMaxLabel.Text = [' ',num2str(scale,2),'π'];
                        app.PhaseMinLabel.Text = ['-',num2str(scale,2),'π'];
                    end

                    app.newPhaseFigFlag = false;

                else

                    % Only new data in the figure = fast
                    set(app.phaseRecoImage, 'CData', rot90(im));
                    set(app.phaseRecoImage, 'CDataMapping', 'scaled');
                    pause(0.002);

                end

                drawnow;

            end

        end % PlotPhaseImageFigFcn

    end


    % --------------------------------------------------------------
    %
    %                       PUBLIC FUNCTIONS
    %
    % --------------------------------------------------------------

    methods (Access = public)


        % Text messages in the app
        function TextMessage(app, message, varargin)

            % Purpose:
            %   Report a message to the user and to the log file.
            %
            % How it works:
            %   Normalises the input to a single string and appends the trailing ellipsis
            %   the app uses throughout, so callers do not have to. The message always goes
            %   to the log file, and reaches the message box only when its level is at or
            %   below the message level spinner, which is how the spinner controls how much
            %   detail is shown. Writing to the log is guarded so that a missing log file
            %   does not stop the app.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %   message - text to report; a string array is joined with spaces
            %   varargin - optional message level, 1 by default; higher numbers are
            %              shown only at higher spinner settings
            %
            % Output:
            %   none, the message box and the log file are updated.

            % Ensure input is a single string
            if ~isstring(message)
                message = string(message);
            end
            message = strjoin(message, " ");

            % Append "..." if not already present
            if ~endsWith(message, "...")
                message = message + " ...";
            end

            % Write all messages to log
            try
                fprintf(app.logFile, "%s \n", message);
            catch
            end

            % Remove line breaks
            message = strrep(message, newline, "");

            % Write message to message box
            try

                if ~app.blockMessagesFlag

                    % If no message level input, message level = 1
                    debugLevel = 1;
                    if nargin > 2
                        debugLevel = varargin{1};
                    end

                    if debugLevel <= app.MessageLevelSpinner.Value

                        % Skip if identical to the previous message
                        currentMessages = app.MessageBox.Value;
                        if ~isempty(currentMessages) && strcmp(currentMessages{1}, message)
                            return;
                        end

                        % Add new text
                        newText = [message ; currentMessages];

                        % Limit the history of the messages in the message box to 100 lines
                        if size(newText, 1) > 200
                            newText = newText(1:200, 1);
                        end

                        % Write the message in the app message window
                        app.MessageBox.Value = newText;
                        drawnow;

                    end

                end

            catch
            end

        end % TextMessage


        % Shut the app down
        function CloseAppFcn(app)

            % Purpose:
            %   Shut the app down, whether the exit button or the window's close box was
            %   used, also while the app is still busy, and leave the MATLAB session as it
            %   was found.
            %
            % How it works:
            %   While app code is still running -- the startup, a step or a loop -- deleting
            %   the app would make that code fail on a deleted object. The close is then
            %   deferred: the window is hidden, what runs is asked to stop, and a timer
            %   finishes the close once no app code runs any more (DeferredCloseFcn).
            %
            %   Otherwise the log file is closed, the app folders are removed from the
            %   search path and MATLAB returns to its starting directory, so that a later
            %   session does not inherit them, and the warning state MATLAB had is put back.
            %   The path is left alone when running deployed, where it is not the app's to
            %   change. Figures other than the app's own are left open.
            %
            %   The figure is deleted before the app. Deleting only the app from the close
            %   box left the figure registered as the running app, and the app could then
            %   not be started again. Both go before the app's folders leave the search
            %   path: deleting the figure runs the app's destructor, which MATLAB cannot
            %   find once they are gone. What the restoring needs is kept first.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none - the app is closed, or its close is scheduled

            % Still busy: stop the reconstruction, calibration and movies, and close once
            % they have
            if app.AppCodeRunningFcn

                if ~app.closeRequested
                    app.closeRequested = true;
                    app.stopRecoFlag = true;
                    app.stopGradCalFlag = true;
                    app.stopMovieFlag = true;
                    app.stopPhaseMovieFlag = true;
                    app.stopKspaceMovieFlag = true;
                    app.stopMIPxMovieFlag = true;
                    app.stopMIPyMovieFlag = true;
                    app.TextMessage('Closing when the current step has finished ...',1);
                    app.P2ROUD_UIFigure.Visible = 'off';
                    app.closeTimer = timer('Name', 'P2ROUDCloseTimer', ...
                        'ExecutionMode', 'fixedSpacing', 'Period', 0.5, 'StartDelay', 0.5, ...
                        'TasksToExecute', 7200, ...
                        'TimerFcn', @(t,~) isvalid(app) && app.DeferredCloseFcn(t));
                    start(app.closeTimer);
                end

                return

            end

            app.TextMessage('Closing P2ROUD ...',1);

            % Close the log file
            try
                if ~isempty(app.logFile) && app.logFile > 2
                    fclose(app.logFile);
                end
                app.logFile = -1;
            catch
            end

            % Keep what the restoring needs; the app is deleted before it is used
            appRoot = app.appDir;
            startFolder = app.oldDir;
            startWarnings = app.oldWarningState;

            % Delete the figure, then the app, while the app folders are still on the
            % search path: deleting the figure runs the app's destructor
            try
                delete(app.P2ROUD_UIFigure);
            catch
            end
            try
                delete(app);
            catch
            end

            % Remove the app folders from the search path, and return to the folder MATLAB
            % was in
            if ~isdeployed
                try
                    rmpath(genpath(appRoot));
                    cd(startFolder);
                catch
                end
            end

            % Restore the warning state MATLAB had
            try
                if ~isempty(startWarnings)
                    warning(startWarnings);
                end
            catch
            end

        end % CloseAppFcn

        % Finish a close that had to wait
        function done = DeferredCloseFcn(app, t)

            % Purpose:
            %   Complete a close that was requested while app code was still running.
            %
            % How it works:
            %   The close timer calls this twice a second. While app code still runs
            %   nothing else happens. Once it has stopped, one more tick is waited, so
            %   that the callback that ended the step has returned as well, and the app is
            %   then closed the normal way.
            %
            % Inputs:
            %   app - the app being closed
            %   t   - the close timer
            %
            % Output:
            %   done - always true, since the timer's function handle needs a value

            done = true;

            try

                % Keep the window hidden; a dialog that returned may have raised it
                app.P2ROUD_UIFigure.Visible = 'off';

                % Still running: wait for the next tick
                if app.AppCodeRunningFcn
                    t.UserData = 0;
                    return
                end

                % Idle for the first time: wait one more tick
                if isempty(t.UserData) || t.UserData < 1
                    t.UserData = 1;
                    return
                end

                % Idle twice in a row: close
                stop(t);
                delete(t);
                app.closeTimer = [];
                app.CloseAppFcn;

            catch

                % A timer that fails is removed rather than left firing
                try
                    stop(t);
                    delete(t);
                catch
                end

            end

        end % DeferredCloseFcn


        % Check whether app code is running
        function running = AppCodeRunningFcn(app)

            % Purpose:
            %   Tell whether closing the app now would break code that is still running.
            %
            % How it works:
            %   Any method of the app still on the call stack -- a callback, the startup,
            %   a loop -- is running underneath the close request, or underneath the timer
            %   that asks. The closing methods themselves do not count, and neither do
            %   anonymous functions, whose names carry the method that created them.
            %
            %   Where the stack cannot be read, the BUSY status that every step sets is
            %   used instead.
            %
            % Inputs:
            %   app - the running app
            %
            % Output:
            %   running - true while app code other than the closing methods runs

            running = app.busyFlag;

            % The methods that do the closing
            closing = ["CloseAppFcn", "DeferredCloseFcn", "AppCodeRunningFcn", ...
                "ExitButtonPushed", "UIFigureCloseRequest"];

            % The app's own methods on the call stack
            try
                names = string({dbstack.name});
                mine = startsWith(names, "P2ROUD.");
                if any(mine)
                    running = any(mine & ~contains(names, "@(") & ~contains(names, closing));
                end
            catch
            end

        end % AppCodeRunningFcn


        % Status icon
        function SetStatus(app,status)

            % Purpose:
            %   Show the overall status of the app as an icon and a word.
            %
            % How it works:
            %   Four states: 0 is OK in green, 1 a warning in orange, 2 an error in red and
            %   3 busy. Each sets its own icon, colour and text, so the status is readable
            %   at a glance without reading the message box.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %   status - 0 ok, 1 warning, 2 error, 3 busy
            %
            % Output:
            %   none, the status icon and label change.

            % BUSY marks a running step until a final status ends it; a WARNING leaves
            % the flag as it was. A close request made meanwhile waits for the step
            if status == 3
                app.busyFlag = true;
            elseif ismember(status, [0 2])
                app.busyFlag = false;
            end

            switch status

                case 0
                    % app.StatusImage.Position = [25,218,30,30];
                    app.StatusImage.ImageSource = 'statusokay.gif';
                    app.StatusLabel.FontSize = 14;
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontColor = app.green;
                    app.StatusLabel.Text = 'OK';

                case 1
                    % app.StatusImage.Position = [25,218,30,30];
                    app.StatusImage.ImageSource = 'statuswarning.gif';
                    app.StatusLabel.FontSize = 11;
                    app.StatusLabel.FontWeight = 'bold';
                    if app.AutoSoundsCheckBox.Value
                        sound(app.warningSound,48000);
                    end
                    app.StatusLabel.FontColor = app.orange;
                    app.StatusLabel.Text = 'WARNING';

                case 2
                    % app.StatusImage.Position = [25,218,30,30];
                    app.StatusImage.ImageSource = 'statuserror.gif';
                    app.StatusLabel.FontSize = 12;
                    app.StatusLabel.FontWeight = 'bold';
                    if app.AutoSoundsCheckBox.Value
                        sound(app.errorSound,48000);
                    end
                    app.StatusLabel.FontColor = app.brightred;
                    app.StatusLabel.Text = 'ERROR';

                case 3
                    % app.StatusImage.Position = [25,218,30,30];
                    app.StatusImage.ImageSource = 'statusbusy.gif';
                    app.StatusLabel.FontSize = 12;
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontColor = app.blue;
                    app.StatusLabel.Text = 'BUSY';

            end

            drawnow;

        end

    end


    % --------------------------------------------------------------
    %
    %                   USER INTERFACE CODE
    %
    % --------------------------------------------------------------


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Purpose:
            %   Bring the app up: search path, log file, settings, layout and the
            %   optional tools it can use.
            %
            % How it works:
            %   Runs in the order the later steps depend on. The functions and gui
            %   folders go on the search path first, because everything after this
            %   needs them, and only when not deployed, where the path is not the
            %   app's to change. The log file is opened next so that the remaining
            %   steps have somewhere to report to, then the settings file is read,
            %   then the layout and colours are applied.
            %
            %   Optional tools are detected rather than required. BART is looked for
            %   on the platform's usual paths and its absence only disables the
            %   reconstructions that need it. The gradient delays file is read when
            %   present, in either the seven-value format with cross-terms or the
            %   older four-value one. A parallel pool is started, capped at eight
            %   workers. Each of these is guarded, so a missing tool leaves the app
            %   running with less rather than failing to start.
            %
            %   The buttons finish in the state that allows loading a file and
            %   nothing else, since there is no data yet.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the app is initialised and ready to load data.

            % The window is already showing, so closing it during the startup waits for
            % the startup to finish, as it does during any other step
            app.busyFlag = true;
            app.P2ROUD_UIFigure.CloseRequestFcn = @(~,~) app.CloseAppFcn;

            % Suppress unneccesary warnings
            app.oldWarningState = warning;   % put back when the app closes
            warning off;
            clc;

            % Set app theme to light
            theme(app.P2ROUD_UIFigure,"light")

            % Add subfolders to search path
            if (~isdeployed)
                app.oldDir = pwd;
                info = dir(which('P2ROUD.mlapp'));
                app.appDir = info.folder;
                cd(app.appDir);
                addpath(genpath(strcat(pwd,filesep,'functions')));
                addpath(genpath(strcat(pwd,filesep,'gui')));
            end

            % Open new log file
            try
                app.TextMessage("Creating log file ...",1);
                logFileName = proud.io.makeLogFile(app.appVersion);
                app.logFile = fopen(logFileName,'w');
                app.TextMessage(strcat("log file = ",logFileName," ..."),3);
            catch ME
                app.TextMessage("Could not create log file ...",1);
                app.TextMessage(ME.message,2);
            end

            % Create empty objects of classes
            app.pd = proudData(AppReporter(app));

            % Read the JSON parameters
            try
                app.TextMessage("Reading JSON parameter file ...",1);
                app.jsonPar = readSettingsFile(app.appVersion, AppReporter(app));
            catch ME
                app.TextMessage(ME.message,2);
            end

            % Set the app layout
            app.SetAppLayoutFcn;
            app.ColorsButtonGroupSelectionChanged;
            app.SetStatus(3);
            app.SwitchOffAllButtonsFcn;
            app.DisableLabelsFcn;
            app.CreditsLabel.Text = strcat("Gustav Strijkers, Bram Coolen, Amsterdam UMC, Version ",app.appVersion);

            % Empty file import and export paths
            app.mrdImportPath = '';
            app.mrdFlag = true;
            app.dicomExportPath = '';

            % Reset flags
            app.dicomFlag = true;
            app.stopMovieFlag = true;
            app.stopPhaseMovieFlag = true;
            app.stopMIPxMovieFlag = true;
            app.stopMIPyMovieFlag = true;
            app.newFigFlag = true;
            app.newPhaseFigFlag = true;
            app.forceProudFlag = false;
            app.scrollEnableFlag = false;
            app.bartDetectedFlag = false;

            % Add the bart path to the environment variable if available
            try
                if ismac()
                    [~,result] = system('uname -v');
                    is_m1_mac = any(strfind(result,'ARM64'));
                    if is_m1_mac
                        app.TextMessage('Apple arm64 version ...',1);
                    else
                        app.TextMessage('Apple Intel version ...',1);
                    end
                else
                    app.TextMessage('PC Intel version ...',1);
                end
            catch
            end

            % Determine if BART is available by requesting its version number
            try
                app.bartVersion = 'not found';
                app.bartVersion = bart(AppReporter(app),'version');
                if contains(app.bartVersion,'v')
                    app.bartDetectedFlag = true;
                end
            catch ME
                app.bartDetectedFlag = false;
                app.TextMessage(ME.message,2);
            end

            % Report whether BART is available or not
            if app.bartDetectedFlag
                app.TextMessage('BART toolbox detected ...',1);
            else
                app.TextMessage('WARNING: BART toolbox not available ...',1);
                app.SetStatus(1);
            end

            % Check if Bart was compiled with GPU support
            app.GPUpresentFlag = false;

            if app.bartDetectedFlag
                try
                    app.blockMessagesFlag = true;
                    rec = bart(AppReporter(app),'pics -l2 -g ',[0 1 0 ; 1 1 1 ; 0 1 0],[1 1 1 ; 1 1 1 ; 1 1 1]); %#ok<NASGU>
                    app.blockMessagesFlag = false;
                    app.TextMessage('BART compiled with GPU support ...',1);
                    app.GPUpresentFlag = true;
                catch
                    app.blockMessagesFlag = false;
                    app.TextMessage('BART compiled without GPU support ...',1);
                end
            end

            % Load gradient delays file
            try
                delays = load('graddelays.txt');
                app.GxDelayEditField.Value = delays(1);
                app.GyDelayEditField.Value = delays(2);
                app.GzDelayEditField.Value = delays(3);

                if numel(delays) >= 7
                    % [Gx, Gy, Gz, Sxy, Sxz, Syz, data offset]
                    app.SxyDelayEditField.Value = delays(4);
                    app.SxzDelayEditField.Value = delays(5);
                    app.SyzDelayEditField.Value = delays(6);
                    app.DataOffsetRadialEditField.Value = delays(7);
                else
                    % [Gx, Gy, Gz, data offset], the older format without cross-terms
                    app.SxyDelayEditField.Value = 0;
                    app.SxzDelayEditField.Value = 0;
                    app.SyzDelayEditField.Value = 0;
                    app.DataOffsetRadialEditField.Value = delays(4);
                end

                app.TextMessage('Gradient delays file found ...',1);
            catch ME
                app.TextMessage(ME.message,2);
                app.TextMessage('Gradient delays file not found or invalid gradient file ...',1);
            end

            % Determine the current directory in case of deployed app
            if isdeployed
                try
                    [~,result] = system('path');
                    app.currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                    app.TextMessage(strcat("Path: ",app.currentDir," ..."),2);
                catch
                    app.currentDir = '';
                end
            end

            % Check app version
            app.CheckVersionFnc;

             % Start the parallel pool if it is not running already
            try
                numCores = feature('numcores');
                p = gcp('nocreate');
                if isempty(p)
                    app.TextMessage("Starting parallel pool ...",1);
                    if numCores >= 8
                        parpool(8);
                    else
                        parpool(numCores);
                    end
                end
            catch ME
                app.TextMessage("Error starting the parallel pool ...",1);
                app.TextMessage(ME.message,2);
            end

            % Initialize buttons
            app.SetLightsFcn({'Red','Red','Red','Red','Red','Red'});
            app.SetButtonsFcn({'On','Off','Off','Off','Off','Off','On'});
            app.SetRecoFieldsFcn;
            app.DisableLabelsFcn;
            app.SetStatus(0);
            app.TextMessage("Ready ...");

        end

        % Selection changed function: ColorsButtonGroup
        function ColorsButtonGroupSelectionChanged(app, event)

            % Purpose:
            %   Switch the app between the classic and the black and white colour scheme.
            %
            % How it works:
            %   Picks the eight colours for the selected scheme and hands them to
            %   SetAppColorsFcn, which applies them to every component.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the app colours change.

            selectedButton = app.ColorsButtonGroup.SelectedObject;

            switch lower(selectedButton.Text)

                % COLORS:           backgroundColor, fontColor, boxColor, gaugeColor, gaugeBackgroundColor, figureColor, movieFigureColor, gridColor

                case "classic"

                    guiColors = {app.guiGrey, app.guiBlack, app.guiDarkGrey, app.blue, app.guiWhite, app.guiWhite, app.guiMiddleGrey, app.guiDarkGrey};

                case "black/white"

                    guiColors = {app.guiBlack, app.guiWhite, app.guiDarkGrey, app.blue, app.guiDarkGrey, app.guiGrey, app.guiDarkGrey, app.guiDarkGrey};

            end

            app.SetAppColorsFcn(guiColors);

        end

        % Button pushed function: LoadMRDButton
        function LoadMRDButtonPushed(app, event)

            % Purpose:
            %   Load an MR Solutions MRD data set and set the app up for it.
            %
            % How it works:
            %   Starts from a fresh proudData object, so nothing carries over from the
            %   previous scan. The import folder is remembered once per session, taken from
            %   the scanner's registry entry on Windows or from mrdpath.txt when that exists.
            %   Multi-coil acquisitions are written as several files sharing a name stem, so
            %   the stem is used to find the whole set and the coil count comes from that.
            %   The MRD data, its footer, the RPR file and the SQL file are then read in that
            %   order. Gradient delays are reset to zero because a calibration belongs to the
            %   scan it was measured on. A valid file leaves the k-space tab plotted and the
            %   reconstruction buttons armed; an invalid one reports and leaves them off.
            %   BART is asked once whether it supports total generalized variation, which
            %   decides the regularisation the reconstructions request later.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, pd holds the loaded data and the app is set up for it.

            % Set buttons
            app.SwitchOffAllButtonsFcn;
            app.DisableLabelsFcn;
            app.SetLightsFcn({'Yellow','Red','Red','Red','Red','Red'});
            app.SetStatus(0);

            % Reset object of class proudData
            app.pd = proudData(AppReporter(app));

            % Check standard smis directory first if platform is pc, only 1x per session
            if ispc

                try
                    folderName = winqueryreg('HKEY_LOCAL_MACHINE','Software\MRS\MRSClinicalUI\Scan','MrdDirectory');
                    folderName = [folderName,filesep];
                catch ME
                    folderName = 'c:\smis\dev\MRD\';
                    app.TextMessage(ME.message,2);
                end

                if exist(folderName, 'dir') && app.mrdFlag
                    app.mrdImportPath = folderName;
                    app.mrdFlag = false;
                end

            end

            % Check mrdpath.txt file next, only 1x per session
            if isfile('mrdpath.txt') && app.mrdFlag

                try
                    folderName = fileread('mrdpath.txt');
                    if exist(folderName, 'dir')
                        app.mrdImportPath = folderName;
                        app.mrdFlag = false;
                    end
                catch ME
                    app.TextMessage(ME.message,2);
                    app.mrdImportPath = '';
                    app.mrdFlag = false;
                end

            end

            % Bart total generalized variation (G) or total variation (T)
            app.pd.totalVariation = 'T';
            if app.bartDetectedFlag
                try
                    picsHelp = bart(AppReporter(app),'pics -Rh');
                    if contains(picsHelp,"total generalized variation")
                        app.pd.totalVariation = 'G';
                    end
                catch
                end
            end

            % Select 1 MRD file
            f = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetfile in front
            [mrdFile,app.mrdImportPath] = uigetfile(strcat(app.mrdImportPath,filesep,'*.mrd'),'Select MR Solutions data file ...');
            delete(f);
            if ~app.closeRequested
                figure(app.P2ROUD_UIFigure);  % Put the app back in the foreground
            end

            if ischar(mrdFile)

                % Indicate busy
                app.SetStatus(3);

                try

                        % MRD file list, check for multiple file names with the same name = multicoil
                        app.MRDfileViewField.Value = mrdFile;
                        k = strfind(mrdFile,'_');
                        if length(k)>1
                            mrdStart = extractBefore(mrdFile,k(end-1));
                        else
                            mrdStart = extractBefore(mrdFile,k(end));
                        end
                        mrdFile = [app.mrdImportPath,mrdFile];
                        fList = dir(fullfile(app.mrdImportPath,[mrdStart,'*.MRD']));

                        % Set the number of coils
                        app.pd = app.pd.setNumberOfCoils(gatherRecoParams(app),fList);
                        app.ResetCoilButtonsFcn;

                        % Load the data
                        app.TextMessage('Reading MRD file(s) ...',1);
                        app.pd = app.pd.readProudData(gatherRecoParams(app), mrdFile, fList);

                        % Filename tag
                        [~,app.tag,~] = fileparts([fList(1).folder, filesep, fList(1).name]);

                        % Read the full mrd-file footer
                        app.pd = app.pd.readMrdFooter(gatherRecoParams(app), fullfile(fList(1).folder, fList(1).name));

                        % Read the rpr file
                        app.pd = app.pd.readRprFile(gatherRecoParams(app), fList(1).folder);

                        % Read the SQL file if present
                        sqlFilename = [];
                        sqlFilenameList = dir(fullfile([fList(1).folder, filesep, '*.SQL']));
                        if ~isempty(sqlFilenameList)
                            sqlFilename = [sqlFilenameList(1).folder, filesep, sqlFilenameList(1).name];
                        end
                        app.pd = app.pd.readSQLfile(gatherRecoParams(app), sqlFilename);

                catch ME

                    app.TextMessage('ERROR: during file loading ...',1);
                    app.TextMessage(ME.message,2);
                    app.SetStatus(2);

                end

            end

            % Report on valid/invalid file selection
            if app.pd.validFileFlag

                % Set other parameters
                try

                    % A new data set starts from zero gradient delays: a calibration
                    % belongs to the scan it was measured on, and carrying it over to
                    % the next one silently is not what anybody means to do
                    app.GxDelayEditField.Value = 0;
                    app.GyDelayEditField.Value = 0;
                    app.GzDelayEditField.Value = 0;
                    app.SxyDelayEditField.Value = 0;
                    app.SxzDelayEditField.Value = 0;
                    app.SyzDelayEditField.Value = 0;

                    app.SetParametersFcn;
                    app.SetKspaceLimitsFcn;
                    app.FillKspaceFcn;
                    app.PlotKspaceFigFcn;

                    app.IncludeNeEditFieldValueChanged;
                    app.IncludeNrEditFieldValueChanged;

                    app.TextMessage('Data import ready ...',1);
                    app.TextMessage(strcat("Size of k-space = ",num2str(size(app.pd.rawKspace{1}))," ..."),1);

                    % Set buttons
                    app.SetLightsFcn({'Green','Red','Red','Red','Red','Red'});
                    app.SetButtonsFcn({'On','On','Null','Null','Null','Null','On'});
                    app.EnableLabelsFcn;
                    app.DisableImageButtonsFcn;
                    app.KspaceTabGroup.SelectedTab = app.KspaceTab;
                    app.kValuesButton.Value = 1;
                    app.SetStatus(0);

                catch ME

                    app.TextMessage("ERROR: during initializing scan parameters ...",1);
                    app.TextMessage(ME.message,2);
                    app.pd.validFileFlag = false;

                    % Set the buttons for invalid data file
                    app.SetLightsFcn({'Red','Red','Red','Red','Red','Red'});
                    app.SetButtonsFcn({'On','Off','Off','Off','Off','Off','On'});
                    app.EnableLabelsFcn;
                    app.DisableImageButtonsFcn;
                    app.KspaceTabGroup.SelectedTab = app.KspaceTab;
                    app.kValuesButton.Value = 1;
                    app.SetStatus(2);

                end

            else

                % Not a valid data file
                app.TextMessage('ERROR: Please select a valid data file ...',1);

                % Set the buttons for invalid data file
                app.SetLightsFcn({'Red','Red','Red','Red','Red','Red'});
                app.SetButtonsFcn({'On','Off','Off','Off','Off','Off','On'});
                app.EnableLabelsFcn;
                app.DisableImageButtonsFcn;
                app.KspaceTabGroup.SelectedTab = app.KspaceTab;
                app.kValuesButton.Value = 1;
                app.SetStatus(2);

            end

        end

        % Value changed function: KFlipAngleEditField
        function KFlipAngleEditFieldValueChanged(app, event)

            % Purpose:
            %   Show the k-space of the flip angle the user typed.
            %
            % How it works:
            %   Copies the value to the matching slider and replots. Ignored while the
            %   k-space movie is running, so the movie keeps control of the display.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.stopKspaceMovieFlag
                app.KFlipAngleSlider.Value = app.KFlipAngleEditField.Value;
                app.PlotKspaceFigFcn;
            end

        end

        % Value changed function: KFlipAngleSlider
        function KFlipAngleSliderValueChanged(app, event)

            % Purpose:
            %   Show the k-space of the flip angle the user dragged to.
            %
            % How it works:
            %   Rounds the slider to a whole index, copies it to the edit field and
            %   replots. Ignored while the k-space movie is running.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.stopKspaceMovieFlag
                app.KFlipAngleEditField.Value = round(app.KFlipAngleSlider.Value);
                app.PlotKspaceFigFcn;
            end

        end

        % Value changed function: KDim4EditField
        function KDim4EditFieldValueChanged(app, event)

            % Purpose:
            %   Show the k-space of the fourth dimension the user typed.
            %
            % How it works:
            %   Copies the value to the matching slider and replots. Ignored while the
            %   k-space movie is running, so the movie keeps control of the display.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.stopKspaceMovieFlag
                app.KDim4Slider.Value = app.KDim4EditField.Value;
                app.PlotKspaceFigFcn;
            end

        end

        % Value changed function: KDim4Slider
        function KDim4SliderValueChanged(app, event)

            % Purpose:
            %   Show the k-space of the fourth dimension the user dragged to.
            %
            % How it works:
            %   Rounds the slider to a whole index, copies it to the edit field and
            %   replots. Ignored while the k-space movie is running.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.stopKspaceMovieFlag
                app.KDim4EditField.Value = round(app.KDim4Slider.Value);
                app.PlotKspaceFigFcn;
            end

        end

        % Value changed function: KEchoEditField
        function KEchoEditFieldValueChanged(app, event)

            % Purpose:
            %   Show the k-space of the echo the user typed.
            %
            % How it works:
            %   Copies the value to the matching slider and replots. Ignored while the
            %   k-space movie is running, so the movie keeps control of the display.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.stopKspaceMovieFlag
                app.KEchoSlider.Value = app.KEchoEditField.Value;
                app.PlotKspaceFigFcn;
            end

        end

        % Value changed function: KEchoSlider
        function KEchoSliderValueChanged(app, event)

            % Purpose:
            %   Show the k-space of the echo the user dragged to.
            %
            % How it works:
            %   Rounds the slider to a whole index, copies it to the edit field and
            %   replots. Ignored while the k-space movie is running.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.stopKspaceMovieFlag
                app.KEchoEditField.Value = round(app.KEchoSlider.Value);
                app.PlotKspaceFigFcn;
            end

        end

        % Value changed function: KDim3EditField
        function KDim3EditFieldValueChanged(app, event)

            % Purpose:
            %   Show the k-space at the third-dimension index the user chose.
            %
            % How it works:
            %   The third dimension has two slider and edit field pairs, one on each tab.
            %   All four controls are set to the new value so the tabs cannot disagree,
            %   then the figure is replotted. Ignored while the k-space movie is running.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.stopKspaceMovieFlag
                app.KDim3EditFieldS.Value = app.KDim3EditField.Value;
                app.KDim3Slider.Value = app.KDim3EditField.Value;
                app.KDim3SliderS.Value = app.KDim3EditField.Value;
                app.PlotKspaceFigFcn;
            end

        end

        % Value changed function: KDim3EditFieldS
        function KDim3EditFieldSValueChanged(app, event)

            % Purpose:
            %   Show the k-space at the third-dimension index the user chose.
            %
            % How it works:
            %   The third dimension has two slider and edit field pairs, one on each tab.
            %   All four controls are set to the new value so the tabs cannot disagree,
            %   then the figure is replotted. Ignored while the k-space movie is running.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.stopKspaceMovieFlag
                app.KDim3EditField.Value = app.KDim3EditFieldS.Value;
                app.KDim3Slider.Value = app.KDim3EditFieldS.Value;
                app.KDim3SliderS.Value = app.KDim3EditFieldS.Value;
                app.PlotKspaceFigFcn;
            end

        end

        % Value changed function: KDim3Slider
        function KDim3SliderValueChanged(app, event)

            % Purpose:
            %   Show the k-space at the third-dimension index the user chose.
            %
            % How it works:
            %   The third dimension has two slider and edit field pairs, one on each tab.
            %   All four controls are set to the new value so the tabs cannot disagree,
            %   then the figure is replotted. Ignored while the k-space movie is running.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.stopKspaceMovieFlag
                app.KDim3SliderS.Value = app.KDim3Slider.Value;
                app.KDim3EditField.Value = round(app.KDim3Slider.Value);
                app.KDim3EditFieldS.Value = round(app.KDim3Slider.Value);
                app.PlotKspaceFigFcn;
            end

        end

        % Value changed function: KDim3SliderS
        function KDim3SliderSValueChanged(app, event)

            % Purpose:
            %   Show the k-space at the third-dimension index the user chose.
            %
            % How it works:
            %   The third dimension has two slider and edit field pairs, one on each tab.
            %   All four controls are set to the new value so the tabs cannot disagree,
            %   then the figure is replotted. Ignored while the k-space movie is running.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.stopKspaceMovieFlag
                app.KDim3Slider.Value = app.KDim3SliderS.Value;
                app.KDim3EditField.Value = round(app.KDim3SliderS.Value);
                app.KDim3EditFieldS.Value = round(app.KDim3SliderS.Value);
                app.PlotKspaceFigFcn;
            end

        end

        % Value changed function: KOrientationSpinner
        function KOrientationSpinnerValueChanged(app, event)

            % Purpose:
            %   Show k-space along a different axis.
            %
            % How it works:
            %   Changing the orientation changes which array dimensions are on screen, so the
            %   axis limits and the window and level are recomputed before replotting.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.stopKspaceMovieFlag
                app.SetKspaceLimitsFcn;
                app.SetKspaceWLFcn;
                app.PlotKspaceFigFcn;
            end

        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)

            % Purpose:
            %   Switch the k-space display between magnitude, phase, real and imaginary.
            %
            % How it works:
            %   The selection changes the range of values on display, so the limits and the
            %   window and level are recomputed before replotting.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.stopKspaceMovieFlag
                app.SetKspaceLimitsFcn;
                app.SetKspaceWLFcn;
                app.PlotKspaceFigFcn;
            end

        end

        % Value changed function: CoilSpinner
        function CoilSpinnerValueChanged(app, event)

            % Purpose:
            %   Show the k-space of a different coil.
            %
            % How it works:
            %   Coils differ in signal level, so the window and level are recomputed for the
            %   new coil before replotting.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.stopKspaceMovieFlag
                app.SetKspaceWLFcn;
                app.PlotKspaceFigFcn;
            end

        end

        % Value changed function: C1CheckBox, C2CheckBox, C3CheckBox, 
        % ...and 5 other components
        function CoilCheckBoxValueChanged(app, event)

            % Purpose:
            %   Choose which coils take part in the reconstruction.
            %
            % How it works:
            %   Collects the eight check boxes into pd.coilActiveFlag. At least one coil has
            %   to stay on, so coil 1 is switched back on when the user clears them all. A
            %   single coil gives nothing to estimate sensitivities from, so automatic
            %   sensitivity is switched off as well.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, pd.coilActiveFlag is updated.

            app.pd.coilActiveFlag = [app.C1CheckBox.Value app.C2CheckBox.Value app.C3CheckBox.Value app.C4CheckBox.Value ...
                app.C5CheckBox.Value app.C6CheckBox.Value app.C7CheckBox.Value app.C8CheckBox.Value];

            if nnz(app.pd.coilActiveFlag(1:app.pd.nrCoils)) == 0
                app.C1CheckBox.Value = 1;
                app.pd.coilActiveFlag(1) = 1;
            end

            if nnz(app.pd.coilActiveFlag(1:app.pd.nrCoils)) == 1
                app.AutoSensitivityCheckBox.Value = 0;
                app.AutoSensitivityCheckBoxValueChanged;
            end

        end

        % Value changed function: S1EditField, S2EditField, S3EditField, 
        % ...and 5 other components
        function SensitivityEditFieldValueChanged(app, event)

            % Purpose:
            %   Take the manually entered coil sensitivities.
            %
            % How it works:
            %   Collects the eight fields into pd.coilSensitivities in coil order.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, pd.coilSensitivities is updated.

            app.pd.coilSensitivities = [app.S1EditField.Value app.S2EditField.Value app.S3EditField.Value app.S4EditField.Value ...
                app.S5EditField.Value app.S6EditField.Value app.S7EditField.Value app.S8EditField.Value];

        end

        % Value changed function: AutoSensitivityCheckBox
        function AutoSensitivityCheckBoxValueChanged(app, event)

            % Purpose:
            %   Turn automatic coil sensitivity estimation on or off.
            %
            % How it works:
            %   Estimation needs more than one coil, so the box is forced off when only one
            %   coil is active. The coil buttons are then re-enabled to match.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the coil controls are updated.

            if nnz(app.pd.coilActiveFlag) == 1
                app.AutoSensitivityCheckBox.Value = 0;
            end

            app.EnableCoilButtonsFcn;

        end

        % Button pushed function: AbortGradCalButton
        function AbortGradCalButtonPushed(app, event)

            % Purpose:
            %   Ask the running gradient delay calibration to stop.
            %
            % How it works:
            %   Sets the flag the calibration polls between iterations.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the calibration ends early.

            app.stopGradCalFlag = true;
            drawnow;

        end

        % Value changed function: ZTEDeadTimeEditField, 
        % ...and 2 other components
        function ZTEDeadTimeEditFieldValueChanged(app, event)

            % Purpose:
            %   Take a new ZTE dead time, shift or full-echo setting.
            %
            % How it works:
            %   Copies the three ZTE controls into pd. For ZTE data the k-space depends on
            %   them, so the parameters, the limits, the k-space filling and the plot are all
            %   redone; other data types only get the buttons reset.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, pd and the k-space view are updated.

            app.stopMovieFlag = true;
            drawnow;

            % Set buttons
            app.SetLightsFcn({'Null','Red','Red','Red','Red','Red'});
            app.SetButtonsFcn({'On','On','Off','Off','Off','Off','On'});
            app.RecoProgressGauge.Value = 0;
            app.ExportProgressGauge.Value = 0;
            drawnow;

            app.pd.deadTimeZTE = app.ZTEDeadTimeEditField.Value;
            app.pd.shiftZTE = app.ZTEShiftEditField.Value;
            app.pd.fullEchoZTE = app.ZTEFullEchoCheckBox.Value;

            if app.pd.validFileFlag && app.pd.dataType == "ZTE"
                app.SetParametersFcn;
                app.SetKspaceLimitsFcn;
                app.FillKspaceFcn;
                app.PlotKspaceFigFcn;
            end

        end

        % Button pushed function: KStartButton
        function KStartButtonPushed(app, event)

            % Purpose:
            %   Play the k-space trajectory as an animation, point by point.
            %
            % How it works:
            %   Draws the acquired trajectory into a scatter plot, adding points a step at a
            %   time so the order of sampling is visible. The step is scaled to about 256
            %   frames, so the animation takes a similar time whatever the trajectory length,
            %   and the last frame draws up to 65536 points at once. The Fast/Slow switch
            %   chooses between drawnow limitrate and a paused redraw. Data with no stored
            %   trajectory is reported and skipped.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the k-space figure is animated.

            if app.pd.validTrajectoryFlag

                % Indicate busy
                app.SetStatus(3);
                app.stopKspaceMovieFlag = false;
                app.DisableKbuttonsFcn;
                app.KStopButton.Enable = 'on';
                app.KStartButton.Enable = 'off';

                % Set the image dimensions
                im = abs(app.pd.rawKspace{app.CoilSpinner.Value});
                app.KspaceFig.Position = [34,87,325,325];
                im = permute(im,app.imageOrient(app.KOrientationSpinner.Value,:));
                im = squeeze(im(:,:,app.KDim3EditField.Value,app.KDim4EditField.Value,app.KFlipAngleEditField.Value,app.KEchoEditField.Value));
                recoAspectRatio = app.CalcKAspectRatioFcn(app.KxLabel.Text,app.KyLabel.Text,size(im,1),size(im,2));
                daspect(app.KspaceFig,[1 recoAspectRatio 1]);
                sizex = size(im,1);
                sizey = size(im,2);

                hold(app.KspaceFig,'on');
                delete(app.kmovieFig);

                % Get plot size in pixel units
                originalUnits = app.KspaceFig.Units;
                axh.Units = 'Points';
                axSizePix = app.KspaceFig.Position(3:4);
                axh.Units = originalUnits; %#ok<STRNU>
                pixPerUnit = axSizePix ./ [range(1:sizex),range(1:sizey)];  %pixels per unit

                % Initialize scatter plot
                app.kmovieFig = scatter(app.KspaceFig,1,1,ceil(median(pixPerUnit))^2,"yellow",'filled','s','MarkerFaceAlpha',0.5);
                xlim(app.KspaceFig,[0, sizex+1]);
                ylim(app.KspaceFig,[0, sizey+1]);
                hold(app.KspaceFig,'off');

                % K-space list that will be displayed
                pnts = app.pd.seqTrajectory(:,app.imageOrient(app.KOrientationSpinner.Value,:));
                pnts = pnts(pnts(:,3) == app.KDim3EditField.Value,:);
                pnts = pnts(pnts(:,4) == app.KDim4EditField.Value,:);

                % Accelerate viewing by stepping faster through the list
                stepSize = round(length(pnts)/256);
                stepSize(stepSize < 1) = 1;

                try

                    % Loop through the list and plot the k-space points
                    for cnt = 1:stepSize:length(pnts)

                        app.kmovieFig.XData = pnts(1:cnt,1);
                        app.kmovieFig.YData = sizey + 1 - pnts(1:cnt,2);

                        if app.FastSlowSwitch.Value == "slow"
                            drawnow;
                            pause(0.002);
                        else
                            drawnow limitrate;
                        end

                        if app.stopKspaceMovieFlag
                            break;
                        end

                    end

                    % Finish the plot by showing all points, with a max of 32768
                    stepSize = round(length(pnts)/65536);
                    stepSize(stepSize < 1) = 1;
                    app.kmovieFig.XData = pnts(1:stepSize:end,1);
                    app.kmovieFig.YData = sizey + 1 - pnts(1:stepSize:end,2);


                catch
                end

            else

                app.stopKspaceMovieFlag = true;
                app.TextMessage('K-space trajectory movie not possible for this type of data ...',1);

            end

            % Reset start/stop buttons
            app.stopKspaceMovieFlag = true;
            app.KStopButton.Enable = 'off';
            app.KStartButton.Enable = 'on';
            drawnow;

            % Set status ok
            app.EnableKbuttonsFcn;
            app.SetStatus(0);

        end

        % Button pushed function: KStopButton
        function KStopButtonPushed(app, event)

            % Purpose:
            %   Stop the k-space trajectory animation.
            %
            % How it works:
            %   Sets the flag the animation loop checks on every frame.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the animation ends.

            app.stopKspaceMovieFlag = true;
            app.KStopButton.Enable = 'off';
            drawnow;

        end

        % Value changed function: FastSlowSwitch
        function FastSlowSwitchValueChanged(app, event)

            % Purpose:
            %   Change the speed of the trajectory animation.
            %
            % How it works:
            %   The animation loop reads the switch on every frame, so only a redraw is
            %   needed here.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none.

            drawnow;

        end

        % Value changed function: KLevelEditField
        function KLevelEditFieldValueChanged(app, event)

            % Purpose:
            %   Set the k-space display window or level.
            %
            % How it works:
            %   Keeps the paired slider and edit field showing the same number, then
            %   redraws. Only acts once a valid file is loaded.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.pd.validFileFlag

                app.KLevelSlider.Value = app.KLevelEditField.Value;
                app.PlotKspaceFigFcn;

            end

        end

        % Value changed function: KWindowEditField
        function KWindowEditFieldValueChanged(app, event)

            % Purpose:
            %   Set the k-space display window or level.
            %
            % How it works:
            %   Keeps the paired slider and edit field showing the same number, then
            %   redraws. Only acts once a valid file is loaded.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.pd.validFileFlag

                app.KWindowSlider.Value = app.KWindowEditField.Value;
                app.PlotKspaceFigFcn;

            end

        end

        % Value changed function: KLevelSlider
        function KLevelSliderValueChanged(app, event)

            % Purpose:
            %   Set the k-space display window or level.
            %
            % How it works:
            %   Keeps the paired slider and edit field showing the same number, then
            %   redraws. Only acts once a valid file is loaded.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.pd.validFileFlag

                app.KLevelEditField.Value = round(app.KLevelSlider.Value);
                app.PlotKspaceFigFcn;

            end

        end

        % Value changed function: KWindowSlider
        function KWindowSliderValueChanged(app, event)

            % Purpose:
            %   Set the k-space display window or level.
            %
            % How it works:
            %   Keeps the paired slider and edit field showing the same number, then
            %   redraws. Only acts once a valid file is loaded.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.pd.validFileFlag

                app.KWindowEditField.Value = round(app.KWindowSlider.Value);
                app.PlotKspaceFigFcn;

            end

        end

        % Value changed function: NREditField
        function NREditFieldValueChanged(app, event)

            % Purpose:
            %   Re-sort the data into a different number of dynamics.
            %
            % How it works:
            %   The dynamics count decides which acquisition belongs to which frame, so the
            %   data is sorted again from scratch rather than reshaped. Movies are stopped
            %   first and the buttons go to their busy state; the k-space view, the
            %   acquisition time and the include lists are recomputed afterwards.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, pd holds the re-sorted k-space.

            % Set buttons
            app.SetLightsFcn({'Yellow','Null','Null','Null','Null','Null'});
            app.SwitchOffAllButtonsFcn;
            app.DisableLabelsFcn;
            app.SetStatus(3);

            % Stop the movies, if running
            app.stopMovieFlag = true;
            app.stopKspaceMovieFlag = true;
            drawnow;

            % Re-sort the data into the new number of frames
            app.pd = app.pd.resortKspace(gatherRecoParams(app));
            MirrorDataOutputsFcn(app);

            % Total scan time and scan time / dynamic
            app.CalcTotalAcquisitionTimeFcn;

            % Set k-space dimensions and plot the k-space
            app.SetKspaceLimitsFcn;
            app.SetKspaceWLFcn;
            app.PlotKspaceFigFcn;

            % Set new include values
            app.IncludeNeEditFieldValueChanged;
            app.IncludeNrEditFieldValueChanged;

            % Set buttons
            app.SetLightsFcn({'Green','Red','Red','Red','Red','Red'});
            app.SetButtonsFcn({'On','On','Off','Off','Off','Off','On'});
            app.RecoProgressGauge.Value = 0;
            app.ExportProgressGauge.Value = 0;
            app.EnableLabelsFcn;
            app.SetStatus(0);

        end

        % Value changed function: IncludeNeEditField
        function IncludeNeEditFieldValueChanged(app, event)

            % Purpose:
            %   Normalise the include list for the echoes.
            %
            % How it works:
            %   The user types a free-form string. The digits are taken in order, padded
            %   with ones when there are fewer than the number of echoes and truncated when
            %   there are more, then written back as a clean list of 0s and 1s.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the edit field is rewritten.

            chars        = char(app.IncludeNeEditField.Value);
            digitMask    = isstrprop(chars, 'digit');
            digitsFound  = double(chars(digitMask) - '0');      % '0'→0, … '9'→9

            N = app.NEViewField.Value;
            numFound = numel(digitsFound);
            if numFound < N
                out = [digitsFound, ones(1, N - numFound)];     % pad with 1s
            else
                out = digitsFound(1:N);                         % truncate excess
            end

            out = reshape(out, 1, []);                          % guarantee row vector
            out = logical(out);                                 % turn into 0s and 1s

            app.IncludeNeEditField.Value = num2str(out);

        end

        % Value changed function: IncludeNrEditField
        function IncludeNrEditFieldValueChanged(app, event)

            % Purpose:
            %   Normalise the include list for the dynamics.
            %
            % How it works:
            %   The user types a free-form string. The digits are taken in order, padded
            %   with ones when there are fewer than the number of dynamics and truncated when
            %   there are more, then written back as a clean list of 0s and 1s.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the edit field is rewritten.

            chars        = char(app.IncludeNrEditField.Value);
            digitMask    = isstrprop(chars, 'digit');
            digitsFound  = double(chars(digitMask) - '0');      % '0'→0, … '9'→9

            N = app.NREditField.Value;
            numFound = numel(digitsFound);
            if numFound < N
                out = [digitsFound, ones(1, N - numFound)];     % pad with 1s
            else
                out = digitsFound(1:N);                         % truncate excess
            end

            out = reshape(out, 1, []);                          % guarantee row vector
            out = logical(out);                                 % turn into 0s and 1s

            app.IncludeNrEditField.Value = num2str(out);

        end

        % Value changed function: ZEditField
        function RecoSizeFieldValueChanged(app, event)

            % Purpose:
            %   Mark the reconstruction out of date after a matrix size change.
            %
            % How it works:
            %   Stops the movie, puts the lights and buttons back into the state that asks
            %   for a new reconstruction, and clears the progress gauges.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the buttons are updated.

            app.stopMovieFlag = true;
            drawnow;

            % Set buttons
            app.SetLightsFcn({'Null','Red','Red','Red','Red','Red'});
            app.SetButtonsFcn({'On','On','Off','Off','Off','Off','On'});
            app.RecoProgressGauge.Value = 0;
            app.ExportProgressGauge.Value = 0;
            drawnow;

        end

        % Value changed function: AmplitudeCorrectionCheckBox, 
        % ...and 20 other components
        function RecoParametersChanged(app, event)

            % Purpose:
            %   Mark the reconstruction out of date after a parameter change.
            %
            % How it works:
            %   As RecoSizeFieldValueChanged, and additionally refreshes the reconstruction
            %   fields so that dependent controls follow the new parameter.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the buttons and fields are updated.

            app.stopMovieFlag = true;
            drawnow;

            % Set buttons
            app.SetLightsFcn({'Null','Red','Red','Red','Red','Red'});
            app.SetButtonsFcn({'On','On','Off','Off','Off','Off','On'});
            app.RecoProgressGauge.Value = 0;
            app.ExportProgressGauge.Value = 0;
            drawnow;

            app.SetRecoFieldsFcn;

        end

        % Button pushed function: RecoButton
        function RecoImagesButtonPushed(app, event)

            % Purpose:
            %   Reconstruct images from the loaded k-space.
            %
            % How it works:
            %   Clears the previous result, optionally estimates coil sensitivities, then
            %   dispatches on pd.dataType and the CS check box: Cartesian 2D and 3D go to
            %   csReco or fftReco, radial and UTE to the BART or the NUFFT path, and ZTE to
            %   Reco3DZTE. Radial, UTE and P2ROUD data are FOV-shifted afterwards. The
            %   optional post-processing runs in a fixed order: readout oversampling removal,
            %   echo and repetition summing, Gibbs ringing suppression, PCA and BMD
            %   denoising, then scaling. On success the mask, pixel size, k-space view and
            %   all three image views are refreshed; on failure or user abort the lights go
            %   red and the reconstruction is marked invalid. NRLUT data is exported to MRD
            %   immediately afterwards, and a CINE acquisition starts its movie.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, pd.images and the displays are updated.

            if app.pd.validFileFlag

                % Set buttons
                app.SwitchOffAllButtonsFcn;
                app.SetLightsFcn({'Null','Yellow','Null','Null','Null','Null'});
                app.DisableLabelsFcn;

                % Indicate busy
                app.SetStatus(3);

                % Reset some buttons, stop the movies
                app.stopRecoFlag = false;
                app.pd.validRecoFlag = false;
                app.validNIFTIdataFlag = false;
                app.validMovieFlag = false;
                app.stopMovieFlag = true;
                app.stopKspaceMovieFlag = true;
                app.newFigFlag = true;
                app.newPhaseFigFlag = true;
                app.AbortRecoButton.Enable = 'on';
                app.RecoProgressGauge.Value = 0;
                app.pd.validMaskFlag = false;
                drawnow;

                app.TextMessage('Reconstructing the data ...',1);

                try

                    % Images = (X, Y, Z, NR, NFA, NE)
                    app.pd.images = [];
                    app.pd.complexImages = [];
                    app.pd.phaseImages = [];
                    app.pd.phaseImagesOrig = [];
                    app.pd.mask = [];
                    app.pd.sensitivityMaps = {};

                    % Auto coil sensitivity estimation
                    if app.AutoSensitivityCheckBox.Value == 1
                        app.pd = app.pd.estimateCoilSensitivies(gatherRecoParams(app));
                    end

                    switch app.pd.dataType

                        case {"2D","2Depi"}

                            if app.CSRecoCheckBox.Value == 1
                                % CS reconstruction
                                app.pd = app.pd.csReco2D(gatherRecoParams(app));
                            else
                                % FFT reconstruction
                                app.pd = app.pd.fftReco2D(gatherRecoParams(app));
                            end

                            MirrorRecoOutputsFcn(app);

                        case "3D"

                            if app.CSRecoCheckBox.Value == 1
                                % CS reconstruction
                                app.pd = app.pd.csReco3D(gatherRecoParams(app));
                            else
                                % FFT reconstruction
                                app.pd = app.pd.fftReco3D(gatherRecoParams(app));
                            end

                            MirrorRecoOutputsFcn(app);

                            % FOV shift for P2ROUD (exLUT) data
                            if strcmp(app.pd.trajType,"P2ROUD")
                                app.pd = app.pd.shiftImages3D(gatherRecoParams(app));
                            end

                        case "2Dradial"

                            if app.CSRecoCheckBox.Value == 1 && app.bartDetectedFlag
                                % 2D Radial reconstruction with BART
                                app.pd = app.pd.Reco2DRadialCS(gatherRecoParams(app));
                            else
                                % 2D Radial reconstruction with NUFFT in Matlab
                                app.pd = app.pd.Reco2DRadialNUFFT(gatherRecoParams(app));
                            end

                            MirrorRecoOutputsFcn(app);

                            % 2D FOV shift
                            app.pd = app.pd.shiftImages2D(gatherRecoParams(app));

                        case "3Dute"

                            if app.CSRecoCheckBox.Value == 1 && app.bartDetectedFlag
                                % CS BART reconstruction
                                app.pd = app.pd.Reco3DuteCS(gatherRecoParams(app));
                            else
                                % NUFFT reconstruction in Matlab
                                app.pd = app.pd.Reco3DuteNUFFT(gatherRecoParams(app));
                            end

                            MirrorRecoOutputsFcn(app);

                            % FOV shift for UTE
                            app.pd = app.pd.shiftImages3D(gatherRecoParams(app));

                        case "ZTE"

                            % ZTE reco
                            app.pd = app.pd.Reco3DZTE(gatherRecoParams(app));
                            MirrorRecoOutputsFcn(app);

                    end

                    % So far successful reco
                    app.pd.validRecoFlag = true;

                    % Readout oversampling
                    if app.pd.oversample == 1
                        oversample = round(app.XEditField.Value/4);
                        app.pd.images = app.pd.images(oversample+1:end-oversample,:,:,:,:,:);
                        app.pd.phaseImages = app.pd.phaseImages(oversample+1:end-oversample,:,:,:,:,:);
                    end

                    % Sum over echoes
                    if app.SumEchosCheckBox.Value
                        app.pd = app.pd.SumEchoes(gatherRecoParams(app));
                    end

                    % Sum over repetitions
                    if app.SumRepetitionsCheckBox.Value
                        app.pd = app.pd.SumRepetitions(gatherRecoParams(app));
                    end

                    % Gibbs ringing suppression
                    if app.DeRingCheckBox.Value
                        app.pd = app.pd.unRing(gatherRecoParams(app));
                    end

                    % PCA denoising
                    if app.PCAdeNoiseCheckBox.Value
                        app.pd = app.pd.PCAdenoise(gatherRecoParams(app));
                    end

                    % BMD denoising
                    if app.BMDdeNoiseCheckBox.Value
                        app.pd = app.pd.BMDdenoise(gatherRecoParams(app));
                    end

                    % Scale images to convenient range
                    app.pd = app.pd.scaleImages;

                    % Finished
                    app.RecoProgressGauge.Value = 100;
                    drawnow;

                catch ME

                    app.TextMessage(ME.message,2);
                    if app.stopRecoFlag
                        app.TextMessage("Reco aborted by user ...",1);
                    end
                    app.pd.validRecoFlag = false;
                    app.RecoProgressGauge.Value = 0;
                    drawnow;

                end

            end

            if app.pd.validRecoFlag

                % Nifti check
                app.validNIFTIdataFlag = false;
                if size(app.pd.images,3)>1
                    app.validNIFTIdataFlag = true;
                end

                % Mask
                app.pd.mask = ones(size(app.pd.images));

                % Calculate pixel size
                app.pd = app.pd.calcPixelSize(gatherRecoParams(app));

                % Replot k-space too
                app.SetKspaceLimitsFcn;
                app.SetKspaceWLFcn;
                app.PlotKspaceFigFcn;

                % Show the images
                if size(app.pd.images,4) > 1
                    app.validMovieFlag = true;
                end
                app.SetImageLimitsFcn;
                app.SetImageWLFcn;
                app.SetMIPWLFcn;
                app.PlotImageFigFcn;
                app.PlotPhaseImageFigFcn;
                app.PlotMipFigFcn;
                app.TextMessage(strcat("Size of image = ",num2str(size(app.pd.images))," ..."),1);

                if app.stopRecoFlag
                    app.SetStatus(1);
                    app.SetLightsFcn({'Null','Green','Red','Red','Red','Red'});
                    app.TextMessage('WARNING: Reconstruction interrupted ...',1);
                else
                    app.SetStatus(0);
                    app.SetLightsFcn({'Null','Green','Red','Red','Red','Red'});
                end

                % Set buttons
                app.SetButtonsFcn({'On','On','On','On','On','On','On'});
                app.EnableLabelsFcn;
                app.AbortRecoButton.Enable = 'off';
                app.ExportProgressGauge.Value = 0;
                app.SetStatus(0);

                % Do DCM export in case of NRLUT, to delete massive number of original dicoms
                if app.pd.trajType == "NRLUT"
                    app.MRDButtonPushed;
                end

                % Start the movie when CINE acquisition
                if (app.pd.frameLoopOn == 1)
                    app.OrientationSpinner.Value = 10;
                    app.OrientationSpinnerValueChanged;
                    app.MovieMoviePlayButtonPushed;
                    drawnow;
                end

            else

                % Set buttons
                app.SetButtonsFcn({'On','On','Off','Off','Off','Off','On'});
                app.SetLightsFcn({'Null','Red','Red','Red','Red','Red'});
                app.EnableLabelsFcn;
                app.AbortRecoButton.Enable = 'off';
                app.TextMessage('ERROR: Something went wrong ...',1);
                app.SetStatus(2);

            end

        end

        % Button pushed function: AbortRecoButton
        function AbortRecoButtonPushed(app, event)

            % Purpose:
            %   Ask the running reconstruction to stop.
            %
            % How it works:
            %   Sets the flag the reconstruction polls between steps. It stops at the next
            %   check rather than immediately, which is why the message asks the user to wait.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the reconstruction ends early.

            app.stopRecoFlag = true;
            app.AbortRecoButton.Enable = 'off';
            app.TextMessage('Reco will be aborted ...',1);
            app.TextMessage('Please wait ...',1);
            drawnow;

        end

        % Value changed function: Dim3EditField
        function Dim3EditFieldValueChanged(app, event)

            % Purpose:
            %   Show the image at the dimension 3 index the user typed.
            %
            % How it works:
            %   Copies the value to the slider and replots.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            app.Dim3Slider.Value = app.Dim3EditField.Value;
            app.PlotImageFigFcn;

        end

        % Value changed function: Dim3Slider
        function Dim3SliderValueChanged(app, event)

            % Purpose:
            %   Show the image at the dimension 3 index the user dragged to.
            %
            % How it works:
            %   Rounds the slider to a whole index, copies it to the edit field and
            %   replots.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            app.Dim3EditField.Value = round(app.Dim3Slider.Value);
            app.PlotImageFigFcn;

        end

        % Value changed function: Dim4EditField
        function Dim4EditFieldValueChanged(app, event)

            % Purpose:
            %   Show the image at the dimension 4 index the user typed.
            %
            % How it works:
            %   Copies the value to the slider and replots. The MIP shares this dimension, so its window and level are
            %   recomputed and it is replotted as well.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            app.Dim4Slider.Value = app.Dim4EditField.Value;
            app.PlotImageFigFcn;
            app.SetMIPWLFcn;
            app.PlotMipFigFcn;

        end

        % Value changed function: Dim4Slider
        function Dim4SliderValueChanged(app, event)

            % Purpose:
            %   Show the image at the dimension 4 index the user dragged to.
            %
            % How it works:
            %   Rounds the slider to a whole index, copies it to the edit field and
            %   replots. The MIP shares this dimension, so its window and level are
            %   recomputed and it is replotted as well.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            app.Dim4EditField.Value = round(app.Dim4Slider.Value);
            app.PlotImageFigFcn;
            app.SetMIPWLFcn;
            app.PlotMipFigFcn;

        end

        % Value changed function: Dim5EditField
        function Dim5EditFieldValueChanged(app, event)

            % Purpose:
            %   Show the image at the dimension 5 index the user typed.
            %
            % How it works:
            %   Copies the value to the slider and replots. The MIP shares this dimension, so its window and level are
            %   recomputed and it is replotted as well.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            app.Dim5Slider.Value = app.Dim5EditField.Value;
            app.PlotImageFigFcn;
            app.SetMIPWLFcn;
            app.PlotMipFigFcn;

        end

        % Value changed function: Dim5Slider
        function Dim5SliderValueChanged(app, event)

            % Purpose:
            %   Show the image at the dimension 5 index the user dragged to.
            %
            % How it works:
            %   Rounds the slider to a whole index, copies it to the edit field and
            %   replots. The MIP shares this dimension, so its window and level are
            %   recomputed and it is replotted as well.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            app.Dim5EditField.Value = round(app.Dim5Slider.Value);
            app.PlotImageFigFcn;
            app.SetMIPWLFcn;
            app.PlotMipFigFcn;

        end

        % Value changed function: Dim6EditField
        function Dim6EditFieldValueChanged(app, event)

            % Purpose:
            %   Show the image at the dimension 6 index the user typed.
            %
            % How it works:
            %   Copies the value to the slider and replots. The MIP shares this dimension, so its window and level are
            %   recomputed and it is replotted as well.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            app.Dim6Slider.Value = app.Dim6EditField.Value;
            app.PlotImageFigFcn;
            app.SetMIPWLFcn;
            app.PlotMipFigFcn;

        end

        % Value changed function: Dim6Slider
        function Dim6SliderValueChanged(app, event)

            % Purpose:
            %   Show the image at the dimension 6 index the user dragged to.
            %
            % How it works:
            %   Rounds the slider to a whole index, copies it to the edit field and
            %   replots. The MIP shares this dimension, so its window and level are
            %   recomputed and it is replotted as well.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            app.Dim6EditField.Value = round(app.Dim6Slider.Value);
            app.PlotImageFigFcn;
            app.SetMIPWLFcn;
            app.PlotMipFigFcn;

        end

        % Value changed function: OrientationSpinner
        function OrientationSpinnerValueChanged(app, event)

            % Purpose:
            %   Show the images along a different axis.
            %
            % How it works:
            %   The image, MIP and phase viewers share one orientation, so all three
            %   spinners are set to the new value. Changing the axis changes which array
            %   dimensions are on screen, so the limits are recomputed, both figures are
            %   marked for a full redraw, and all three views are replotted.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            app.MIPOrientationSpinner.Value = app.OrientationSpinner.Value;
            app.PhaseOrientationSpinner.Value = app.OrientationSpinner.Value;
            app.SetImageLimitsFcn;
            app.newFigFlag = true;
            app.newPhaseFigFlag = true;
            app.PlotImageFigFcn;
            app.PlotMipFigFcn;
            app.PlotPhaseImageFigFcn;

        end

        % Value changed function: MIPOrientationSpinner
        function MIPOrientationSpinnerValueChanged(app, event)

            % Purpose:
            %   Show the images along a different axis.
            %
            % How it works:
            %   The image, MIP and phase viewers share one orientation, so all three
            %   spinners are set to the new value. Changing the axis changes which array
            %   dimensions are on screen, so the limits are recomputed, both figures are
            %   marked for a full redraw, and all three views are replotted.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            app.OrientationSpinner.Value = app.MIPOrientationSpinner.Value;
            app.PhaseOrientationSpinner.Value = app.MIPOrientationSpinner.Value;
            app.SetImageLimitsFcn;
            app.newFigFlag = true;
            app.newPhaseFigFlag = true;
            app.PlotImageFigFcn;
            app.PlotMipFigFcn;
            app.PlotPhaseImageFigFcn;

        end

        % Value changed function: LevelSlider
        function LevelSliderValueChanged(app, event)

            % Purpose:
            %   Set the image display window or level by hand.
            %
            % How it works:
            %   Keeps the paired slider and edit field showing the same number, then
            %   redraws. Setting it by hand means it is no longer automatic, so the
            %   automatic intensity box is cleared. Only acts on a valid reconstruction.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.pd.validRecoFlag

                app.AutoIntensityCheckBox.Value = 0;
                app.LevelEditField.Value = round(app.LevelSlider.Value);
                app.PlotImageFigFcn;

            end

        end

        % Value changed function: LevelEditField
        function LevelEditFieldValueChanged(app, event)

            % Purpose:
            %   Set the image display window or level by hand.
            %
            % How it works:
            %   Keeps the paired slider and edit field showing the same number, then
            %   redraws. Setting it by hand means it is no longer automatic, so the
            %   automatic intensity box is cleared. Only acts on a valid reconstruction.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.pd.validRecoFlag

                app.AutoIntensityCheckBox.Value = 0;
                app.LevelSlider.Value = app.LevelEditField.Value;
                app.PlotImageFigFcn;

            end

        end

        % Value changed function: WindowSlider
        function WindowSliderValueChanged(app, event)

            % Purpose:
            %   Set the image display window or level by hand.
            %
            % How it works:
            %   Keeps the paired slider and edit field showing the same number, then
            %   redraws. Setting it by hand means it is no longer automatic, so the
            %   automatic intensity box is cleared. Only acts on a valid reconstruction.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.pd.validRecoFlag

                app.AutoIntensityCheckBox.Value = 0;
                app.WindowEditField.Value = round(app.WindowSlider.Value);
                app.PlotImageFigFcn;

            end

        end

        % Value changed function: WindowEditField
        function WindowEditFieldValueChanged(app, event)

            % Purpose:
            %   Set the image display window or level by hand.
            %
            % How it works:
            %   Keeps the paired slider and edit field showing the same number, then
            %   redraws. Setting it by hand means it is no longer automatic, so the
            %   automatic intensity box is cleared. Only acts on a valid reconstruction.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.pd.validRecoFlag

                app.AutoIntensityCheckBox.Value = 0;
                app.WindowSlider.Value = app.WindowEditField.Value;
                app.PlotImageFigFcn;

            end

        end

        % Value changed function: AutoIntensityCheckBox
        function AutoIntensityCheckBoxValueChanged(app, event)

            % Purpose:
            %   Return the image display to an automatic window and level.
            %
            % How it works:
            %   Replots when the box is switched on; the plotting function computes the
            %   scaling itself while the box is set.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.AutoIntensityCheckBox.Value == 1
                app.PlotImageFigFcn;
            end

        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)

            % Purpose:
            %   Recompute the image window and level from the data.
            %
            % How it works:
            %   Clears the automatic box, takes a fresh window and level from the current
            %   image through SetImageWLFcn, then replots.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.pd.validRecoFlag

                app.AutoIntensityCheckBox.Value = 0;
                app.SetImageWLFcn;
                app.PlotImageFigFcn;

            end

        end

        % Button pushed function: MoviePlayButton
        function MovieMoviePlayButtonPushed(app, event)

            % Purpose:
            %   Play the reconstructed images as a movie over the fourth dimension.
            %
            % How it works:
            %   Steps the dimension 4 controls in a loop, replotting each frame and wrapping
            %   at the end, until the stop flag is set. The buttons swap so that only one of
            %   play and stop is available at a time.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the image figure is animated.

            if app.pd.validRecoFlag

                try

                    app.MoviePlayButton.Enable = 'off';
                    app.MovieStopButton.Enable = 'on';
                    % app.ExitButton.Enable = 'off';
                    app.stopMovieFlag = false;

                    app.TextMessage('Starting the movie ...',1);

                    movieCounter = 1;
                    while ~app.stopMovieFlag

                        app.Dim4EditField.Value = movieCounter;
                        app.Dim4Slider.Value = movieCounter;
                        app.PlotImageFigFcn;
                        movieCounter = app.Dim4EditField.Value;

                        movieCounter = movieCounter + 1;
                        movieIm = permute(app.pd.images,app.imageOrient(app.OrientationSpinner.Value,:));
                        if movieCounter>size(movieIm,4)
                            movieCounter = 1;
                        end

                    end

                    app.MoviePlayButton.Enable = 'on';
                    app.MovieStopButton.Enable = 'off';

                catch
                end


            end

        end

        % Button pushed function: MovieStopButton
        function MovieMovieStopButtonPushed(app, event)

            % Purpose:
            %   Stop the image movie.
            %
            % How it works:
            %   Sets the flag the movie loop checks on every frame and restores the buttons.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the movie ends.

            app.TextMessage('Stopping the movie ...',1);

            app.MoviePlayButton.Enable = 'on';
            app.MovieStopButton.Enable = 'off';
            app.stopMovieFlag = true;
            app.ExitButton.Enable = 'on';
            drawnow;

        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)

            % Purpose:
            %   Make an image mask by automatic thresholding.
            %
            % How it works:
            %   Takes Otsu's threshold over the whole image stack, scales it back to the data
            %   range, and marks everything below it as outside the mask. The threshold is
            %   shown on the slider and field so it can be adjusted afterwards. A failure
            %   leaves the mask invalid and is reported.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, pd.mask and pd.threshold are updated.

            if app.pd.validRecoFlag

                app.TextMessage('Automatic image thresholding ...',1);

                try

                    % Reset the mask
                    app.pd.mask = ones(size(app.pd.images));

                    % Determine automatic threshold using the Otsu method
                    app.pd.threshold = double(graythresh(mat2gray(app.pd.images(:)))) * max(app.pd.images(:));

                    % Mask based on threshold
                    app.pd.mask(app.pd.images < app.pd.threshold) = 0;

                    % Display the threshold value for the current slice
                    app.ThresholdEditField.Value = round(app.pd.threshold);
                    app.ThresholdSlider.Value = round(app.pd.threshold);

                    % Mask is valid
                    app.pd.validMaskFlag = true;
                    app.newFigFlag = true;

                    % Display the mask in the image
                    app.PlotImageFigFcn;

                    % Plot MIP and phase image
                    app.PlotMipFigFcn;
                    app.PlotPhaseImageFigFcn;

                catch ME

                    % Failure
                    app.pd.validMaskFlag = false;
                    app.newFigFlag = true;
                    app.TextMessage('ERROR: during image thresholding ...',1);
                    app.TextMessage(ME.message,2);

                end

            end

        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)

            % Purpose:
            %   Rebuild the mask at the threshold the user typed.
            %
            % How it works:
            %   Rebuilds the mask from scratch rather than editing the existing one, so
            %   lowering the threshold brings voxels back. All three views are replotted.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, pd.mask is updated.

            app.ThresholdSlider.Value = app.ThresholdEditField.Value;

            if app.pd.validRecoFlag

                try

                    % Reset the mask
                    app.pd.mask = ones(size(app.pd.images));

                    % Threshold
                    app.pd.threshold = app.ThresholdEditField.Value;

                    % Mask based on threshold
                    app.pd.mask(app.pd.images < app.pd.threshold) = 0;

                    % Mask is valid
                    app.pd.validMaskFlag = true;
                    app.newFigFlag = true;

                    % Display the mask in the image
                    app.PlotImageFigFcn;

                    % Plot MIP and phase image
                    app.PlotMipFigFcn;
                    app.PlotPhaseImageFigFcn;

                catch ME

                    % Failure
                    app.pd.validMaskFlag = false;
                    app.newFigFlag = true;
                    app.TextMessage('ERROR: during image thresholding ...',1);
                    app.TextMessage(ME.message,2);

                end

            end

        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)

            % Purpose:
            %   Rebuild the mask at the threshold the user dragged to.
            %
            % How it works:
            %   Rounds the slider into the edit field and defers to its callback, so both
            %   routes build the mask by the same code.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, pd.mask is updated.

            app.ThresholdEditField.Value = round(app.ThresholdSlider.Value);

            app.ThresholdEditFieldValueChanged;

        end

        % Button pushed function: ErodeButton
        function ErodeButtonPushed(app, event)

            % Purpose:
            %   Morphologically adjust the mask.
            %
            % How it works:
            %   Applies imerode with the structuring element in app.structElement. The mask is
            %   inverted around the call because the operation is defined on the object
            %   rather than on the background, then all three views are replotted.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, pd.mask is updated.

            if app.pd.validRecoFlag && app.pd.validMaskFlag

                try

                    % Morphological erosion
                    app.pd.mask = 1-imerode(1-app.pd.mask,app.structElement);

                    % Display the result;
                    app.newFigFlag = true;
                    app.PlotImageFigFcn;
                    app.PlotMipFigFcn;
                    app.PlotPhaseImageFigFcn;

                catch
                end

            end

        end

        % Button pushed function: DilateButton
        function DilateButtonPushed(app, event)

            % Purpose:
            %   Morphologically adjust the mask.
            %
            % How it works:
            %   Applies imdilate with the structuring element in app.structElement. The mask is
            %   inverted around the call because the operation is defined on the object
            %   rather than on the background, then all three views are replotted.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, pd.mask is updated.

            if app.pd.validRecoFlag && app.pd.validMaskFlag

                try

                    % Morphological erosion
                    app.pd.mask = 1-imdilate(1-app.pd.mask,app.structElement);

                    % Display the result;
                    app.newFigFlag = true;
                    app.PlotImageFigFcn;
                    app.PlotMipFigFcn;
                    app.PlotPhaseImageFigFcn;

                catch
                end

            end

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)

            % Purpose:
            %   Morphologically adjust the mask.
            %
            % How it works:
            %   Applies imclose with the structuring element in app.structElement. The mask is
            %   inverted around the call because the operation is defined on the object
            %   rather than on the background, then all three views are replotted.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, pd.mask is updated.

            if app.pd.validRecoFlag && app.pd.validMaskFlag

                try

                    % Morphological erosion
                    app.pd.mask = 1-imclose(1-app.pd.mask,app.structElement);

                    % Display the result;
                    app.newFigFlag = true;
                    app.PlotImageFigFcn;
                    app.PlotMipFigFcn;
                    app.PlotPhaseImageFigFcn;

                catch
                end

            end

        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)

            % Purpose:
            %   Morphologically adjust the mask.
            %
            % How it works:
            %   Applies imopen with the structuring element in app.structElement. The mask is
            %   inverted around the call because the operation is defined on the object
            %   rather than on the background, then all three views are replotted.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, pd.mask is updated.

            if app.pd.validRecoFlag && app.pd.validMaskFlag

                try

                    % Morphological erosion
                    app.pd.mask = 1-imopen(1-app.pd.mask,app.structElement);

                    % Display the result;
                    app.newFigFlag = true;
                    app.PlotImageFigFcn;
                    app.PlotMipFigFcn;
                    app.PlotPhaseImageFigFcn;

                catch
                end

            end

        end

        % Value changed function: MIPxAngleSlider, MIPyAngleSlider
        function MIPAngleSliderValueChanged(app, event)

            % Purpose:
            %   Rotate the maximum intensity projection.
            %
            % How it works:
            %   Resets the rotation counter and replots at the new angle.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            app.MIPcounter = 0;
            app.PlotMipFigFcn;

        end

        % Button pushed function: MIPxPlayButton
        function MIPxPlayButtonPushed(app, event)

            % Purpose:
            %   Spin the maximum intensity projection about the x axis.
            %
            % How it works:
            %   Acts as a toggle: pressing it while it runs stops it. The other axis is
            %   stopped first so only one rotation runs at a time, and the icon follows
            %   the state. The loop advances the angle by MIPstep and wraps past 179
            %   degrees.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the MIP figure is animated.

            if app.pd.validRecoFlag

                app.stopMIPyMovieFlag = true;
                app.MIPyPlayButton.Icon = 'play.gif';

                if app.stopMIPxMovieFlag == true
                    app.MIPcounter = 0;
                    app.stopMIPxMovieFlag = false;
                    app.MIPxPlayButton.Icon = 'stop.gif';
                else
                    app.stopMIPxMovieFlag = true;
                    app.MIPxPlayButton.Icon = 'play.gif';
                end

                drawnow;

                while ~app.stopMIPxMovieFlag

                    newAngleX = app.MIPxAngleSlider.Value + app.MIPstep;
                    app.MIPcounter = app.MIPcounter + app.MIPstep;
                    newAngleX(newAngleX > 179) = -180;
                    app.MIPxAngleSlider.Value = newAngleX;
                    drawnow;

                    app.PlotMipFigFcn;

                end

            end

        end

        % Button pushed function: MIPyPlayButton
        function MIPyPlayButtonPushed(app, event)

            % Purpose:
            %   Spin the maximum intensity projection about the y axis.
            %
            % How it works:
            %   Acts as a toggle: pressing it while it runs stops it. The other axis is
            %   stopped first so only one rotation runs at a time, and the icon follows
            %   the state. The loop advances the angle by MIPstep and wraps past 179
            %   degrees.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the MIP figure is animated.

            if app.pd.validRecoFlag

                app.stopMIPxMovieFlag = true;
                app.MIPxPlayButton.Icon = 'play.gif';

                if app.stopMIPyMovieFlag == true
                    app.MIPcounter = 0;
                    app.stopMIPyMovieFlag = false;
                    app.MIPyPlayButton.Icon = 'stop.gif';
                else
                    app.stopMIPyMovieFlag = true;
                    app.MIPyPlayButton.Icon = 'play.gif';
                end

                drawnow;

                while ~app.stopMIPyMovieFlag

                    app.MIPcounter = app.MIPcounter + app.MIPstep;
                    newAngleY = app.MIPyAngleSlider.Value + app.MIPstep;
                    newAngleY(newAngleY > 179) = -180;
                    app.MIPyAngleSlider.Value = newAngleY;
                    drawnow;

                    app.PlotMipFigFcn;

                end

            end

        end

        % Button pushed function: MIPAngleResetButton
        function MIPAngleSliderResetButtonPushed(app, event)

            % Purpose:
            %   Return the MIP to its unrotated view.
            %
            % How it works:
            %   Stops both rotations, sets both angles to zero, resets the play icons and
            %   replots.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.pd.validRecoFlag

                app.stopMIPxMovieFlag = true;
                app.stopMIPyMovieFlag = true;

                app.MIPxAngleSlider.Value = 0;
                app.MIPyAngleSlider.Value = 0;

                % Reset the play icon buttons
                app.MIPxPlayButton.Icon = 'play.gif';
                app.MIPyPlayButton.Icon = 'play.gif';

                app.MIPcounter = 0;

                app.PlotMipFigFcn;

            end

        end

        % Value changed function: MIPResizeButton
        function MIPResizeButtonValueChanged(app, event)

            % Purpose:
            %   Change the size of the MIP display.
            %
            % How it works:
            %   Replots at the new size, but only while no rotation is running, so a spin is
            %   not interrupted.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.pd.validRecoFlag

                app.MIPcounter = 0;

                if app.stopMIPxMovieFlag & app.stopMIPyMovieFlag %#ok<AND2>

                    app.PlotMipFigFcn;

                end

            end

        end

        % Button pushed function: MIPSetButton
        function MIPSetButtonPushed(app, event)

            % Purpose:
            %   Recompute the MIP window and level from the data.
            %
            % How it works:
            %   Takes a fresh window and level through SetMIPWLFcn, then replots.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            app.SetMIPWLFcn;
            app.PlotMipFigFcn;

        end

        % Value changed function: MIPLevelSlider
        function MIPLevelSliderValueChanged(app, event)

            % Purpose:
            %   Set the MIP display window or level.
            %
            % How it works:
            %   Keeps the paired slider and edit field showing the same number, then
            %   redraws. Only acts on a valid reconstruction.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.pd.validRecoFlag

                app.MIPLevelEditField.Value = round(app.MIPLevelSlider.Value);
                app.PlotMipFigFcn;

            end

        end

        % Value changed function: MIPWindowSlider
        function MIPWindowSliderValueChanged(app, event)

            % Purpose:
            %   Set the MIP display window or level.
            %
            % How it works:
            %   Keeps the paired slider and edit field showing the same number, then
            %   redraws. Only acts on a valid reconstruction.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.pd.validRecoFlag

                app.MIPWindowEditField.Value = round(app.MIPWindowSlider.Value);
                app.PlotMipFigFcn;

            end

        end

        % Value changed function: MIPLevelEditField
        function MIPLevelEditFieldValueChanged(app, event)

            % Purpose:
            %   Set the MIP display window or level.
            %
            % How it works:
            %   Keeps the paired slider and edit field showing the same number, then
            %   redraws. Only acts on a valid reconstruction.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.pd.validRecoFlag

                app.MIPLevelSlider.Value = app.MIPLevelEditField.Value;
                app.PlotMipFigFcn;

            end

        end

        % Value changed function: MIPWindowEditField
        function MIPWindowEditFieldValueChanged(app, event)

            % Purpose:
            %   Set the MIP display window or level.
            %
            % How it works:
            %   Keeps the paired slider and edit field showing the same number, then
            %   redraws. Only acts on a valid reconstruction.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            if app.pd.validRecoFlag

                app.MIPWindowSlider.Value = app.MIPWindowEditField.Value;
                app.PlotMipFigFcn;

            end

        end

        % Value changed function: PhaseDim3EditField
        function PhaseDim3EditFieldValueChanged(app, event)

            % Purpose:
            %   Show the phase image at the dimension 3 index the user typed.
            %
            % How it works:
            %   Copies the value to the slider and replots the phase view.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            app.PhaseDim3Slider.Value = app.PhaseDim3EditField.Value;
            app.PlotPhaseImageFigFcn;

        end

        % Value changed function: PhaseDim3Slider
        function PhaseDim3SliderValueChanged(app, event)

            % Purpose:
            %   Show the phase image at the dimension 3 index the user dragged to.
            %
            % How it works:
            %   Rounds the slider into the edit field and replots the phase view.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            app.PhaseDim3EditField.Value = round(app.PhaseDim3Slider.Value);
            app.PlotPhaseImageFigFcn;

        end

        % Value changed function: PhaseDim4EditField
        function PhaseDim4EditFieldValueChanged(app, event)

            % Purpose:
            %   Show the phase image at the dimension 4 index the user typed.
            %
            % How it works:
            %   Copies the value to the slider and replots the phase view.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            app.PhaseDim4Slider.Value = app.PhaseDim4EditField.Value;
            app.PlotPhaseImageFigFcn;

        end

        % Value changed function: PhaseDim4Slider
        function PhaseDim4SliderValueChanged(app, event)

            % Purpose:
            %   Show the phase image at the dimension 4 index the user dragged to.
            %
            % How it works:
            %   Rounds the slider into the edit field and replots the phase view.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            app.PhaseDim4EditField.Value = round(app.PhaseDim4Slider.Value);
            app.PlotPhaseImageFigFcn

        end

        % Value changed function: PhaseDim5EditField
        function PhaseDim5EditFieldValueChanged(app, event)

            % Purpose:
            %   Show the phase image at the dimension 5 index the user typed.
            %
            % How it works:
            %   Copies the value to the slider and replots the phase view.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            app.PhaseDim5Slider.Value = app.PhaseDim5EditField.Value;
            app.PlotPhaseImageFigFcn;

        end

        % Value changed function: PhaseDim5Slider
        function PhaseDim5SliderValueChanged(app, event)

            % Purpose:
            %   Show the phase image at the dimension 5 index the user dragged to.
            %
            % How it works:
            %   Rounds the slider into the edit field and replots the phase view.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            app.PhaseDim5EditField.Value = round(app.PhaseDim5Slider.Value);
            app.PlotPhaseImageFigFcn

        end

        % Value changed function: PhaseDim6EditField
        function PhaseDim6EditFieldValueChanged(app, event)

            % Purpose:
            %   Show the phase image at the dimension 6 index the user typed.
            %
            % How it works:
            %   Copies the value to the slider and replots the phase view.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            app.PhaseDim6Slider.Value = app.PhaseDim6EditField.Value;
            app.PlotPhaseImageFigFcn;

        end

        % Value changed function: PhaseDim6Slider
        function PhaseDim6SliderValueChanged(app, event)

            % Purpose:
            %   Show the phase image at the dimension 6 index the user dragged to.
            %
            % How it works:
            %   Rounds the slider into the edit field and replots the phase view.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            app.PhaseDim6EditField.Value = round(app.PhaseDim6Slider.Value);
            app.PlotPhaseImageFigFcn

        end

        % Value changed function: PhaseOrientationSpinner
        function PhaseOrientationSpinnerValueChanged(app, event)

            % Purpose:
            %   Show the images along a different axis.
            %
            % How it works:
            %   The image, MIP and phase viewers share one orientation, so all three
            %   spinners are set to the new value. Changing the axis changes which array
            %   dimensions are on screen, so the limits are recomputed, both figures are
            %   marked for a full redraw, and all three views are replotted.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the displayed figure is redrawn.

            app.OrientationSpinner.Value = app.PhaseOrientationSpinner.Value;
            app.MIPOrientationSpinner.Value = app.PhaseOrientationSpinner.Value;
            app.SetImageLimitsFcn;
            app.newFigFlag = true;
            app.newPhaseFigFlag = true;
            app.PlotImageFigFcn;
            app.PlotMipFigFcn;
            app.PlotPhaseImageFigFcn;

        end

        % Button pushed function: PhaseMovieStartButton
        function PhaseMovieStartButtonPushed(app, event)

            % Purpose:
            %   Play the phase images as a movie over the fourth dimension.
            %
            % How it works:
            %   Steps the phase dimension 4 controls in a loop, replotting each frame and
            %   wrapping at the end, until the stop flag is set.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the phase figure is animated.

            if app.pd.validRecoFlag

                app.PhaseMovieStartButton.Enable = 'off';
                app.PhaseMovieStopButton.Enable = 'on';
                app.stopPhaseMovieFlag = false;

                app.TextMessage('Starting the phase movie ...',1);

                try

                    phaseCounter = 1;
                    while ~app.stopPhaseMovieFlag

                        app.PhaseDim4EditField.Value = phaseCounter;
                        app.PhaseDim4Slider.Value = phaseCounter;
                        app.PlotPhaseImageFigFcn;
                        phaseCounter = app.PhaseDim4EditField.Value;
                        phaseCounter = phaseCounter + 1;
                        phaseIm = permute(app.pd.phaseImages,app.imageOrient(app.PhaseOrientationSpinner.Value,:));
                        if phaseCounter>size(phaseIm,4)
                            phaseCounter = 1;
                        end

                    end

                catch
                end

                app.PhaseMovieStartButton.Enable = 'on';
                app.PhaseMovieStopButton.Enable = 'off';

            end

        end

        % Button pushed function: PhaseMovieStopButton
        function PhaseMovieStopButtonPushed(app, event)

            % Purpose:
            %   Stop the phase movie.
            %
            % How it works:
            %   Sets the flag the movie loop checks on every frame and restores the buttons.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the movie ends.

            app.TextMessage('Stopping the phase movie ...',1);

            app.PhaseMovieStartButton.Enable = 'on';
            app.PhaseMovieStopButton.Enable = 'off';
            app.stopPhaseMovieFlag = true;
            app.ExitButton.Enable = 'on';
            drawnow;

        end

        % Button pushed function: PhaseUnwrapButton
        function PhaseUnwrapButtonPushed(app, event)

            % Purpose:
            %   Unwrap the phase images so that they run continuously.
            %
            % How it works:
            %   Hands the volume to pd.unwrap3D and marks the phase figure for a full redraw.
            %   The button is disabled while it runs, because unwrapping a large volume takes
            %   time and a second press would start it again.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, pd.phaseImages is updated.

            if app.pd.validRecoFlag

                app.SetStatus(3);
                app.PhaseUnwrapButton.Enable = 'off';
                drawnow;

                % Phase unwrapping
                try
                    app.pd = app.pd.unwrap3D;
                    app.newPhaseFigFlag = true;
                    app.PlotPhaseImageFigFcn;
                catch ME
                    app.TextMessage("Error during phase unwrapping ...",1);
                    app.TextMessage(ME.message,2);
                end

                app.SetStatus(0);
                app.PhaseUnwrapButton.Enable = 'on';
                drawnow;

            end

        end

        % Window scroll wheel function: P2ROUD_UIFigure
        function P2ROUD_UIFigureWindowScrollWheel(app, event)

            % Purpose:
            %   Step through slices with the scroll wheel.
            %
            % How it works:
            %   One slice per wheel notch, wrapping at both ends, then defers to the
            %   dimension 3 slider callback so that scrolling and dragging behave
            %   identically. Only acts on a valid reconstruction and while scrolling is
            %   enabled, so the wheel does not fight a control already using it.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %   event - scroll event, for the wheel direction
            %
            % Output:
            %   none, the displayed slice changes.

            verticalScrollCount = event.VerticalScrollCount;

            if app.pd.validRecoFlag && app.scrollEnableFlag

                if verticalScrollCount > 0

                    newslice = app.Dim3Slider.Value + 1;
                    newslice(newslice>app.Dim3Slider.Limits(2)) = 1;

                else

                    newslice = app.Dim3Slider.Value - 1;
                    newslice(newslice<1) = app.Dim3Slider.Limits(2);

                end

                app.Dim3Slider.Value = newslice;
                app.Dim3SliderValueChanged;

            end

        end

        % Button pushed function: DCMNIFTIButton
        function DCMNIFTIButtonPushed(app, event)

            % Purpose:
            %   Export the reconstructed images as DICOM, and as NIfTI when possible.
            %
            % How it works:
            %   Fills in the geometry the exporters need, which differs between 2D and 3D
            %   data, then looks for the scanner's own DICOM files beside the MRD directory,
            %   trying the study folder and three numbered subfolders. Found, the export
            %   reuses that header through exportDicomDCM and keeps patient and study
            %   identity; not found, the user picks a folder and exportDicomMRD builds a
            %   header from the MRD parameters instead. The export folder is tested for
            %   writability first. NIfTI export follows and is allowed to fail without
            %   failing the DICOM export.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, files are written and pd.exportPath is set.

            if app.pd.validRecoFlag

                try

                    % Set buttons
                    app.SwitchOffAllButtonsFcn;
                    app.SetLightsFcn({'Null','Null','Yellow','Null','Null','Null'});
                    app.DisableLabelsFcn;
                    app.SetStatus(3);

                    % Slice geometry filled in for the export
                    %
                    % 2D keeps what it was imported with. A slice thickness is a property
                    % of the excitation, not of the reconstruction matrix, and reading it
                    % back out of FOVViewField3 made it depend on a display field.
                    % That field shows the thickness for the 2D types and the whole slab
                    % for 3D, so the assignment was a no-op only for as long as the 2D
                    % branches keep showing the thickness.
                    %
                    % 3D does divide, and should: its partitions share one slab, so the
                    % thickness follows the reconstructed partition count, which is the
                    % matrix size the GUI is meant to be able to set.
                    %
                    % sliceSeparation is not set here at all any more. Nothing reads it
                    % since the exporters moved to proud.util.sliceSpacing, and dividing
                    % it by the interleave is what put 1.099535 into the 19046 export
                    % where the scanner says 0.70.
                    switch app.pd.dataType

                        case {"3D","3Dute","ZTE"}
                            app.pd.sliceThickness = app.FOVViewField3.Value/size(app.pd.images,3);

                    end
                    app.pd.sliceInterleave = 1;
                    app.pd.FOV = app.FOVViewField1.Value;
                    app.pd.noSlicesDcm = size(app.pd.images,3);
                    % app.pd.noViews = size(app.pd.images,2);
                    % app.pd.noViews2 = size(app.pd.images,3);
                    app.pd.fieldStrength = 7;
                    app.pd.filename = 111;
                    app.CalcTotalAcquisitionTimeFcn;
                    app.pd.acqdur = app.pd.totalAcqTime;
                    app.pd.timeperframe = app.pd.totalAcqTime/app.NREditField.Value;
                    app.pd.nrFrames = app.NREditField.Value;

                    % Search for dicom file
                    app.TextMessage('DICOM export ...',1);

                    % Assuming that MRD and Data directory share the same base-directory
                    % Example: base / MRD / 343 / 8455 /

                    % Base / data / 343 / DICOM / 8455 /
                    dcmSpath{1} = replace(app.mrdImportPath,"MRD","Data");
                    try
                        k = strfind(dcmSpath{1},"Data");
                        fs = strfind(dcmSpath{1}(k:end),filesep);
                        dcmSpath{1}= dcmSpath{1}(1:k+fs(3)-1);
                        dcmSpath{1} = insertAfter(dcmSpath{1},k+fs(2)-1,strcat("DICOM",filesep));
                        dcmIndx{1} = 2;
                    catch
                        dcmSpath{1} = app.mrdImportPath;
                    end

                    % Base / data / 343 / DICOM / 8455 / 1 /
                    dcmSpath{2} = strcat(dcmSpath{1},'1',filesep);
                    dcmIndx{2} = 3;

                    % Base / data / 343 / DICOM / 8455 / 2 /
                    dcmSpath{3} = strcat(dcmSpath{1},'2',filesep);
                    dcmIndx{3} = 3;

                    % Base / data / 343 / DICOM / 8455 / 3 /
                    dcmSpath{4} = strcat(dcmSpath{1},'3',filesep);
                    dcmIndx{4} = 3;

                    % Check if one of the directories contains dicom files
                    dcmExist = false;
                    for i=1:length(dcmSpath)
                        if ~isempty(dir(strcat(dcmSpath{i},"*.dcm")))
                            dcmPath = dcmSpath{i};
                            app.TextMessage(strcat("Found DICOM information in ",dcmPath, " ..."),1);
                            filesepIdx = strfind(dcmPath, filesep);
                            app.dicomExportPath = dcmPath(1:filesepIdx(end-dcmIndx{i}));
                            dcmExist = true;
                            break;
                        end
                    end

                    if dcmExist

                        % Export, reading dicom information from dcmPath
                        try
                            % Check if directory is actually writable
                            mkdir(strcat(app.dicomExportPath,filesep,"testdir"));
                            rmdir(strcat(app.dicomExportPath,filesep,"testdir"));
                        catch
                            app.P2ROUD_UIFigure.Visible = 'off';
                            app.dicomExportPath = uigetdir(app.dicomExportPath,'DICOM export path');
                            if ~app.closeRequested
                                app.P2ROUD_UIFigure.Visible = 'on';
                            end
                            drawnow;
                            if ~ischar(app.dicomExportPath)
                                ME = MException('P2ROUD:userCancel','User cancel ...');
                                throw(ME);
                            end
                        end

                        % Export with image orientation information
                        app.pd.exportPath = proud.io.exportDicomDCM(app.pd, gatherRecoParams(app), AppReporter(app,'export'), dcmPath);
                        app.TextMessage(strcat("DICOM export path = ",app.pd.exportPath," ..."),1);
                        app.pd.ExportRecoParametersFcn(gatherRecoParams(app));

                    else

                        % Ask for a dicom export directory
                        app.P2ROUD_UIFigure.Visible = 'off';
                        app.dicomExportPath = uigetdir(app.dicomExportPath,'DICOM export path');
                        if ~app.closeRequested
                            app.P2ROUD_UIFigure.Visible = 'on';
                        end
                        drawnow;
                        if ~ischar(app.dicomExportPath)
                            ME = MException('P2ROUD:userCancel','User cancel ...');
                            throw(ME);
                        end

                        % Export without image orientation information
                        app.pd.exportPath = proud.io.exportDicomMRD(app.pd, gatherRecoParams(app), AppReporter(app,'export'), app.tag);
                        app.TextMessage(strcat("DICOM export path = ",app.pd.exportPath," ..."),1);
                        app.pd.ExportRecoParametersFcn(gatherRecoParams(app));

                    end

                    % Try additional nifti export (beta)
                    try
                        app.pd.exportPath = proud.io.exportNiftiFcn(app.pd, gatherRecoParams(app), AppReporter(app,'export'));
                        app.pd.ExportRecoParametersFcn(gatherRecoParams(app));
                    catch ME
                        app.TextMessage(ME.message,2);
                        app.TextMessage("ERROR: NIfti export failed ...",1);
                    end

                    % Success
                    app.TextMessage('Ready ...');
                    app.SetStatus(0);
                    app.SetLightsFcn({'Null','Null','Green','Null','Null','Null'});
                    SetButtonsFcn(app,{'On','On','On','On','On','On','On'});
                    app.EnableLabelsFcn;

                catch ME

                    % Failure
                    app.TextMessage(ME.message,2);
                    app.TextMessage('ERROR: Something went wrong during DICOM export ...',1);
                    app.dicomExportPath = '';
                    app.SetStatus(2);
                    app.SetLightsFcn({'Null','Null','Red','Null','Null','Null'});
                    SetButtonsFcn(app,{'On','On','On','On','On','On','On'});
                    app.EnableLabelsFcn;
                end

            end

        end

        % Button pushed function: MRDButton
        function MRDButtonPushed(app, event)

            % Purpose:
            %   Export the reconstruction as a new MRD file, with its RPR footer.
            %
            % How it works:
            %   Transforms the images back to k-space, builds a matching footer, and writes
            %   both to a file named p2roud_<n>_<tag>.MRD, the counter rising until the name
            %   is free. The import folder is used when it is writable, otherwise the user
            %   picks one. On Windows the scanner's own reconstruction is then driven to
            %   produce SUR files: the image directory comes from the registry, or is derived
            %   from the MRD path when the registry has nothing, and NRLUT data has its SUR
            %   files converted to DICOM afterwards. Each of those steps warns rather than
            %   fails the export.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, files are written and pd.exportPath is set.

            try

                % Set buttons
                app.SwitchOffAllButtonsFcn;
                app.SetLightsFcn({'Null','Null','Null','Null','Yellow','Null'});
                app.DisableLabelsFcn;
                app.SetStatus(3);

                % Indicate busy
                app.TextMessage('MRD file export ...',1);

                % Make a k-space from the movie
                % Dimensions = (samples, views, views2, slices, echoes (frames), experiments, flip-angles)
                app.pd = app.pd.backToKspace;

                % Parameters for mrd footer
                par = proud.io.replaceMRDparameters(app.pd, gatherRecoParams(app));

                % Construct a new footer for the MRD file
                app.pd = app.pd.makeMrdFooter(par);

                % Construct a new rpr file if input rpr file was available
                if app.pd.rprFileFlag
                    app.pd = app.pd.makeRprFile(par);
                end

                % Auto export the new MRD and rpr files
                app.mrdExportPath = app.mrdImportPath;

                % Check if directory is actually writable, else ask for different folder
                try
                    mkdir(strcat(app.mrdExportPath,filesep,"testdir"));
                    rmdir(strcat(app.mrdExportPath,filesep,"testdir"));
                catch
                    app.P2ROUD_UIFigure.Visible = 'off';
                    app.mrdExportPath = uigetdir(app.mrdExportPath,'MRD export folder');
                    if ~app.closeRequested
                        app.P2ROUD_UIFigure.Visible = 'on';
                    end
                    if ~ischar(app.mrdExportPath)
                        ME = MException('P2ROUD:userCancel','User cancel ...');
                        throw(ME);
                    end
                end

                % Make a unique file name
                if contains(app.tag,'p2roud')
                    k = strfind(app.tag,'_');
                    app.tag = extractAfter(app.tag,k(2));
                end
                cnt = 1;
                while true
                    mrdfilename = ['p2roud_',num2str(cnt),'_',app.tag,'.MRD'];
                    rprfilename = ['p2roud_',num2str(cnt),'_',app.tag,'.rpr'];
                    mrdexportfilename = [app.mrdExportPath,mrdfilename];
                    rprexportfilename = [app.mrdExportPath,rprfilename];
                    if ~isfile(mrdexportfilename)
                        break;
                    end
                    cnt = cnt + 1;
                end

                % Try the export in the MRD input directory
                app.pd.writeDataToMrd(mrdexportfilename, par);
                app.pd.exportPath = app.mrdExportPath;
                app.pd.ExportRecoParametersFcn(gatherRecoParams(app));
                if app.pd.rprFileFlag
                    app.pd.writeToRprFile(rprexportfilename);
                end

                % Backup rtable file
                if isfile(app.rtableFile)
                    [rtablePath,rtableName,rtableExt] = fileparts(app.rtableFile);
                    if rtableName == "rtable"
                        movefile(strcat(rtablePath,filesep,rtableName,rtableExt),strcat(rtablePath,filesep,'origrtable.rtv.orig'));
                        delete(strcat(rtablePath,filesep,rtableName,rtableExt));
                    end
                end

                % Try to autoreconstruct and export of SUR files, only try when platform is pc
                if ispc

                    % Determine SUR directory
                    surPathFound = false;
                    try

                        scanPos = strfind(app.mrdImportPath,filesep);
                        scanTag = strcat(app.mrdImportPath(scanPos(end-2)+1:scanPos(end-1)-1),filesep,'Image',filesep,app.mrdImportPath(scanPos(end-1)+1:scanPos(end)-1));
                        surPath = winqueryreg('HKEY_LOCAL_MACHINE','Software\MRS\MRSClinicalUI\Scan','BaseStorageDirectory');
                        surPath1 = strcat(surPath,filesep,scanTag,filesep);
                        surPath2 = strcat(surPath,filesep,scanTag,filesep,'1',filesep);
                        % Check if directory is actually writable
                        mkdir(strcat(surPath2,filesep,"testdir"));
                        rmdir(strcat(surPath2,filesep,"testdir"));
                        app.TextMessage('Image directory found in Windows registry ...',1);
                        surPathFound = true;

                    catch
                    end

                    % If not found automatically, try different way
                    if ~surPathFound

                        try

                            surPath = replace(app.mrdImportPath,"MRD","Data");
                            k = strfind(surPath,"Data");
                            fs = strfind(surPath(k:end),filesep);
                            surPath = surPath(1:k+fs(3)-1);
                            imageSlash = strcat("Image",filesep);
                            surPath1 = insertAfter(surPath,k+fs(2)-1,imageSlash);
                            surPath1 = strrep(surPath1,dblSlash,filesep);
                            surPath2 = strcat(surPath1,filesep,'1',filesep);
                            surPath2 = strrep(surPath2,dblSlash,filesep);
                            % Check if directory is actually writable
                            mkdir(strcat(surPath2,filesep,"testdir"));
                            rmdir(strcat(surPath2,filesep,"testdir"));
                            surPathFound = true;

                        catch
                        end

                    end

                    if surPathFound

                        try

                            surPath = surPath1;
                            if (exist(surPath2, 'dir'))
                                surPath = surPath2;
                            end

                            app.TextMessage(strcat("SUR directory = ",surPath," ..."),1);

                            % SUR file names
                            sursuffix = app.tag;

                            % Make and save the new SUR files
                            app.pd.recoSurFiles(surPath,sursuffix,mrdexportfilename,rprexportfilename);

                        catch ME1

                            app.TextMessage(ME1.message,2);
                            app.TextMessage('WARNING: Automatic reconstruction of SUR files failed ...',1);
                            app.SetStatus(1);

                        end

                        if app.pd.trajType == "NRLUT"

                            try

                                % Create new DCM files from SUR files
                                app.pd.surToDcm(gatherRecoParams(app), surPath);

                            catch ME2

                                app.TextMessage(ME2.message,2);
                                app.TextMessage('WARNING: Automatic reconstruction of DCM files failed ...',1);
                                app.SetStatus(1);

                            end

                        end

                    end

                end

                % Success
                app.TextMessage('MRD export successfully completed ...',1);
                app.SetLightsFcn({'Null','Null','Null','Null','Green','Null'});
                SetButtonsFcn(app,{'On','On','On','On','On','On','On'});
                app.EnableLabelsFcn;
                app.SetStatus(0);

            catch ME

                % Failures
                app.TextMessage(ME.message,2);
                app.TextMessage('ERROR: Something went wrong during MRD export ...',1);
                app.mrdExportPath = '';
                app.SetLightsFcn({'Null','Null','Null','Null','Red','Null'});
                SetButtonsFcn(app,{'On','On','On','On','On','On','On'});
                app.EnableLabelsFcn;
                app.SetStatus(2);

            end

        end

        % Button pushed function: GIFButton
        function GIFButtonPushed(app, event)

            % Purpose:
            %   Export the reconstructed images as animated GIF files.
            %
            % How it works:
            %   Writes into a GIF folder beside the study data, asking the user for a folder
            %   when that path is not writable, then hands the work to exportGifProud.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, files are written and pd.exportPath is set.

            if app.pd.validRecoFlag

                try

                    % Set buttons
                    app.SwitchOffAllButtonsFcn;
                    app.SetLightsFcn({'Null','Null','Null','Null','Null','Yellow'});
                    app.DisableLabelsFcn;
                    app.SetStatus(3);

                    % Indicate busy
                    app.SetStatus(3);
                    app.TextMessage('Exporting to GIF ...',1);

                    % Make base / data / study / GIF / directory
                    try
                        gifExportDir = replace(app.mrdImportPath, "MRD", "Data");
                        k = strfind(gifExportDir,"Data");
                        fs = strfind(gifExportDir(k:end),filesep);
                        gifExportDir = gifExportDir(1:k+fs(2)-1);
                        % Check if directory is actually writable
                        mkdir(strcat(gifExportDir,filesep,"testdir"));
                        rmdir(strcat(gifExportDir,filesep,"testdir"));
                    catch
                        app.P2ROUD_UIFigure.Visible = 'off';
                        gifExportDir = uigetdir(app.gifExportPath,'GIF export path');
                        if ~app.closeRequested
                            app.P2ROUD_UIFigure.Visible = 'on';
                        end
                        drawnow;
                        if ~ischar(gifExportDir)
                            ME = MException('PROUD:userCancel','User cancel ...');
                            throw(ME);
                        end
                    end
                    app.gifExportPath = gifExportDir;

                    gifExportDir= proud.io.exportGifProud(app.pd, gatherRecoParams(app), AppReporter(app,'export'));
                    app.pd.exportPath = gifExportDir;
                    app.pd.ExportRecoParametersFcn(gatherRecoParams(app));

                    app.TextMessage('GIF export finished ...',1);
                    app.SetLightsFcn({'Null','Null','Null','Null','Null','Green'});
                    SetButtonsFcn(app,{'On','On','On','On','On','On','On'});
                    app.EnableLabelsFcn;
                    app.SetStatus(0);

                catch ME

                    app.TextMessage('ERROR: Something went wrong during GIF export ...',1);
                    app.TextMessage(ME.message,2);
                    app.gifExportPath = '';
                    app.SetLightsFcn({'Null','Null','Null','Null','Null','Red'});
                    SetButtonsFcn(app,{'On','On','On','On','On','On','On'});
                    app.EnableLabelsFcn;
                    app.SetStatus(2);

                end

            end

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)

            % Purpose:
            %   Close the app and leave the MATLAB session as it was found.
            %
            % How it works:
            %   Hands over to CloseAppFcn, which the window's close box uses as well. It
            %   removes the app folders from the search path and returns to the starting
            %   directory, closes the log file, restores the warning state and deletes the
            %   app, and first lets a running reconstruction or movie stop.
            %
            % Inputs:
            %   app - the running P2ROUD app
            %
            % Output:
            %   none, the app closes.

            app.CloseAppFcn;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.P2ROUD_UIFigure = uifigure('Visible', 'off');
            app.P2ROUD_UIFigure.Icon = fullfile(pathToMLAPP, 'Picon.png');
            app.P2ROUD_UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @P2ROUD_UIFigureWindowScrollWheel, true);
            app.TitleLabel = uilabel(app.P2ROUD_UIFigure);
            app.TitleLabel.Interpreter = 'html';
            app.TitleLabel.Text = 'P<sup>2</sup>ROUD';
            app.TulpImage = uiimage(app.P2ROUD_UIFigure);
            app.CreditsLabel = uilabel(app.P2ROUD_UIFigure);
            app.CreditsLabel.Tooltip = {'g.j.strijkers@amsterdamumc.nl'};
            app.CreditsLabel.Text = 'Gustav Strijkers, Bram Coolen, Amsterdam UMC, Version 6.0.0';
            app.MRSImage = uiimage(app.P2ROUD_UIFigure);
            app.P2ROUDLabel = uilabel(app.P2ROUD_UIFigure);
            app.P2ROUDLabel.Text = 'Preclinical PROspective Undersampling in multiple Dimensions';
            app.DashboardTabGroup = uitabgroup(app.P2ROUD_UIFigure);
            app.DashboardTab = uitab(app.DashboardTabGroup);
            app.DashboardTab.Title = 'Dashboard';
            app.LoadMRDButton = uibutton(app.DashboardTab, 'push');
            app.LoadMRDButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMRDButtonPushed, true);
            app.LoadMRDButton.Enable = 'off';
            app.LoadMRDButton.Tooltip = {'Load MRD'};
            app.LoadMRDButton.Text = 'Load MRD';
            app.Lamp1 = uilamp(app.DashboardTab);
            app.ReconstructionDimensionsPanel = uipanel(app.DashboardTab);
            app.ReconstructionDimensionsPanel.Tooltip = {'Dimensions'};
            app.ReconstructionDimensionsPanel.Title = 'Dimensions';
            app.XEditField = uieditfield(app.ReconstructionDimensionsPanel, 'numeric');
            app.XEditField.Limits = [16 1024];
            app.XEditField.RoundFractionalValues = 'on';
            app.XEditField.ValueDisplayFormat = '%.0f';
            app.XEditField.Tooltip = {'Readout dimension'};
            app.XEditField.Value = 128;
            app.YEditField = uieditfield(app.ReconstructionDimensionsPanel, 'numeric');
            app.YEditField.Limits = [16 1024];
            app.YEditField.RoundFractionalValues = 'on';
            app.YEditField.ValueDisplayFormat = '%.0f';
            app.YEditField.Tooltip = {'Phase encoding dimension'};
            app.YEditField.Value = 128;
            app.ZEditField = uieditfield(app.ReconstructionDimensionsPanel, 'numeric');
            app.ZEditField.Limits = [1 1024];
            app.ZEditField.RoundFractionalValues = 'on';
            app.ZEditField.ValueDisplayFormat = '%.0f';
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @RecoSizeFieldValueChanged, true);
            app.ZEditField.Tooltip = {'Slice dimension / 2nd phase-encoding dimension'};
            app.ZEditField.Value = 128;
            app.NREditField = uieditfield(app.ReconstructionDimensionsPanel, 'numeric');
            app.NREditField.Limits = [1 1024];
            app.NREditField.RoundFractionalValues = 'on';
            app.NREditField.ValueDisplayFormat = '%.0f';
            app.NREditField.ValueChangedFcn = createCallbackFcn(app, @NREditFieldValueChanged, true);
            app.NREditField.Tooltip = {'Number of dynamics/repetitions'};
            app.NREditField.Value = 1;
            app.XEditFieldLabel = uilabel(app.ReconstructionDimensionsPanel);
            app.XEditFieldLabel.Text = 'X';
            app.YEditFieldLabel = uilabel(app.ReconstructionDimensionsPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.ZEditFieldLabel = uilabel(app.ReconstructionDimensionsPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.NREditFieldLabel = uilabel(app.ReconstructionDimensionsPanel);
            app.NREditFieldLabel.Text = 'NR';
            app.ReconstructionParametersPanel = uipanel(app.DashboardTab);
            app.ReconstructionParametersPanel.Tooltip = {'Reconstruction'};
            app.ReconstructionParametersPanel.Title = 'Reconstruction';
            app.RecoButton = uibutton(app.ReconstructionParametersPanel, 'push');
            app.RecoButton.ButtonPushedFcn = createCallbackFcn(app, @RecoImagesButtonPushed, true);
            app.RecoButton.Enable = 'off';
            app.RecoButton.Tooltip = {'Reconstruct the images'};
            app.RecoButton.Text = 'Reco';
            app.Lamp2 = uilamp(app.ReconstructionParametersPanel);
            app.RecoProgressGauge = uigauge(app.ReconstructionParametersPanel, 'linear');
            app.RecoProgressGauge.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95.1];
            app.AbortRecoButton = uibutton(app.ReconstructionParametersPanel, 'push');
            app.AbortRecoButton.ButtonPushedFcn = createCallbackFcn(app, @AbortRecoButtonPushed, true);
            app.AbortRecoButton.Enable = 'off';
            app.AbortRecoButton.Tooltip = {'Abort the reconstruction'};
            app.AbortRecoButton.Text = 'abort';
            app.CSRecoCheckBox = uicheckbox(app.ReconstructionParametersPanel);
            app.CSRecoCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.CSRecoCheckBox.Tooltip = {'Check for compressed sensing reconstruction'};
            app.CSRecoCheckBox.Text = '';
            app.CSRecoLabel = uilabel(app.ReconstructionParametersPanel);
            app.CSRecoLabel.Tooltip = {'Compressed-sensing reconstruction'};
            app.CSRecoLabel.Text = 'CS reco';
            app.ExportPanel = uipanel(app.DashboardTab);
            app.ExportPanel.Tooltip = {'Export images'};
            app.ExportPanel.Title = 'Export';
            app.DCMNIFTIButton = uibutton(app.ExportPanel, 'push');
            app.DCMNIFTIButton.ButtonPushedFcn = createCallbackFcn(app, @DCMNIFTIButtonPushed, true);
            app.DCMNIFTIButton.Enable = 'off';
            app.DCMNIFTIButton.Tooltip = {'Export to DICOM & NIFTI'};
            app.DCMNIFTIButton.Text = 'DCM / NIFTI';
            app.ExportProgressGauge = uigauge(app.ExportPanel, 'linear');
            app.ExportProgressGauge.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.MRDButton = uibutton(app.ExportPanel, 'push');
            app.MRDButton.ButtonPushedFcn = createCallbackFcn(app, @MRDButtonPushed, true);
            app.MRDButton.Enable = 'off';
            app.MRDButton.Tooltip = {'Export to MRD'};
            app.MRDButton.Text = 'MRD';
            app.GIFButton = uibutton(app.ExportPanel, 'push');
            app.GIFButton.ButtonPushedFcn = createCallbackFcn(app, @GIFButtonPushed, true);
            app.GIFButton.Enable = 'off';
            app.GIFButton.Tooltip = {'Export to GIF'};
            app.GIFButton.Text = 'GIF';
            app.Lamp3 = uilamp(app.ExportPanel);
            app.Lamp5 = uilamp(app.ExportPanel);
            app.Lamp6 = uilamp(app.ExportPanel);
            app.ExitButton = uibutton(app.DashboardTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Tooltip = {'Exit the app'};
            app.ExitButton.Text = '    Exit';
            app.KspaceTabGroup = uitabgroup(app.P2ROUD_UIFigure);
            app.KspaceTab = uitab(app.KspaceTabGroup);
            app.KspaceTab.Title = 'K-space';
            app.KspaceFig = uiaxes(app.KspaceTab);
            app.KspaceFig.XTick = [];
            app.KspaceFig.YTick = [];
            app.KDim3EditField = uieditfield(app.KspaceTab, 'numeric');
            app.KDim3EditField.Limits = [1 Inf];
            app.KDim3EditField.ValueDisplayFormat = '%.0f';
            app.KDim3EditField.ValueChangedFcn = createCallbackFcn(app, @KDim3EditFieldValueChanged, true);
            app.KDim3EditField.Value = 1;
            app.KDim3Slider = uislider(app.KspaceTab);
            app.KDim3Slider.Limits = [1 200];
            app.KDim3Slider.MajorTicks = [];
            app.KDim3Slider.ValueChangedFcn = createCallbackFcn(app, @KDim3SliderValueChanged, true);
            app.KDim3Slider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.KDim3Slider.Value = 1;
            app.KDim4Slider = uislider(app.KspaceTab);
            app.KDim4Slider.Limits = [1 200];
            app.KDim4Slider.MajorTicks = [];
            app.KDim4Slider.ValueChangedFcn = createCallbackFcn(app, @KDim4SliderValueChanged, true);
            app.KDim4Slider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.KDim4Slider.Value = 1;
            app.KDim4EditField = uieditfield(app.KspaceTab, 'numeric');
            app.KDim4EditField.Limits = [1 Inf];
            app.KDim4EditField.ValueDisplayFormat = '%.0f';
            app.KDim4EditField.ValueChangedFcn = createCallbackFcn(app, @KDim4EditFieldValueChanged, true);
            app.KDim4EditField.Value = 1;
            app.KxLabel = uilabel(app.KspaceTab);
            app.KxLabel.Text = '     Kx     ';
            app.KyLabel = uilabel(app.KspaceTab);
            app.KyLabel.Text = '     Ky     ';
            app.ButtonGroup = uibuttongroup(app.KspaceTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.kValuesButton = uiradiobutton(app.ButtonGroup);
            app.kValuesButton.Tooltip = {'Check to display K-space'};
            app.kValuesButton.Text = '| k-values |';
            app.kValuesButton.Interpreter = 'html';
            app.kValuesButton.Value = true;
            app.averagesButton = uiradiobutton(app.ButtonGroup);
            app.averagesButton.Tooltip = {'Check to display K-space averages'};
            app.averagesButton.Text = 'averages';
            app.averagesButton.Interpreter = 'html';
            app.fillingButton = uiradiobutton(app.ButtonGroup);
            app.fillingButton.Tooltip = {'Check to display K-space filling'};
            app.fillingButton.Text = 'filling';
            app.fillingButton.Interpreter = 'html';
            app.senseMapButton = uiradiobutton(app.ButtonGroup);
            app.senseMapButton.Tooltip = {'Check to display K-space'};
            app.senseMapButton.Text = 'sense map';
            app.senseMapButton.Interpreter = 'html';
            app.KDim3Label = uilabel(app.KspaceTab);
            app.KDim3Label.Text = '            Kz';
            app.KEchoSlider = uislider(app.KspaceTab);
            app.KEchoSlider.Limits = [1 200];
            app.KEchoSlider.MajorTicks = [];
            app.KEchoSlider.ValueChangedFcn = createCallbackFcn(app, @KEchoSliderValueChanged, true);
            app.KEchoSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.KEchoSlider.Value = 1;
            app.KDim4Label = uilabel(app.KspaceTab);
            app.KDim4Label.Text = 'R';
            app.KFlipAngleSlider = uislider(app.KspaceTab);
            app.KFlipAngleSlider.Limits = [1 200];
            app.KFlipAngleSlider.MajorTicks = [];
            app.KFlipAngleSlider.ValueChangedFcn = createCallbackFcn(app, @KFlipAngleSliderValueChanged, true);
            app.KFlipAngleSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.KFlipAngleSlider.Value = 1;
            app.FillingViewField = uieditfield(app.KspaceTab, 'numeric');
            app.FillingViewField.Editable = 'off';
            app.FillingViewField.Tooltip = {'K-space filling percentage'};
            app.FillingPercLabel = uilabel(app.KspaceTab);
            app.FillingPercLabel.Text = '%';
            app.KStartButton = uibutton(app.KspaceTab, 'push');
            app.KStartButton.ButtonPushedFcn = createCallbackFcn(app, @KStartButtonPushed, true);
            app.KStartButton.Tooltip = {'Start the K-space trajectory movie'};
            app.KStartButton.Text = 'Start';
            app.KStopButton = uibutton(app.KspaceTab, 'push');
            app.KStopButton.ButtonPushedFcn = createCallbackFcn(app, @KStopButtonPushed, true);
            app.KStopButton.Tooltip = {'Stop the K-space trajectory movie'};
            app.KStopButton.Text = 'Stop';
            app.KspaceMovieLabel = uilabel(app.KspaceTab);
            app.KspaceMovieLabel.Text = {'K-space'; 'movie'};
            app.FastSlowSwitch = uiswitch(app.KspaceTab, 'rocker');
            app.FastSlowSwitch.Items = {'fast', 'slow'};
            app.FastSlowSwitch.ValueChangedFcn = createCallbackFcn(app, @FastSlowSwitchValueChanged, true);
            app.FastSlowSwitch.Tooltip = {'Slow or fast K-space movie'};
            app.FastSlowSwitch.Value = 'fast';
            app.KLevelSlider = uislider(app.KspaceTab);
            app.KLevelSlider.Limits = [0 65536];
            app.KLevelSlider.MajorTicks = [];
            app.KLevelSlider.ValueChangedFcn = createCallbackFcn(app, @KLevelSliderValueChanged, true);
            app.KLevelSlider.MinorTicks = [0 8192 16384 24576 32768 40960 49152 57344 65536];
            app.KLevelSlider.Tooltip = {'Level slider'};
            app.KWLabel = uilabel(app.KspaceTab);
            app.KWLabel.Text = 'W';
            app.KLLabel = uilabel(app.KspaceTab);
            app.KLLabel.Text = 'L';
            app.KWindowSlider = uislider(app.KspaceTab);
            app.KWindowSlider.Limits = [0 65536];
            app.KWindowSlider.MajorTicks = [];
            app.KWindowSlider.ValueChangedFcn = createCallbackFcn(app, @KWindowSliderValueChanged, true);
            app.KWindowSlider.MinorTicks = [0 8192 16384 24576 32768 40960 49152 57344 65536];
            app.KWindowSlider.Tooltip = {'Window slider'};
            app.KLevelEditField = uieditfield(app.KspaceTab, 'numeric');
            app.KLevelEditField.Limits = [0 Inf];
            app.KLevelEditField.ValueDisplayFormat = '%.0f';
            app.KLevelEditField.ValueChangedFcn = createCallbackFcn(app, @KLevelEditFieldValueChanged, true);
            app.KLevelEditField.Tooltip = {'Level'};
            app.KWindowEditField = uieditfield(app.KspaceTab, 'numeric');
            app.KWindowEditField.Limits = [0 Inf];
            app.KWindowEditField.ValueDisplayFormat = '%.0f';
            app.KWindowEditField.ValueChangedFcn = createCallbackFcn(app, @KWindowEditFieldValueChanged, true);
            app.KWindowEditField.Tooltip = {'Window'};
            app.KEchoEditFieldLabel = uilabel(app.KspaceTab);
            app.KEchoEditFieldLabel.Text = 'E';
            app.KEchoEditField = uieditfield(app.KspaceTab, 'numeric');
            app.KEchoEditField.Limits = [1 Inf];
            app.KEchoEditField.ValueDisplayFormat = '%.0f';
            app.KEchoEditField.ValueChangedFcn = createCallbackFcn(app, @KEchoEditFieldValueChanged, true);
            app.KEchoEditField.Value = 1;
            app.KOrientationSpinnerLabel = uilabel(app.KspaceTab);
            app.KOrientationSpinnerLabel.Text = 'orientation';
            app.KOrientationSpinner = uispinner(app.KspaceTab);
            app.KOrientationSpinner.Limits = [1 6];
            app.KOrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @KOrientationSpinnerValueChanged, true);
            app.KOrientationSpinner.Tooltip = {'Image orientation'};
            app.KOrientationSpinner.Value = 1;
            app.KFlipAngleEditFieldLabel = uilabel(app.KspaceTab);
            app.KFlipAngleEditFieldLabel.Text = 'FA';
            app.KFlipAngleEditField = uieditfield(app.KspaceTab, 'numeric');
            app.KFlipAngleEditField.Limits = [1 Inf];
            app.KFlipAngleEditField.ValueDisplayFormat = '%.0f';
            app.KFlipAngleEditField.ValueChangedFcn = createCallbackFcn(app, @KFlipAngleEditFieldValueChanged, true);
            app.KFlipAngleEditField.Value = 1;
            app.CoilSpinnerLabel = uilabel(app.KspaceTab);
            app.CoilSpinnerLabel.Text = 'Coil';
            app.CoilSpinner = uispinner(app.KspaceTab);
            app.CoilSpinner.Limits = [1 16];
            app.CoilSpinner.ValueDisplayFormat = '%.0f';
            app.CoilSpinner.ValueChangedFcn = createCallbackFcn(app, @CoilSpinnerValueChanged, true);
            app.CoilSpinner.Tooltip = {'Receiver coil'};
            app.CoilSpinner.Value = 1;
            app.CoilsTab = uitab(app.KspaceTabGroup);
            app.CoilsTab.Title = 'Coils';
            app.CoilSelectionPanel = uipanel(app.CoilsTab);
            app.CoilSelectionPanel.Tooltip = {'Receiver coil selection and relative sensitivities'};
            app.CoilSelectionPanel.Title = 'Coil selection';
            app.C1CheckBox = uicheckbox(app.CoilSelectionPanel);
            app.C1CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C1CheckBox.Text = ' 1';
            app.C1CheckBox.Value = true;
            app.C2CheckBox = uicheckbox(app.CoilSelectionPanel);
            app.C2CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C2CheckBox.Text = ' 2';
            app.C3CheckBox = uicheckbox(app.CoilSelectionPanel);
            app.C3CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C3CheckBox.Text = ' 3';
            app.C4CheckBox = uicheckbox(app.CoilSelectionPanel);
            app.C4CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C4CheckBox.Text = ' 4';
            app.C5CheckBox = uicheckbox(app.CoilSelectionPanel);
            app.C5CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C5CheckBox.Text = ' 5';
            app.C6CheckBox = uicheckbox(app.CoilSelectionPanel);
            app.C6CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C6CheckBox.Text = ' 6';
            app.C7CheckBox = uicheckbox(app.CoilSelectionPanel);
            app.C7CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C7CheckBox.Text = ' 7';
            app.C8CheckBox = uicheckbox(app.CoilSelectionPanel);
            app.C8CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C8CheckBox.Text = ' 8';
            app.S1EditField = uieditfield(app.CoilSelectionPanel, 'numeric');
            app.S1EditField.Limits = [0.01 1];
            app.S1EditField.ValueDisplayFormat = '%.3f';
            app.S1EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S1EditField.Value = 1;
            app.S2EditField = uieditfield(app.CoilSelectionPanel, 'numeric');
            app.S2EditField.Limits = [0.01 1];
            app.S2EditField.ValueDisplayFormat = '%.3f';
            app.S2EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S2EditField.Value = 1;
            app.S3EditField = uieditfield(app.CoilSelectionPanel, 'numeric');
            app.S3EditField.Limits = [0.01 1];
            app.S3EditField.ValueDisplayFormat = '%.3f';
            app.S3EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S3EditField.Value = 1;
            app.S4EditField = uieditfield(app.CoilSelectionPanel, 'numeric');
            app.S4EditField.Limits = [0.01 1];
            app.S4EditField.ValueDisplayFormat = '%.3f';
            app.S4EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S4EditField.Value = 1;
            app.S5EditField = uieditfield(app.CoilSelectionPanel, 'numeric');
            app.S5EditField.Limits = [0.01 1];
            app.S5EditField.ValueDisplayFormat = '%.3f';
            app.S5EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S5EditField.Value = 1;
            app.S6EditField = uieditfield(app.CoilSelectionPanel, 'numeric');
            app.S6EditField.Limits = [0.01 1];
            app.S6EditField.ValueDisplayFormat = '%.3f';
            app.S6EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S6EditField.Value = 1;
            app.S7EditField = uieditfield(app.CoilSelectionPanel, 'numeric');
            app.S7EditField.Limits = [0.01 1];
            app.S7EditField.ValueDisplayFormat = '%.3f';
            app.S7EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S7EditField.Value = 1;
            app.S8EditField = uieditfield(app.CoilSelectionPanel, 'numeric');
            app.S8EditField.Limits = [0.01 1];
            app.S8EditField.ValueDisplayFormat = '%.3f';
            app.S8EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S8EditField.Value = 1;
            app.CoilLabel = uilabel(app.CoilSelectionPanel);
            app.CoilLabel.Text = 'Coil';
            app.RelativeSensitivityLabel = uilabel(app.CoilSelectionPanel);
            app.RelativeSensitivityLabel.Text = 'Relative sensitivity';
            app.AutoSensitivityCheckBox = uicheckbox(app.CoilSelectionPanel);
            app.AutoSensitivityCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoSensitivityCheckBoxValueChanged, true);
            app.AutoSensitivityCheckBox.Tooltip = {'Automatically determine coil sensitivities (requires Bart toolbox)'};
            app.AutoSensitivityCheckBox.Text = ' auto sensitivity';
            app.AutoSensitivityCheckBox.Value = true;
            app.SensitivityMapsPanel = uipanel(app.CoilsTab);
            app.SensitivityMapsPanel.Tooltip = {'Receiver coil selection and relative sensitivities'};
            app.SensitivityMapsPanel.Title = 'Sensitivity maps';
            app.SenseFig1 = uiaxes(app.SensitivityMapsPanel);
            app.SenseFig1.XTick = [];
            app.SenseFig1.YTick = [];
            app.SenseFig2 = uiaxes(app.SensitivityMapsPanel);
            app.SenseFig2.XTick = [];
            app.SenseFig2.YTick = [];
            app.SenseFig3 = uiaxes(app.SensitivityMapsPanel);
            app.SenseFig3.XTick = [];
            app.SenseFig3.YTick = [];
            app.SenseFig4 = uiaxes(app.SensitivityMapsPanel);
            app.SenseFig4.XTick = [];
            app.SenseFig4.YTick = [];
            app.SenseFig5 = uiaxes(app.SensitivityMapsPanel);
            app.SenseFig5.XTick = [];
            app.SenseFig5.YTick = [];
            app.SenseFig6 = uiaxes(app.SensitivityMapsPanel);
            app.SenseFig6.XTick = [];
            app.SenseFig6.YTick = [];
            app.SenseFig7 = uiaxes(app.SensitivityMapsPanel);
            app.SenseFig7.XTick = [];
            app.SenseFig7.YTick = [];
            app.SenseFig8 = uiaxes(app.SensitivityMapsPanel);
            app.SenseFig8.XTick = [];
            app.SenseFig8.YTick = [];
            app.Label2 = uilabel(app.SensitivityMapsPanel);
            app.Label2.Text = '2';
            app.Label3 = uilabel(app.SensitivityMapsPanel);
            app.Label3.Text = '3';
            app.Label4 = uilabel(app.SensitivityMapsPanel);
            app.Label4.Text = '4';
            app.Label6 = uilabel(app.SensitivityMapsPanel);
            app.Label6.Text = '6';
            app.Label7 = uilabel(app.SensitivityMapsPanel);
            app.Label7.Text = '7';
            app.Label8 = uilabel(app.SensitivityMapsPanel);
            app.Label8.Text = '8';
            app.Label1 = uilabel(app.SensitivityMapsPanel);
            app.Label1.Text = '1';
            app.Label5 = uilabel(app.SensitivityMapsPanel);
            app.Label5.Text = '5';
            app.KDim3SliderS = uislider(app.SensitivityMapsPanel);
            app.KDim3SliderS.Limits = [1 200];
            app.KDim3SliderS.MajorTicks = [];
            app.KDim3SliderS.ValueChangedFcn = createCallbackFcn(app, @KDim3SliderSValueChanged, true);
            app.KDim3SliderS.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.KDim3SliderS.Value = 1;
            app.KDim3LabelS = uilabel(app.CoilsTab);
            app.KDim3LabelS.Text = '            Kz';
            app.KDim3EditFieldS = uieditfield(app.CoilsTab, 'numeric');
            app.KDim3EditFieldS.Limits = [1 Inf];
            app.KDim3EditFieldS.ValueDisplayFormat = '%.0f';
            app.KDim3EditFieldS.ValueChangedFcn = createCallbackFcn(app, @KDim3EditFieldSValueChanged, true);
            app.KDim3EditFieldS.Value = 1;
            app.ScanParameterTabGroup = uitabgroup(app.P2ROUD_UIFigure);
            app.ScanParametersTab = uitab(app.ScanParameterTabGroup);
            app.ScanParametersTab.Title = 'Scan parameters';
            app.KMatrixViewField2 = uieditfield(app.ScanParametersTab, 'numeric');
            app.KMatrixViewField2.Limits = [1 Inf];
            app.KMatrixViewField2.ValueDisplayFormat = '%.0f';
            app.KMatrixViewField2.Editable = 'off';
            app.KMatrixViewField2.Tooltip = {'Phase-encoding matrix size'};
            app.KMatrixViewField2.Value = 1;
            app.KMatrixViewField3 = uieditfield(app.ScanParametersTab, 'numeric');
            app.KMatrixViewField3.Limits = [1 Inf];
            app.KMatrixViewField3.ValueDisplayFormat = '%.0f';
            app.KMatrixViewField3.Editable = 'off';
            app.KMatrixViewField3.Tooltip = {'Number of slices / 2nd phase-encoding matrix size'};
            app.KMatrixViewField3.Value = 1;
            app.FOVViewField2 = uieditfield(app.ScanParametersTab, 'numeric');
            app.FOVViewField2.Limits = [0 Inf];
            app.FOVViewField2.ValueDisplayFormat = '%.1f';
            app.FOVViewField2.Editable = 'off';
            app.FOVViewField2.Tooltip = {'FOV in phas-encoding direction'};
            app.FOVViewField3 = uieditfield(app.ScanParametersTab, 'numeric');
            app.FOVViewField3.Limits = [0 Inf];
            app.FOVViewField3.ValueDisplayFormat = '%.1f';
            app.FOVViewField3.Editable = 'off';
            app.FOVViewField3.Tooltip = {'Slice thickness / FOV in 2nd phase-encoding direction'};
            app.MXLabel = uilabel(app.ScanParametersTab);
            app.MXLabel.Text = 'Kx';
            app.MYLabel = uilabel(app.ScanParametersTab);
            app.MYLabel.Text = 'Ky';
            app.MZLabel = uilabel(app.ScanParametersTab);
            app.MZLabel.Text = 'Kz';
            app.NRViewFieldLabel = uilabel(app.ScanParametersTab);
            app.NRViewFieldLabel.Text = 'NR';
            app.NRViewField = uieditfield(app.ScanParametersTab, 'numeric');
            app.NRViewField.Limits = [0 Inf];
            app.NRViewField.ValueDisplayFormat = '%.0f';
            app.NRViewField.Editable = 'off';
            app.NRViewField.Tooltip = {'Number of dynamics/repetitions'};
            app.SequenceViewFieldLabel = uilabel(app.ScanParametersTab);
            app.SequenceViewFieldLabel.Text = 'sequence';
            app.SequenceViewField = uieditfield(app.ScanParametersTab, 'text');
            app.SequenceViewField.Editable = 'off';
            app.SequenceViewField.Tooltip = {'MRI sequence name'};
            app.KMatrixViewField1Label = uilabel(app.ScanParametersTab);
            app.KMatrixViewField1Label.Text = 'matrix';
            app.KMatrixViewField1 = uieditfield(app.ScanParametersTab, 'numeric');
            app.KMatrixViewField1.Limits = [1 Inf];
            app.KMatrixViewField1.ValueDisplayFormat = '%.0f';
            app.KMatrixViewField1.Editable = 'off';
            app.KMatrixViewField1.Tooltip = {'Readout matrix size'};
            app.KMatrixViewField1.Value = 1;
            app.FOVViewField1Label = uilabel(app.ScanParametersTab);
            app.FOVViewField1Label.Text = 'FOV (mm)';
            app.FOVViewField1 = uieditfield(app.ScanParametersTab, 'numeric');
            app.FOVViewField1.ValueDisplayFormat = '%.1f';
            app.FOVViewField1.Editable = 'off';
            app.FOVViewField1.Tooltip = {'FOV in readout direction'};
            app.TRViewFieldLabel = uilabel(app.ScanParametersTab);
            app.TRViewFieldLabel.Text = 'TR (ms)';
            app.TRViewField = uieditfield(app.ScanParametersTab, 'numeric');
            app.TRViewField.ValueDisplayFormat = '%.2f';
            app.TRViewField.Editable = 'off';
            app.TRViewField.Tooltip = {'Repetition time'};
            app.TEViewFieldLabel = uilabel(app.ScanParametersTab);
            app.TEViewFieldLabel.Text = 'TE (ms)';
            app.TEViewField = uieditfield(app.ScanParametersTab, 'numeric');
            app.TEViewField.ValueDisplayFormat = '%.2f';
            app.TEViewField.Editable = 'off';
            app.TEViewField.Tooltip = {'Echo time'};
            app.NAViewFieldLabel = uilabel(app.ScanParametersTab);
            app.NAViewFieldLabel.Text = 'NA';
            app.NAViewField = uieditfield(app.ScanParametersTab, 'numeric');
            app.NAViewField.Editable = 'off';
            app.NAViewField.Tooltip = {'Number of averages'};
            app.NFAViewFieldLabel = uilabel(app.ScanParametersTab);
            app.NFAViewFieldLabel.Text = 'NFA';
            app.NFAViewField = uieditfield(app.ScanParametersTab, 'numeric');
            app.NFAViewField.Editable = 'off';
            app.NFAViewField.Tooltip = {'Number of flip-angles'};
            app.NEViewFieldLabel = uilabel(app.ScanParametersTab);
            app.NEViewFieldLabel.Text = 'NE';
            app.NEViewField = uieditfield(app.ScanParametersTab, 'numeric');
            app.NEViewField.Editable = 'off';
            app.NEViewField.Tooltip = {'Number of echoes'};
            app.MRDfileViewFieldLabel = uilabel(app.ScanParametersTab);
            app.MRDfileViewFieldLabel.Text = 'MRD file';
            app.MRDfileViewField = uieditfield(app.ScanParametersTab, 'text');
            app.MRDfileViewField.Editable = 'off';
            app.MRDfileViewField.Tooltip = {'MRD file name'};
            app.FAViewFieldLabel = uilabel(app.ScanParametersTab);
            app.FAViewFieldLabel.Text = 'FA (deg)';
            app.FAViewField = uieditfield(app.ScanParametersTab, 'text');
            app.FAViewField.Editable = 'off';
            app.FAViewField.Tooltip = {'Flip angle(s)'};
            app.TrajectoryViewFieldLabel = uilabel(app.ScanParametersTab);
            app.TrajectoryViewFieldLabel.Text = 'trajectory';
            app.TrajectoryViewField = uieditfield(app.ScanParametersTab, 'text');
            app.TrajectoryViewField.Editable = 'off';
            app.TrajectoryViewField.Tooltip = {'Trajectory file'};
            app.ScanTimeViewFieldLabel = uilabel(app.ScanParametersTab);
            app.ScanTimeViewFieldLabel.Text = 'scan time';
            app.ScanTimeViewField = uieditfield(app.ScanParametersTab, 'text');
            app.ScanTimeViewField.Editable = 'off';
            app.ScanTimeViewField.Tooltip = {'Scan time'};
            app.TimeDynViewFieldLabel = uilabel(app.ScanParametersTab);
            app.TimeDynViewFieldLabel.Text = 'time / dyn';
            app.TimeDynViewField = uieditfield(app.ScanParametersTab, 'text');
            app.TimeDynViewField.Editable = 'off';
            app.TimeDynViewField.Tooltip = {'Time per dynamic'};
            app.VencViewFieldLabel = uilabel(app.ScanParametersTab);
            app.VencViewFieldLabel.Text = 'Venc (DAC)';
            app.VencViewField = uieditfield(app.ScanParametersTab, 'text');
            app.VencViewField.Editable = 'off';
            app.VencViewField.Tooltip = {'Flow encoding Venc'};
            app.GeometryTab = uitab(app.ScanParameterTabGroup);
            app.GeometryTab.Title = 'Geometry';
            app.FieldOfViewViewField2Label = uilabel(app.GeometryTab);
            app.FieldOfViewViewField2Label.Tooltip = {'Field of view'};
            app.FieldOfViewViewField2Label.Text = 'FOV (mm)';
            app.FieldOfViewViewField2 = uieditfield(app.GeometryTab, 'numeric');
            app.FieldOfViewViewField2.Limits = [0 Inf];
            app.FieldOfViewViewField2.ValueDisplayFormat = '%.2f';
            app.FieldOfViewViewField2.Editable = 'off';
            app.FieldOfViewViewField2.Tooltip = {'Field of view'};
            app.AspectRatioViewFieldLabel = uilabel(app.GeometryTab);
            app.AspectRatioViewFieldLabel.Tooltip = {'Aspect ratio'};
            app.AspectRatioViewFieldLabel.Text = 'aspect ratio';
            app.AspectRatioViewField = uieditfield(app.GeometryTab, 'numeric');
            app.AspectRatioViewField.Limits = [0 Inf];
            app.AspectRatioViewField.ValueDisplayFormat = '%.2f';
            app.AspectRatioViewField.Editable = 'off';
            app.AspectRatioViewField.Tooltip = {'Aspect ratio'};
            app.PhaseOrientationViewFieldLabel = uilabel(app.GeometryTab);
            app.PhaseOrientationViewFieldLabel.Tooltip = {'Phase orientation'};
            app.PhaseOrientationViewFieldLabel.Text = 'phase orientation';
            app.PhaseOrientationViewField = uieditfield(app.GeometryTab, 'numeric');
            app.PhaseOrientationViewField.Limits = [0 Inf];
            app.PhaseOrientationViewField.ValueDisplayFormat = '%.0f';
            app.PhaseOrientationViewField.Editable = 'off';
            app.PhaseOrientationViewField.Tooltip = {'Phase orientation, horizontal (1) or vertical (0)'};
            app.SliceThicknessViewFieldLabel = uilabel(app.GeometryTab);
            app.SliceThicknessViewFieldLabel.Tooltip = {'Slice thickness'};
            app.SliceThicknessViewFieldLabel.Text = 'slice thickness (mm)';
            app.SliceThicknessViewField = uieditfield(app.GeometryTab, 'numeric');
            app.SliceThicknessViewField.Limits = [0 Inf];
            app.SliceThicknessViewField.ValueDisplayFormat = '%.2f';
            app.SliceThicknessViewField.Editable = 'off';
            app.SliceThicknessViewField.Tooltip = {'Slice thickness'};
            app.OffsetXViewFieldLabel = uilabel(app.GeometryTab);
            app.OffsetXViewFieldLabel.Tooltip = {'X offset'};
            app.OffsetXViewFieldLabel.Text = 'offset X (mm)';
            app.OffsetXViewField = uieditfield(app.GeometryTab, 'numeric');
            app.OffsetXViewField.ValueDisplayFormat = '%.2f';
            app.OffsetXViewField.Editable = 'off';
            app.OffsetXViewField.Tooltip = {'X offset'};
            app.OffsetYViewFieldLabel = uilabel(app.GeometryTab);
            app.OffsetYViewFieldLabel.Tooltip = {'Y offset'};
            app.OffsetYViewFieldLabel.Text = 'offset Y (mm)';
            app.OffsetYViewField = uieditfield(app.GeometryTab, 'numeric');
            app.OffsetYViewField.ValueDisplayFormat = '%.2f';
            app.OffsetYViewField.Editable = 'off';
            app.OffsetYViewField.Tooltip = {'Y offset'};
            app.OffsetZViewField = uieditfield(app.GeometryTab, 'numeric');
            app.OffsetZViewField.ValueDisplayFormat = '%.2f';
            app.OffsetZViewField.Editable = 'off';
            app.OffsetZViewField.Tooltip = {'Z offset'};
            app.OffsetZViewFieldLabel = uilabel(app.GeometryTab);
            app.OffsetZViewFieldLabel.Tooltip = {'Z offset'};
            app.OffsetZViewFieldLabel.Text = 'offset Z (mm)';
            app.AngleXViewField = uieditfield(app.GeometryTab, 'numeric');
            app.AngleXViewField.ValueDisplayFormat = '%.0f';
            app.AngleXViewField.Editable = 'off';
            app.AngleXViewField.Tooltip = {'X angle'};
            app.AngleXViewFieldLabel = uilabel(app.GeometryTab);
            app.AngleXViewFieldLabel.Tooltip = {'X angle'};
            app.AngleXViewFieldLabel.Text = 'angle X (deg)';
            app.AngleYViewField = uieditfield(app.GeometryTab, 'numeric');
            app.AngleYViewField.ValueDisplayFormat = '%.0f';
            app.AngleYViewField.Editable = 'off';
            app.AngleYViewField.Tooltip = {'Y angle'};
            app.AngleYViewFieldLabel = uilabel(app.GeometryTab);
            app.AngleYViewFieldLabel.Tooltip = {'Y angle'};
            app.AngleYViewFieldLabel.Text = 'angle Y (deg)';
            app.AngleZViewField = uieditfield(app.GeometryTab, 'numeric');
            app.AngleZViewField.ValueDisplayFormat = '%.0f';
            app.AngleZViewField.Editable = 'off';
            app.AngleZViewField.Tooltip = {'Z angle'};
            app.AngleZViewFieldLabel = uilabel(app.GeometryTab);
            app.AngleZViewFieldLabel.Tooltip = {'Z angle'};
            app.AngleZViewFieldLabel.Text = 'angle Z (deg)';
            app.SliceGapViewFieldLabel = uilabel(app.GeometryTab);
            app.SliceGapViewFieldLabel.Tooltip = {'Slice gap'};
            app.SliceGapViewFieldLabel.Text = 'slice gap (mm)';
            app.SliceGapViewField = uieditfield(app.GeometryTab, 'numeric');
            app.SliceGapViewField.ValueDisplayFormat = '%.2f';
            app.SliceGapViewField.Editable = 'off';
            app.SliceGapViewField.Tooltip = {'Slice gap'};
            app.SlabOverlapEditFieldLabel = uilabel(app.GeometryTab);
            app.SlabOverlapEditFieldLabel.Tooltip = {'Slab overlap (pixels)'};
            app.SlabOverlapEditFieldLabel.Text = 'slab overlap (pixels)';
            app.SlabOverlapEditField = uieditfield(app.GeometryTab, 'numeric');
            app.SlabOverlapEditField.Limits = [0 Inf];
            app.SlabOverlapEditField.ValueDisplayFormat = '%.0f';
            app.SlabOverlapEditField.Tooltip = {'Slab overlap (pixels)'};
            app.SlabOverlapEditField.Value = 1;
            app.ReconstructionTab = uitab(app.ScanParameterTabGroup);
            app.ReconstructionTab.Title = 'Reconstruction';
            app.DeRingCheckBox = uicheckbox(app.ReconstructionTab);
            app.DeRingCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.DeRingCheckBox.Tooltip = {'Check for Gibbs ringing filter'};
            app.DeRingCheckBox.Text = '';
            app.DeRingLabel = uilabel(app.ReconstructionTab);
            app.DeRingLabel.Tooltip = {'Gibbs ringing filter'};
            app.DeRingLabel.Text = 'deRing';
            app.PCAdeNoiseLabel = uilabel(app.ReconstructionTab);
            app.PCAdeNoiseLabel.Tooltip = {'PCA denoising'};
            app.PCAdeNoiseLabel.Text = 'PCA';
            app.PCAdeNoiseCheckBox = uicheckbox(app.ReconstructionTab);
            app.PCAdeNoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.PCAdeNoiseCheckBox.Tooltip = {'Check for PCA denoising'};
            app.PCAdeNoiseCheckBox.Text = '';
            app.PCAdeNoiseWindowLabel = uilabel(app.ReconstructionTab);
            app.PCAdeNoiseWindowLabel.Tooltip = {'PCA denoising'};
            app.PCAdeNoiseWindowLabel.Text = 'window';
            app.PCAdeNoiseWindowEditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.PCAdeNoiseWindowEditField.Limits = [2 64];
            app.PCAdeNoiseWindowEditField.RoundFractionalValues = 'on';
            app.PCAdeNoiseWindowEditField.ValueDisplayFormat = '%.0f';
            app.PCAdeNoiseWindowEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.PCAdeNoiseWindowEditField.Tooltip = {'PCA denoising filter window (pixels)'};
            app.PCAdeNoiseWindowEditField.Value = 8;
            app.TVxyzEditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.TVxyzEditField.Limits = [0 2];
            app.TVxyzEditField.ValueDisplayFormat = '%.4f';
            app.TVxyzEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.TVxyzEditField.Tooltip = {'Total Varation regularization in spatial domain'};
            app.TVxyzEditFieldLabel = uilabel(app.ReconstructionTab);
            app.TVxyzEditFieldLabel.Text = 'TVxyz';
            app.TVtimeEditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.TVtimeEditField.Limits = [0 2];
            app.TVtimeEditField.ValueDisplayFormat = '%.4f';
            app.TVtimeEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.TVtimeEditField.Tooltip = {'Total Variation regularization in time domain'};
            app.TVtimeEditField.Value = 0.001;
            app.TVtimeEditFieldLabel = uilabel(app.ReconstructionTab);
            app.TVtimeEditFieldLabel.Text = 'TVtime';
            app.WVxyzEditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.WVxyzEditField.Limits = [0 2];
            app.WVxyzEditField.ValueDisplayFormat = '%.4f';
            app.WVxyzEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.WVxyzEditField.Tooltip = {'L1 Wavelet regularization in spatial domain '};
            app.WVxyzEditField.Value = 0.001;
            app.WVxyzEditFieldLabel = uilabel(app.ReconstructionTab);
            app.WVxyzEditFieldLabel.Text = 'WVxyz';
            app.LLRxyzEditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.LLRxyzEditField.Limits = [0 2];
            app.LLRxyzEditField.ValueDisplayFormat = '%.4f';
            app.LLRxyzEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.LLRxyzEditField.Tooltip = {'Locally Low-rank regularization in spatial domain'};
            app.LLRxyzEditField.Value = 0.001;
            app.LLRxyzEditFieldLabel = uilabel(app.ReconstructionTab);
            app.LLRxyzEditFieldLabel.Text = 'LLRxyz';
            app.CompressedsensingLabel = uilabel(app.ReconstructionTab);
            app.CompressedsensingLabel.Text = 'Compressed sensing';
            app.DeNoisingLabel = uilabel(app.ReconstructionTab);
            app.DeNoisingLabel.Text = 'Denoising';
            app.ZTEIterationsEditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.ZTEIterationsEditField.Limits = [10 1000];
            app.ZTEIterationsEditField.RoundFractionalValues = 'on';
            app.ZTEIterationsEditField.ValueDisplayFormat = '%.0f';
            app.ZTEIterationsEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.ZTEIterationsEditField.Enable = 'off';
            app.ZTEIterationsEditField.Tooltip = {'ZTE max number of iterations during CS reconstruction'};
            app.ZTEIterationsEditField.Value = 500;
            app.ZTEIterationsLabel = uilabel(app.ReconstructionTab);
            app.ZTEIterationsLabel.Enable = 'off';
            app.ZTEIterationsLabel.Text = 'CS iterations';
            app.MultiEchoLabel = uilabel(app.ReconstructionTab);
            app.MultiEchoLabel.Text = 'Sum acquisition';
            app.SumEchosCheckBox = uicheckbox(app.ReconstructionTab);
            app.SumEchosCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.SumEchosCheckBox.Text = '';
            app.SumEchosLabel = uilabel(app.ReconstructionTab);
            app.SumEchosLabel.Text = 'Σ NE';
            app.SumRepetitionsLabel = uilabel(app.ReconstructionTab);
            app.SumRepetitionsLabel.Text = 'Σ NR';
            app.SumRepetitionsCheckBox = uicheckbox(app.ReconstructionTab);
            app.SumRepetitionsCheckBox.Text = '';
            app.IncludeNrEditField = uieditfield(app.ReconstructionTab, 'text');
            app.IncludeNrEditField.ValueChangedFcn = createCallbackFcn(app, @IncludeNrEditFieldValueChanged, true);
            app.SumTypeNrDropDown = uidropdown(app.ReconstructionTab);
            app.SumTypeNrDropDown.Items = {'SoS', 'Max', 'Sum'};
            app.SumTypeNrDropDown.Value = 'SoS';
            app.IncludeNeEditField = uieditfield(app.ReconstructionTab, 'text');
            app.IncludeNeEditField.ValueChangedFcn = createCallbackFcn(app, @IncludeNeEditFieldValueChanged, true);
            app.SumTypeNeDropDown = uidropdown(app.ReconstructionTab);
            app.SumTypeNeDropDown.Items = {'SoS', 'Max', 'Sum'};
            app.SumTypeNeDropDown.Value = 'SoS';
            app.BMDdeNoiseLabel = uilabel(app.ReconstructionTab);
            app.BMDdeNoiseLabel.Tooltip = {'BMD denoising'};
            app.BMDdeNoiseLabel.Text = 'BMD';
            app.BMDdeNoiseCheckBox = uicheckbox(app.ReconstructionTab);
            app.BMDdeNoiseCheckBox.Tooltip = {'Check for BMD denoising'};
            app.BMDdeNoiseCheckBox.Text = '';
            app.RadialTab = uitab(app.ScanParameterTabGroup);
            app.RadialTab.Title = 'Radial';
            app.GradientdelaysPanel = uipanel(app.RadialTab);
            app.GradientdelaysPanel.Title = 'Gradient delays';
            app.GradDelayCalibrationCheckBox = uicheckbox(app.GradientdelaysPanel);
            app.GradDelayCalibrationCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.GradDelayCalibrationCheckBox.Tooltip = {'Automatic calibration of gradient delays'};
            app.GradDelayCalibrationCheckBox.Enable = 'off';
            app.GradDelayCalibrationCheckBox.Text = '';
            app.GradDelayCalibrationCheckBoxLabel = uilabel(app.GradientdelaysPanel);
            app.GradDelayCalibrationCheckBoxLabel.Tooltip = {'Automatic calibration of gradient delays'};
            app.GradDelayCalibrationCheckBoxLabel.Text = 'calibration';
            app.RingMethodCheckBox = uicheckbox(app.GradientdelaysPanel);
            app.RingMethodCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.RingMethodCheckBox.Tooltip = {'Ring method calibration [Rosenzweig et al., MRM 81:1898-1906 (2019)]'};
            app.RingMethodCheckBox.Enable = 'off';
            app.RingMethodCheckBox.Text = '';
            app.RingMethodCheckBoxLabel = uilabel(app.GradientdelaysPanel);
            app.RingMethodCheckBoxLabel.Tooltip = {'Ring method calibration [Rosenzweig et al., MRM 81:1898-1906 (2019)]'};
            app.RingMethodCheckBoxLabel.Text = 'ring method';
            app.AbortGradCalButton = uibutton(app.GradientdelaysPanel, 'push');
            app.AbortGradCalButton.ButtonPushedFcn = createCallbackFcn(app, @AbortGradCalButtonPushed, true);
            app.AbortGradCalButton.Enable = 'off';
            app.AbortGradCalButton.Tooltip = {'Abort gradient calibration'};
            app.AbortGradCalButton.Text = 'abort';
            app.GxdelayEditFieldLabel = uilabel(app.GradientdelaysPanel);
            app.GxdelayEditFieldLabel.Text = 'Gx delay';
            app.GxDelayEditField = uieditfield(app.GradientdelaysPanel, 'numeric');
            app.GxDelayEditField.Limits = [-10 10];
            app.GxDelayEditField.ValueDisplayFormat = '%.5f';
            app.GxDelayEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.GxDelayEditField.Enable = 'off';
            app.GxDelayEditField.Tooltip = {'X-gradient delay'};
            app.GydelayEditFieldLabel = uilabel(app.GradientdelaysPanel);
            app.GydelayEditFieldLabel.Text = 'Gy delay';
            app.GyDelayEditField = uieditfield(app.GradientdelaysPanel, 'numeric');
            app.GyDelayEditField.Limits = [-10 10];
            app.GyDelayEditField.ValueDisplayFormat = '%.5f';
            app.GyDelayEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.GyDelayEditField.Enable = 'off';
            app.GyDelayEditField.Tooltip = {'Y-gradient delay'};
            app.GzdelayEditFieldLabel = uilabel(app.GradientdelaysPanel);
            app.GzdelayEditFieldLabel.Text = 'Gz delay';
            app.GzDelayEditField = uieditfield(app.GradientdelaysPanel, 'numeric');
            app.GzDelayEditField.Limits = [-10 10];
            app.GzDelayEditField.ValueDisplayFormat = '%.5f';
            app.GzDelayEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.GzDelayEditField.Enable = 'off';
            app.GzDelayEditField.Tooltip = {'Z-gradient delay'};
            app.DataOffsetRadialEditFieldLabel = uilabel(app.GradientdelaysPanel);
            app.DataOffsetRadialEditFieldLabel.Tooltip = {'Number of discarded datapoints at the beginning of UTE readout'};
            app.DataOffsetRadialEditFieldLabel.Text = 'data offset';
            app.DataOffsetRadialEditField = uieditfield(app.GradientdelaysPanel, 'numeric');
            app.DataOffsetRadialEditField.Limits = [0 512];
            app.DataOffsetRadialEditField.RoundFractionalValues = 'on';
            app.DataOffsetRadialEditField.ValueDisplayFormat = '%.0f';
            app.DataOffsetRadialEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.DataOffsetRadialEditField.Enable = 'off';
            app.DataOffsetRadialEditField.Tooltip = {'Number of discarded datapoints at the beginning of UTE readout'};
            app.SxydelayEditFieldLabel = uilabel(app.GradientdelaysPanel);
            app.SxydelayEditFieldLabel.Tooltip = {'XY cross-term of the gradient delay matrix'};
            app.SxydelayEditFieldLabel.Text = 'Sxy delay';
            app.SxyDelayEditField = uieditfield(app.GradientdelaysPanel, 'numeric');
            app.SxyDelayEditField.Limits = [-10 10];
            app.SxyDelayEditField.ValueDisplayFormat = '%.5f';
            app.SxyDelayEditField.Enable = 'off';
            app.SxyDelayEditField.Tooltip = {'XY cross-term of the gradient delay matrix'};
            app.SxzdelayEditFieldLabel = uilabel(app.GradientdelaysPanel);
            app.SxzdelayEditFieldLabel.Tooltip = {'XZ cross-term of the gradient delay matrix'};
            app.SxzdelayEditFieldLabel.Text = 'Sxz delay';
            app.SxzDelayEditField = uieditfield(app.GradientdelaysPanel, 'numeric');
            app.SxzDelayEditField.Limits = [-10 10];
            app.SxzDelayEditField.ValueDisplayFormat = '%.5f';
            app.SxzDelayEditField.Enable = 'off';
            app.SxzDelayEditField.Tooltip = {'XZ cross-term of the gradient delay matrix'};
            app.SyzdelayEditFieldLabel = uilabel(app.GradientdelaysPanel);
            app.SyzdelayEditFieldLabel.Tooltip = {'YZ cross-term of the gradient delay matrix'};
            app.SyzdelayEditFieldLabel.Text = 'Syz delay';
            app.SyzDelayEditField = uieditfield(app.GradientdelaysPanel, 'numeric');
            app.SyzDelayEditField.Limits = [-10 10];
            app.SyzDelayEditField.ValueDisplayFormat = '%.5f';
            app.SyzDelayEditField.Enable = 'off';
            app.SyzDelayEditField.Tooltip = {'YZ cross-term of the gradient delay matrix'};
            app.CorrectionsPanel = uipanel(app.RadialTab);
            app.CorrectionsPanel.Title = 'Corrections';
            app.PhaseCorrectCheckBox = uicheckbox(app.CorrectionsPanel);
            app.PhaseCorrectCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.PhaseCorrectCheckBox.Tooltip = {'Check for phase correction'};
            app.PhaseCorrectCheckBox.Enable = 'off';
            app.PhaseCorrectCheckBox.Text = '';
            app.PhaseCorrectCheckBox.Value = true;
            app.PhaseCorrectCheckBoxLabel = uilabel(app.CorrectionsPanel);
            app.PhaseCorrectCheckBoxLabel.Tooltip = {'Check for phase correction'};
            app.PhaseCorrectCheckBoxLabel.Text = 'phase correction';
            app.CenterEchoCheckBox = uicheckbox(app.CorrectionsPanel);
            app.CenterEchoCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.CenterEchoCheckBox.Tooltip = {'Check to center the echo'};
            app.CenterEchoCheckBox.Enable = 'off';
            app.CenterEchoCheckBox.Text = '';
            app.CenterEchoCheckBox.Value = true;
            app.CenterEchoCheckBoxLabel = uilabel(app.CorrectionsPanel);
            app.CenterEchoCheckBoxLabel.Tooltip = {'Check to center the echo'};
            app.CenterEchoCheckBoxLabel.Text = 'center echo';
            app.DensityCorrectCheckBox = uicheckbox(app.CorrectionsPanel);
            app.DensityCorrectCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.DensityCorrectCheckBox.Tooltip = {'Check for density correction'};
            app.DensityCorrectCheckBox.Enable = 'off';
            app.DensityCorrectCheckBox.Text = '';
            app.DensityCorrectCheckBoxLabel = uilabel(app.CorrectionsPanel);
            app.DensityCorrectCheckBoxLabel.Tooltip = {'Check for density correction'};
            app.DensityCorrectCheckBoxLabel.Text = 'density correction';
            app.AmplitudeCorrectionCheckBox = uicheckbox(app.CorrectionsPanel);
            app.AmplitudeCorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.AmplitudeCorrectionCheckBox.Tooltip = {'Check for amplitude correction'};
            app.AmplitudeCorrectionCheckBox.Enable = 'off';
            app.AmplitudeCorrectionCheckBox.Text = '';
            app.AmplitudeCorrectionCheckBoxLabel = uilabel(app.CorrectionsPanel);
            app.AmplitudeCorrectionCheckBoxLabel.Tooltip = {'Check for amplitude correction'};
            app.AmplitudeCorrectionCheckBoxLabel.Text = 'amplitude correction';
            app.TukeyFilterCheckBox = uicheckbox(app.CorrectionsPanel);
            app.TukeyFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.TukeyFilterCheckBox.Tooltip = {'Check for application of k-space Tukey filter'};
            app.TukeyFilterCheckBox.Enable = 'off';
            app.TukeyFilterCheckBox.Text = '';
            app.TukeyFilterCheckBoxLabel = uilabel(app.CorrectionsPanel);
            app.TukeyFilterCheckBoxLabel.Tooltip = {'Check for application of k-space Tukey filter'};
            app.TukeyFilterCheckBoxLabel.Text = 'Tukey filter';
            app.ZTEPanel = uipanel(app.RadialTab);
            app.ZTEPanel.Title = 'ZTE';
            app.ZTEFullEchoCheckBox = uicheckbox(app.ZTEPanel);
            app.ZTEFullEchoCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZTEDeadTimeEditFieldValueChanged, true);
            app.ZTEFullEchoCheckBox.Tooltip = {'ZTE full echo reconstruction'};
            app.ZTEFullEchoCheckBox.Enable = 'off';
            app.ZTEFullEchoCheckBox.Text = '';
            app.ZTEFullEchoLabel = uilabel(app.ZTEPanel);
            app.ZTEFullEchoLabel.Tooltip = {'ZTE full echo reconstruction'};
            app.ZTEFullEchoLabel.Text = 'full echo';
            app.ZTEDeadTimeEditField = uieditfield(app.ZTEPanel, 'numeric');
            app.ZTEDeadTimeEditField.Limits = [0 20];
            app.ZTEDeadTimeEditField.ValueDisplayFormat = '%.1f';
            app.ZTEDeadTimeEditField.ValueChangedFcn = createCallbackFcn(app, @ZTEDeadTimeEditFieldValueChanged, true);
            app.ZTEDeadTimeEditField.Enable = 'off';
            app.ZTEDeadTimeEditField.Tooltip = {'ZTE receiver dead time'};
            app.ZTEDeadTimeEditField.Value = 14;
            app.ZTEDeadTimeLabel = uilabel(app.ZTEPanel);
            app.ZTEDeadTimeLabel.Text = 'dead time (μs)';
            app.ZTEShiftEditField = uieditfield(app.ZTEPanel, 'numeric');
            app.ZTEShiftEditField.Limits = [1 7];
            app.ZTEShiftEditField.RoundFractionalValues = 'on';
            app.ZTEShiftEditField.ValueDisplayFormat = '%.0f';
            app.ZTEShiftEditField.ValueChangedFcn = createCallbackFcn(app, @ZTEDeadTimeEditFieldValueChanged, true);
            app.ZTEShiftEditField.Enable = 'off';
            app.ZTEShiftEditField.Tooltip = {'ZTE sample shift'};
            app.ZTEShiftEditField.Value = 4;
            app.ZTEShiftLabel = uilabel(app.ZTEPanel);
            app.ZTEShiftLabel.Text = 'shift (samples)';
            app.ZTEDeadTimeCalibrationCheckBox = uicheckbox(app.ZTEPanel);
            app.ZTEDeadTimeCalibrationCheckBox.Tooltip = {'Estimate the ZTE dead time from the data'};
            app.ZTEDeadTimeCalibrationCheckBox.Enable = 'off';
            app.ZTEDeadTimeCalibrationCheckBox.Text = '';
            app.ZTEDeadTimeCalibrationLabel = uilabel(app.ZTEPanel);
            app.ZTEDeadTimeCalibrationLabel.Tooltip = {'Estimate the ZTE dead time from the data'};
            app.ZTEDeadTimeCalibrationLabel.Text = 'calibrate';
            app.ReconstructionTabGroup = uitabgroup(app.P2ROUD_UIFigure);
            app.ImagesTab = uitab(app.ReconstructionTabGroup);
            app.ImagesTab.Title = 'Images';
            app.RecoFig = uiaxes(app.ImagesTab);
            app.RecoFig.XTick = [];
            app.RecoFig.YTick = [];
            app.Dim6Slider = uislider(app.ImagesTab);
            app.Dim6Slider.Limits = [1 200];
            app.Dim6Slider.MajorTicks = [];
            app.Dim6Slider.ValueChangedFcn = createCallbackFcn(app, @Dim6SliderValueChanged, true);
            app.Dim6Slider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.Dim6Slider.Value = 1;
            app.Dim5Slider = uislider(app.ImagesTab);
            app.Dim5Slider.Limits = [1 200];
            app.Dim5Slider.MajorTicks = [];
            app.Dim5Slider.ValueChangedFcn = createCallbackFcn(app, @Dim5SliderValueChanged, true);
            app.Dim5Slider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.Dim5Slider.Value = 1;
            app.Dim4Slider = uislider(app.ImagesTab);
            app.Dim4Slider.Limits = [1 200];
            app.Dim4Slider.MajorTicks = [];
            app.Dim4Slider.ValueChangedFcn = createCallbackFcn(app, @Dim4SliderValueChanged, true);
            app.Dim4Slider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.Dim4Slider.Value = 1;
            app.Dim3Slider = uislider(app.ImagesTab);
            app.Dim3Slider.Limits = [1 200];
            app.Dim3Slider.MajorTicks = [];
            app.Dim3Slider.ValueChangedFcn = createCallbackFcn(app, @Dim3SliderValueChanged, true);
            app.Dim3Slider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.Dim3Slider.Value = 1;
            app.Dim6EditField = uieditfield(app.ImagesTab, 'numeric');
            app.Dim6EditField.Limits = [1 Inf];
            app.Dim6EditField.ValueDisplayFormat = '%.0f';
            app.Dim6EditField.ValueChangedFcn = createCallbackFcn(app, @Dim6EditFieldValueChanged, true);
            app.Dim6EditField.Value = 1;
            app.Dim5EditField = uieditfield(app.ImagesTab, 'numeric');
            app.Dim5EditField.Limits = [1 Inf];
            app.Dim5EditField.ValueDisplayFormat = '%.0f';
            app.Dim5EditField.ValueChangedFcn = createCallbackFcn(app, @Dim5EditFieldValueChanged, true);
            app.Dim5EditField.Value = 1;
            app.Dim4EditField = uieditfield(app.ImagesTab, 'numeric');
            app.Dim4EditField.Limits = [1 Inf];
            app.Dim4EditField.ValueDisplayFormat = '%.0f';
            app.Dim4EditField.ValueChangedFcn = createCallbackFcn(app, @Dim4EditFieldValueChanged, true);
            app.Dim4EditField.Value = 1;
            app.Dim3EditField = uieditfield(app.ImagesTab, 'numeric');
            app.Dim3EditField.Limits = [1 Inf];
            app.Dim3EditField.ValueDisplayFormat = '%.0f';
            app.Dim3EditField.ValueChangedFcn = createCallbackFcn(app, @Dim3EditFieldValueChanged, true);
            app.Dim3EditField.Value = 1;
            app.YLabel = uilabel(app.ImagesTab);
            app.YLabel.Text = '     Y     ';
            app.XLabel = uilabel(app.ImagesTab);
            app.XLabel.Text = '     X     ';
            app.Dim6Label = uilabel(app.ImagesTab);
            app.Dim6Label.Text = 'E';
            app.Dim5Label = uilabel(app.ImagesTab);
            app.Dim5Label.Text = 'FA';
            app.Dim4Label = uilabel(app.ImagesTab);
            app.Dim4Label.Text = 'R';
            app.Dim3Label = uilabel(app.ImagesTab);
            app.Dim3Label.Text = '              Z';
            app.LevelSlider = uislider(app.ImagesTab);
            app.LevelSlider.Limits = [0 65536];
            app.LevelSlider.MajorTicks = [];
            app.LevelSlider.ValueChangedFcn = createCallbackFcn(app, @LevelSliderValueChanged, true);
            app.LevelSlider.MinorTicks = [0 8192 16384 24576 32768 40960 49152 57344 65536];
            app.LevelSlider.Tooltip = {'Level'};
            app.WLabel = uilabel(app.ImagesTab);
            app.WLabel.Text = 'W';
            app.LLabel = uilabel(app.ImagesTab);
            app.LLabel.Text = 'L';
            app.WindowSlider = uislider(app.ImagesTab);
            app.WindowSlider.Limits = [0 65536];
            app.WindowSlider.MajorTicks = [];
            app.WindowSlider.ValueChangedFcn = createCallbackFcn(app, @WindowSliderValueChanged, true);
            app.WindowSlider.MinorTicks = [0 8192 16384 24576 32768 40960 49152 57344 65536];
            app.WindowSlider.Tooltip = {'Window'};
            app.LevelEditField = uieditfield(app.ImagesTab, 'numeric');
            app.LevelEditField.Limits = [0 Inf];
            app.LevelEditField.ValueDisplayFormat = '%.0f';
            app.LevelEditField.ValueChangedFcn = createCallbackFcn(app, @LevelEditFieldValueChanged, true);
            app.LevelEditField.Tooltip = {'Level'};
            app.WindowEditField = uieditfield(app.ImagesTab, 'numeric');
            app.WindowEditField.Limits = [0 Inf];
            app.WindowEditField.ValueDisplayFormat = '%.0f';
            app.WindowEditField.ValueChangedFcn = createCallbackFcn(app, @WindowEditFieldValueChanged, true);
            app.WindowEditField.Tooltip = {'Window'};
            app.SetButton = uibutton(app.ImagesTab, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Tooltip = {'Set level and window'};
            app.SetButton.Text = 'Set';
            app.MoviePlayButton = uibutton(app.ImagesTab, 'push');
            app.MoviePlayButton.ButtonPushedFcn = createCallbackFcn(app, @MovieMoviePlayButtonPushed, true);
            app.MoviePlayButton.Tooltip = {'Start movie'};
            app.MoviePlayButton.Text = '';
            app.MovieStopButton = uibutton(app.ImagesTab, 'push');
            app.MovieStopButton.ButtonPushedFcn = createCallbackFcn(app, @MovieMovieStopButtonPushed, true);
            app.MovieStopButton.Tooltip = {'Stop movie'};
            app.MovieStopButton.Text = '';
            app.AutoIntensityCheckBox = uicheckbox(app.ImagesTab);
            app.AutoIntensityCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoIntensityCheckBoxValueChanged, true);
            app.AutoIntensityCheckBox.Tooltip = {'Check for auto level and window'};
            app.AutoIntensityCheckBox.Text = '';
            app.AutoIntensityCheckBox.Value = true;
            app.AutoIntensityCheckBoxLabel = uilabel(app.ImagesTab);
            app.AutoIntensityCheckBoxLabel.Tooltip = {'Auto level and window'};
            app.AutoIntensityCheckBoxLabel.Text = 'auto';
            app.MovieImage = uiimage(app.ImagesTab);
            app.MovieImage.Enable = 'off';
            app.MovieImage.Tooltip = {'Movie dimension'};
            app.OrientationSpinnerLabel = uilabel(app.ImagesTab);
            app.OrientationSpinnerLabel.Text = 'orientation';
            app.OrientationSpinner = uispinner(app.ImagesTab);
            app.OrientationSpinner.Limits = [1 12];
            app.OrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @OrientationSpinnerValueChanged, true);
            app.OrientationSpinner.Tooltip = {'Image orienation'};

            app.OrientationSpinner.Value = 1;
            app.ThresholdEditFieldLabel = uilabel(app.ImagesTab);
            app.ThresholdEditFieldLabel.Text = 'threshold';
            app.ThresholdEditField = uieditfield(app.ImagesTab, 'numeric');
            app.ThresholdEditField.Limits = [0 Inf];
            app.ThresholdEditField.ValueDisplayFormat = '%.0f';
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Tooltip = {'Mask threshold'};
            app.ThresholdSlider = uislider(app.ImagesTab);
            app.ThresholdSlider.Limits = [0 4095];
            app.ThresholdSlider.MajorTicks = [];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.MinorTicks = [0 455 910 1365 1820 2275 2730 3185 3640 4095];
            app.ThresholdSlider.Tooltip = {'Mask threshold slider'};
            app.ErodeButton = uibutton(app.ImagesTab, 'push');
            app.ErodeButton.ButtonPushedFcn = createCallbackFcn(app, @ErodeButtonPushed, true);
            app.ErodeButton.Tooltip = {'Mask erosion'};
            app.ErodeButton.Text = 'Erode';
            app.DilateButton = uibutton(app.ImagesTab, 'push');
            app.DilateButton.ButtonPushedFcn = createCallbackFcn(app, @DilateButtonPushed, true);
            app.DilateButton.Tooltip = {'Mask dilation'};
            app.DilateButton.Text = 'Dilate';
            app.CloseButton = uibutton(app.ImagesTab, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Tooltip = {'Mask closing'};
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.ImagesTab, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Tooltip = {'Mask opening'};
            app.OpenButton.Text = 'Open';
            app.MaskButton = uibutton(app.ImagesTab, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Tooltip = {'Image masking/segmentation'};
            app.MaskButton.Text = 'Mask';
            app.MIPTab = uitab(app.ReconstructionTabGroup);
            app.MIPTab.Tooltip = {''};
            app.MIPTab.Title = 'MIP';
            app.MIPFig = uiaxes(app.MIPTab);
            app.MIPFig.XTick = [];
            app.MIPFig.YTick = [];
            app.MIPyAngleSlider = uislider(app.MIPTab);
            app.MIPyAngleSlider.Limits = [-180 180];
            app.MIPyAngleSlider.MajorTicks = [];
            app.MIPyAngleSlider.ValueChangedFcn = createCallbackFcn(app, @MIPAngleSliderValueChanged, true);
            app.MIPyAngleSlider.MinorTicks = [-180 -162 -144 -126 -108 -90 -72 -54 -36 -18 0 18 36 54 72 90 108 126 144 162 180];
            app.MIPxAngleSlider = uislider(app.MIPTab);
            app.MIPxAngleSlider.Limits = [-180 180];
            app.MIPxAngleSlider.MajorTicks = [];
            app.MIPxAngleSlider.ValueChangedFcn = createCallbackFcn(app, @MIPAngleSliderValueChanged, true);
            app.MIPxAngleSlider.MinorTicks = [-180 -162 -144 -126 -108 -90 -72 -54 -36 -18 0 18 36 54 72 90 108 126 144 162 180];
            app.MIPyAngleLabel = uilabel(app.MIPTab);
            app.MIPyAngleLabel.Text = 'Y';
            app.MIPxAngleLabel = uilabel(app.MIPTab);
            app.MIPxAngleLabel.Text = 'X';
            app.MIPRotateLabel = uilabel(app.MIPTab);
            app.MIPRotateLabel.Text = 'Rotate';
            app.MIPAngleResetButton = uibutton(app.MIPTab, 'push');
            app.MIPAngleResetButton.ButtonPushedFcn = createCallbackFcn(app, @MIPAngleSliderResetButtonPushed, true);
            app.MIPAngleResetButton.Tooltip = {'Reset MIP orientation and stop the movie'};
            app.MIPAngleResetButton.Text = 'Reset';
            app.MIPLevelSlider = uislider(app.MIPTab);
            app.MIPLevelSlider.Limits = [0 65536];
            app.MIPLevelSlider.MajorTicks = [];
            app.MIPLevelSlider.ValueChangedFcn = createCallbackFcn(app, @MIPLevelSliderValueChanged, true);
            app.MIPLevelSlider.MinorTicks = [0 8192 16384 24576 32768 40960 49152 57344 65536];
            app.MIPLevelSlider.Tooltip = {'Level'};
            app.MIPWLabel = uilabel(app.MIPTab);
            app.MIPWLabel.Text = 'W';
            app.MIPLLabel = uilabel(app.MIPTab);
            app.MIPLLabel.Text = 'L';
            app.MIPWindowSlider = uislider(app.MIPTab);
            app.MIPWindowSlider.Limits = [0 65536];
            app.MIPWindowSlider.MajorTicks = [];
            app.MIPWindowSlider.ValueChangedFcn = createCallbackFcn(app, @MIPWindowSliderValueChanged, true);
            app.MIPWindowSlider.MinorTicks = [0 8192 16384 24576 32768 40960 49152 57344 65536];
            app.MIPWindowSlider.Tooltip = {'window'};
            app.MIPLevelEditField = uieditfield(app.MIPTab, 'numeric');
            app.MIPLevelEditField.Limits = [0 Inf];
            app.MIPLevelEditField.ValueDisplayFormat = '%.0f';
            app.MIPLevelEditField.ValueChangedFcn = createCallbackFcn(app, @MIPLevelEditFieldValueChanged, true);
            app.MIPLevelEditField.Tooltip = {'Level'};
            app.MIPWindowEditField = uieditfield(app.MIPTab, 'numeric');
            app.MIPWindowEditField.Limits = [0 Inf];
            app.MIPWindowEditField.ValueDisplayFormat = '%.0f';
            app.MIPWindowEditField.ValueChangedFcn = createCallbackFcn(app, @MIPWindowEditFieldValueChanged, true);
            app.MIPWindowEditField.Tooltip = {'Window'};
            app.MIPSetButton = uibutton(app.MIPTab, 'push');
            app.MIPSetButton.ButtonPushedFcn = createCallbackFcn(app, @MIPSetButtonPushed, true);
            app.MIPSetButton.Tooltip = {'Set Level and Window'};
            app.MIPSetButton.Text = 'Set';
            app.MIPYLabel = uilabel(app.MIPTab);
            app.MIPYLabel.Text = '     Y     ';
            app.MIPXLabel = uilabel(app.MIPTab);
            app.MIPXLabel.Text = '     X     ';
            app.MIPOrientationSpinnerLabel = uilabel(app.MIPTab);
            app.MIPOrientationSpinnerLabel.Text = 'orientation';
            app.MIPOrientationSpinner = uispinner(app.MIPTab);
            app.MIPOrientationSpinner.Limits = [1 12];
            app.MIPOrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @MIPOrientationSpinnerValueChanged, true);
            app.MIPOrientationSpinner.Tooltip = {'Image orienation'};
            app.MIPOrientationSpinner.Value = 1;
            app.MIPyPlayButton = uibutton(app.MIPTab, 'push');
            app.MIPyPlayButton.ButtonPushedFcn = createCallbackFcn(app, @MIPyPlayButtonPushed, true);
            app.MIPyPlayButton.Tooltip = {'Start MIP movie'};
            app.MIPyPlayButton.Text = '';
            app.MIPxPlayButton = uibutton(app.MIPTab, 'push');
            app.MIPxPlayButton.ButtonPushedFcn = createCallbackFcn(app, @MIPxPlayButtonPushed, true);
            app.MIPxPlayButton.Tooltip = {'Play MIP movie'};
            app.MIPxPlayButton.Text = '';
            app.MIPResizeButton = uibutton(app.MIPTab, 'state');
            app.MIPResizeButton.ValueChangedFcn = createCallbackFcn(app, @MIPResizeButtonValueChanged, true);
            app.MIPResizeButton.Tooltip = {'Downsample MIP for speed'};
            app.MIPResizeButton.Text = 'Resize';
            app.PhaseTab = uitab(app.ReconstructionTabGroup);
            app.PhaseTab.Title = 'Phase';
            app.PhaseRecoFig = uiaxes(app.PhaseTab);
            app.PhaseRecoFig.XTick = [];
            app.PhaseRecoFig.YTick = [];
            app.PhaseYLabel = uilabel(app.PhaseTab);
            app.PhaseYLabel.Text = '     Y     ';
            app.PhaseXLabel = uilabel(app.PhaseTab);
            app.PhaseXLabel.Text = '     X     ';
            app.PhaseMovieStartButton = uibutton(app.PhaseTab, 'push');
            app.PhaseMovieStartButton.ButtonPushedFcn = createCallbackFcn(app, @PhaseMovieStartButtonPushed, true);
            app.PhaseMovieStartButton.Tooltip = {'Start movie'};
            app.PhaseMovieStartButton.Text = '';
            app.PhaseMovieStopButton = uibutton(app.PhaseTab, 'push');
            app.PhaseMovieStopButton.ButtonPushedFcn = createCallbackFcn(app, @PhaseMovieStopButtonPushed, true);
            app.PhaseMovieStopButton.Tooltip = {'Stop movie'};
            app.PhaseMovieStopButton.Text = '';
            app.PhaseDim3Slider = uislider(app.PhaseTab);
            app.PhaseDim3Slider.Limits = [1 200];
            app.PhaseDim3Slider.MajorTicks = [];
            app.PhaseDim3Slider.ValueChangedFcn = createCallbackFcn(app, @PhaseDim3SliderValueChanged, true);
            app.PhaseDim3Slider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.PhaseDim3Slider.Value = 1;
            app.PhaseDim5Slider = uislider(app.PhaseTab);
            app.PhaseDim5Slider.Limits = [1 200];
            app.PhaseDim5Slider.MajorTicks = [];
            app.PhaseDim5Slider.ValueChangedFcn = createCallbackFcn(app, @PhaseDim5SliderValueChanged, true);
            app.PhaseDim5Slider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.PhaseDim5Slider.Value = 1;
            app.PhaseDim4Slider = uislider(app.PhaseTab);
            app.PhaseDim4Slider.Limits = [1 200];
            app.PhaseDim4Slider.MajorTicks = [];
            app.PhaseDim4Slider.ValueChangedFcn = createCallbackFcn(app, @PhaseDim4SliderValueChanged, true);
            app.PhaseDim4Slider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.PhaseDim4Slider.Value = 1;
            app.PhaseDim3EditField = uieditfield(app.PhaseTab, 'numeric');
            app.PhaseDim3EditField.Limits = [1 Inf];
            app.PhaseDim3EditField.ValueDisplayFormat = '%.0f';
            app.PhaseDim3EditField.ValueChangedFcn = createCallbackFcn(app, @PhaseDim3EditFieldValueChanged, true);
            app.PhaseDim3EditField.Value = 1;
            app.PhaseDim5EditField = uieditfield(app.PhaseTab, 'numeric');
            app.PhaseDim5EditField.Limits = [1 Inf];
            app.PhaseDim5EditField.ValueDisplayFormat = '%.0f';
            app.PhaseDim5EditField.ValueChangedFcn = createCallbackFcn(app, @PhaseDim5EditFieldValueChanged, true);
            app.PhaseDim5EditField.Value = 1;
            app.PhaseDim4EditField = uieditfield(app.PhaseTab, 'numeric');
            app.PhaseDim4EditField.Limits = [1 Inf];
            app.PhaseDim4EditField.ValueDisplayFormat = '%.0f';
            app.PhaseDim4EditField.ValueChangedFcn = createCallbackFcn(app, @PhaseDim4EditFieldValueChanged, true);
            app.PhaseDim4EditField.Value = 1;
            app.PhaseDim6EditField = uieditfield(app.PhaseTab, 'numeric');
            app.PhaseDim6EditField.Limits = [1 Inf];
            app.PhaseDim6EditField.ValueDisplayFormat = '%.0f';
            app.PhaseDim6EditField.ValueChangedFcn = createCallbackFcn(app, @PhaseDim6EditFieldValueChanged, true);
            app.PhaseDim6EditField.Value = 1;
            app.PhaseDim6Slider = uislider(app.PhaseTab);
            app.PhaseDim6Slider.Limits = [1 200];
            app.PhaseDim6Slider.MajorTicks = [];
            app.PhaseDim6Slider.ValueChangedFcn = createCallbackFcn(app, @PhaseDim6SliderValueChanged, true);
            app.PhaseDim6Slider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.PhaseDim6Slider.Value = 1;
            app.PhaseDim3Label = uilabel(app.PhaseTab);
            app.PhaseDim3Label.Text = '              Z';
            app.PhaseDim4Label = uilabel(app.PhaseTab);
            app.PhaseDim4Label.Text = '             NR';
            app.PhaseDim5Label = uilabel(app.PhaseTab);
            app.PhaseDim5Label.Text = 'FA';
            app.PhaseDim6Label = uilabel(app.PhaseTab);
            app.PhaseDim6Label.Text = 'echo';
            app.PhaseMovieImage = uiimage(app.PhaseTab);
            app.PhaseMovieImage.Enable = 'off';
            app.PhaseMovieImage.Tooltip = {'Movie dimension'};
            app.PhaseOrientationSpinnerLabel = uilabel(app.PhaseTab);
            app.PhaseOrientationSpinnerLabel.Text = 'orientation';
            app.PhaseOrientationSpinner = uispinner(app.PhaseTab);
            app.PhaseOrientationSpinner.Limits = [1 12];
            app.PhaseOrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @PhaseOrientationSpinnerValueChanged, true);
            app.PhaseOrientationSpinner.Tooltip = {'Image orientation'};
            app.PhaseOrientationSpinner.Value = 1;
            app.PhaseUnwrapButton = uibutton(app.PhaseTab, 'push');
            app.PhaseUnwrapButton.ButtonPushedFcn = createCallbackFcn(app, @PhaseUnwrapButtonPushed, true);
            app.PhaseUnwrapButton.Tooltip = {'Phase unwrapping'};
            app.PhaseUnwrapButton.Text = 'Unwrap';
            app.PhaseMaxLabel = uilabel(app.PhaseTab);
            app.PhaseMaxLabel.Text = 'max';
            app.PhaseMinLabel = uilabel(app.PhaseTab);
            app.PhaseMinLabel.Text = 'min';
            app.MessagesTabGroup = uitabgroup(app.P2ROUD_UIFigure);
            app.MessagesTab = uitab(app.MessagesTabGroup);
            app.MessagesTab.Title = 'Messages';
            app.MessageBox = uitextarea(app.MessagesTab);
            app.MessageBox.Editable = 'off';
            app.MessageBox.Tooltip = {'Messages'};
            app.MessageBox.Value = {'Messages ...'};
            app.StatusBox = uitextarea(app.MessagesTab);
            app.StatusBox.Editable = 'off';
            app.StatusBox.Tooltip = {'App status'};
            app.StatusLabel = uilabel(app.MessagesTab);
            app.StatusLabel.Text = 'STATUS';
            app.StatusImage = uiimage(app.MessagesTab);
            app.SettingsTab = uitab(app.MessagesTabGroup);
            app.SettingsTab.Title = 'Settings';
            app.SoundsLabel = uilabel(app.SettingsTab);
            app.SoundsLabel.Text = 'Sounds';
            app.AutoSoundsCheckBox = uicheckbox(app.SettingsTab);
            app.AutoSoundsCheckBox.Tooltip = {'Warning and error sounds on or off'};
            app.AutoSoundsCheckBox.Text = '';
            app.AutoSoundsCheckBox.Value = true;
            app.AutoSoundsCheckBoxLabel = uilabel(app.SettingsTab);
            app.AutoSoundsCheckBoxLabel.Tooltip = {'Warning and error sounds on or off'};
            app.AutoSoundsCheckBoxLabel.Text = 'sounds on/off';
            app.ColorsLabel = uilabel(app.SettingsTab);
            app.ColorsLabel.Text = 'Colors';
            app.ColorsButtonGroup = uibuttongroup(app.SettingsTab);
            app.ColorsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorsButtonGroupSelectionChanged, true);
            app.ColorsButtonGroup.Tooltip = {'App color scheme selection'};
            app.ClassicButton = uiradiobutton(app.ColorsButtonGroup);
            app.ClassicButton.Text = 'classic';
            app.ClassicButton.Interpreter = 'html';
            app.BlackWhiteButton = uiradiobutton(app.ColorsButtonGroup);
            app.BlackWhiteButton.Text = 'black/white';
            app.BlackWhiteButton.Interpreter = 'html';
            app.BlackWhiteButton.Value = true;
            app.MessageLevelSpinner = uispinner(app.SettingsTab);
            app.MessageLevelSpinner.Limits = [1 3];
            app.MessageLevelSpinner.Value = 1;
            app.MessageLevelSpinnerLabel = uilabel(app.SettingsTab);
            app.MessageLevelSpinnerLabel.Text = 'message level';
            app.MessagesLabel = uilabel(app.SettingsTab);
            app.MessagesLabel.Text = 'Messages';
            app.Hyperlink = uihyperlink(app.P2ROUD_UIFigure);
            app.Hyperlink.URL = 'https://github.com/Moby1971/';
            app.Hyperlink.Text = 'Github';
            % Show the figure after all components are created
            app.P2ROUD_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P2ROUD

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.P2ROUD_UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.P2ROUD_UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.P2ROUD_UIFigure)
        end
    end
end